% description: Find objects (e.g. synapses) in three-dimentional image stacks.
% Copyright 2016-2018 Luca Della Santina (luca.dellasantina@gmail.com)
classdef ObjectFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Lamp                           matlab.ui.control.Lamp
        TabGroup                       matlab.ui.container.TabGroup
        DetectTab                      matlab.ui.container.Tab
        SelectFolderButton             matlab.ui.control.Button
        ChooseImagesButton             matlab.ui.control.Button
        FindObjectsButton              matlab.ui.control.Button
        ValidateobjectsButton          matlab.ui.control.Button
        SearchsettingsPanel            matlab.ui.container.Panel
        MaxLabel_3                     matlab.ui.control.Label
        MaxSizeXY                      matlab.ui.control.NumericEditField
        Watershed                      matlab.ui.control.CheckBox
        MinLabel_3                     matlab.ui.control.Label
        MinSizeXY                      matlab.ui.control.NumericEditField
        BlockSearch                    matlab.ui.control.CheckBox
        Sphericity                     matlab.ui.control.CheckBox
        btnRecalculateSphericity       matlab.ui.control.Button
        ExpectedsizerangediametermLabel  matlab.ui.control.Label
        MinimumobjectintensitytimesabovenoiseLabel  matlab.ui.control.Label
        MinIntensity                   matlab.ui.control.Spinner
        SingleZDotCut                  matlab.ui.control.CheckBox
        EdgeDotCut                     matlab.ui.control.CheckBox
        xyStableDots                   matlab.ui.control.CheckBox
        FilteragainButton              matlab.ui.control.Button
        btnCalcDiameter                matlab.ui.control.Button
        ExcludecandidateobjectsiftheirvoxelsareLabel  matlab.ui.control.Label
        LocalNoise                     matlab.ui.control.CheckBox
        ValidationsettingsPanel        matlab.ui.container.Panel
        ToolLabel                      matlab.ui.control.Label
        InspectorType                  matlab.ui.control.DropDown
        NeuralNetLabel                 matlab.ui.control.Label
        InspectorNeuralNet             matlab.ui.control.DropDown
        InspectorNewObjects            matlab.ui.control.CheckBox
        btnPurgeInvalid                matlab.ui.control.Button
        CurrentObjectsPanel            matlab.ui.container.Panel
        DetectSelObjects               matlab.ui.control.DropDown
        btnRenameObjects               matlab.ui.control.Button
        btnDeleteObjects               matlab.ui.control.Button
        btnCopyObjects                 matlab.ui.control.Button
        lblFolder                      matlab.ui.control.Label
        lblImageFile                   matlab.ui.control.Label
        lblMask                        matlab.ui.control.Label
        lblResolution                  matlab.ui.control.Label
        ReportTab                      matlab.ui.container.Tab
        pnlObjDensity                  matlab.ui.container.Panel
        btnPlotDensityByDepth          matlab.ui.control.Button
        btnPlotDensityLinear           matlab.ui.control.Button
        btnPlotHeatmap                 matlab.ui.control.Button
        btnPlotSholl                   matlab.ui.control.Button
        pnlObjPosition                 matlab.ui.container.Panel
        btnPlotLocationsXY             matlab.ui.control.Button
        btnPlotSkeleton                matlab.ui.control.Button
        btnPlotLocationsZ              matlab.ui.control.Button
        btnCreateMask                  matlab.ui.control.Button
        pnlObjStats                    matlab.ui.container.Panel
        ReportSelObjects               matlab.ui.control.DropDown
        lblReportValid                 matlab.ui.control.Label
        lblReportAvgSize               matlab.ui.control.Label
        lblReportAvgBright             matlab.ui.control.Label
        lblReportSrcImgName            matlab.ui.control.Label
        lblReportSrcImgVol             matlab.ui.control.Label
        lblReportVolOccupancy          matlab.ui.control.Label
        lblReportSrcImgRes             matlab.ui.control.Label
        lblReportMask                  matlab.ui.control.Label
        pnlNearestNeighbor             matlab.ui.container.Panel
        NeighboringobjectsDropDownLabel  matlab.ui.control.Label
        ReportSelObjectsNN             matlab.ui.control.DropDown
        CalculateNN                    matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        lblNNdistance                  matlab.ui.control.Label
        lblNNvoxelOverlap              matlab.ui.control.Label
        btnPlotNNdistDist              matlab.ui.control.Button
        btnPlotNNoverlapDist           matlab.ui.control.Button
        pnlObjPosition_2               matlab.ui.container.Panel
        btnPlotObjSizeDist             matlab.ui.control.Button
        btnPlotObjBrightDist           matlab.ui.control.Button
        FittingdistributionLabel       matlab.ui.control.Label
        ReportDistFitType              matlab.ui.control.DropDown
        btnPlotObjRoundDist            matlab.ui.control.Button
        btnPlotObjMajorAxisDist        matlab.ui.control.Button
        AutomateTab                    matlab.ui.container.Tab
        ProcessinglistPanel            matlab.ui.container.Panel
        lstBatch                       matlab.ui.control.ListBox
        btnBatchAdd                    matlab.ui.control.Button
        btnBatchRemove                 matlab.ui.control.Button
        btnBatchUp                     matlab.ui.control.Button
        btnBatchDown                   matlab.ui.control.Button
        btnBatchTop                    matlab.ui.control.Button
        btnBatchBottom                 matlab.ui.control.Button
        btnBatchClear                  matlab.ui.control.Button
        btnBatchAddMultiple            matlab.ui.control.Button
        chkBatchNewImages              matlab.ui.control.CheckBox
        chkBatchPropagateSettings      matlab.ui.control.CheckBox
        btnBatchStart                  matlab.ui.control.Button
        BatchLog                       matlab.ui.control.TextArea
        btnBatchStop                   matlab.ui.control.StateButton
        AutomateOperation              matlab.ui.container.TabGroup
        AutoSearchTab                  matlab.ui.container.Tab
        BatchWatershed                 matlab.ui.control.CheckBox
        BatchBlockSearch               matlab.ui.control.CheckBox
        BatchSphericity                matlab.ui.control.CheckBox
        ExpecteddiameterrangeofobjectsmLabel  matlab.ui.control.Label
        BatchSingleZDotCut             matlab.ui.control.CheckBox
        BatchEdgeDotCut                matlab.ui.control.CheckBox
        BatchxyStableDots              matlab.ui.control.CheckBox
        ExcludecandidateobjectsiftheirvoxelsareLabel_2  matlab.ui.control.Label
        BatchLocalNoise                matlab.ui.control.CheckBox
        MaxLabel_4                     matlab.ui.control.Label
        BatchMaxSizeXY                 matlab.ui.control.NumericEditField
        MinLabel_4                     matlab.ui.control.Label
        BatchMinSizeXY                 matlab.ui.control.NumericEditField
        ismorethanLabel                matlab.ui.control.Label
        BatchThreshValue               matlab.ui.control.NumericEditField
        MinimumintensityofobjectstimesthenoiselevelLabel  matlab.ui.control.Label
        BatchMinIntensity              matlab.ui.control.Spinner
        ValidateifLabel                matlab.ui.control.Label
        BatchThreshParam               matlab.ui.control.DropDown
        AutoColocalizeTab              matlab.ui.container.Tab
        ReferenceObjectsDropDownLabel  matlab.ui.control.Label
        AutoColoRefObjs                matlab.ui.control.DropDown
        AutoColoChannel                matlab.ui.control.DropDown
        TocolocalizewithSwitchLabel    matlab.ui.control.Label
        AutoColoMaskOrObjs             matlab.ui.control.Switch
        MustshareatleastSpinnerLabel   matlab.ui.control.Label
        AutoColoVoxNum                 matlab.ui.control.Spinner
        AutoColoSwitchVoxPerc          matlab.ui.control.Switch
        chkAutoColoAll                 matlab.ui.control.CheckBox
        chkAutoColoSaveTable           matlab.ui.control.CheckBox
        BatchScheduledTime             matlab.ui.control.Knob
        BatchScheduler                 matlab.ui.control.CheckBox
        lblBatchScheduler              matlab.ui.control.Label
        ColocalizeTab                  matlab.ui.container.Tab
        ChannelstocolocalizeobjectswithPanel  matlab.ui.container.Panel
        lstColo                        matlab.ui.control.ListBox
        ProcessedchannelsPanel         matlab.ui.container.Panel
        lstColoDone                    matlab.ui.control.ListBox
        btnColoDoneRemove              matlab.ui.control.Button
        btnColoDoneClear               matlab.ui.control.Button
        btnColoRedoValid               matlab.ui.control.Button
        lblColocRate                   matlab.ui.control.Label
        lblReferenceName               matlab.ui.control.Label
        btnColoRedoDiscarded           matlab.ui.control.Button
        btnColoDoneUp                  matlab.ui.control.Button
        btnColoDoneDown                matlab.ui.control.Button
        lblColoc2Rate                  matlab.ui.control.Label
        lblColocRateDouble             matlab.ui.control.Label
        btnColoDoneTop                 matlab.ui.control.Button
        btnColoDoneBottom              matlab.ui.control.Button
        lblColoMethod                  matlab.ui.control.Label
        lblColoVoxThresh               matlab.ui.control.Label
        lblColoPercThresh              matlab.ui.control.Label
        ManualanalysisPanel            matlab.ui.container.Panel
        btnColocalize                  matlab.ui.control.Button
        btnColocalize2                 matlab.ui.control.Button
        AutomatedanalysisPanel         matlab.ui.container.Panel
        btnColocalizeMask              matlab.ui.control.Button
        btnColocalizeNN                matlab.ui.control.Button
        ColocalizedifsharingatleastLabel  matlab.ui.control.Label
        ColoSharedVoxNum               matlab.ui.control.Spinner
        ColoSwitchVoxPerc              matlab.ui.control.Switch
        ObjectstocolocalizePanel       matlab.ui.container.Panel
        lstColoSelObjects              matlab.ui.control.ListBox
        chkColoSaveAsNewObjects        matlab.ui.control.CheckBox
        NeuralNetTab                   matlab.ui.container.Tab
        TrainingDatasetPanel           matlab.ui.container.Panel
        lstTraining                    matlab.ui.control.ListBox
        btnTrainingAdd                 matlab.ui.control.Button
        btnTrainingRemove              matlab.ui.control.Button
        btnTrainingUp                  matlab.ui.control.Button
        btnTrainingDown                matlab.ui.control.Button
        btnTrainingTop                 matlab.ui.control.Button
        btnTrainingBottom              matlab.ui.control.Button
        btnTrainingClear               matlab.ui.control.Button
        TrainingsettingsPanel          matlab.ui.container.Panel
        FractionofobjectstousefortrainingSpinnerLabel  matlab.ui.control.Label
        TrainSplitRatio                matlab.ui.control.Spinner
        MiniBatchSizenumberofpointsuponwhichtrainingstatsupdateLabel  matlab.ui.control.Label
        TrainMiniBatchSize             matlab.ui.control.NumericEditField
        MaximumnumberortrainingepochsLabel  matlab.ui.control.Label
        TrainMaxEpochs                 matlab.ui.control.NumericEditField
        MomentumpercentcontributionoftheprevioussteptotrainingLabel  matlab.ui.control.Label
        TrainMomentum                  matlab.ui.control.Spinner
        Initiallearningrate0to1zeromeansslowestLabel  matlab.ui.control.Label
        TrainInitialLearningRate       matlab.ui.control.Spinner
        TrainingFolderButton           matlab.ui.control.Button
        TrainNeuralNetworkButton       matlab.ui.control.Button
        GenerateTrainingDatasetButton  matlab.ui.control.Button
        TrainingFolder                 matlab.ui.control.EditField
        TrainingfolderLabel            matlab.ui.control.Label
        NameLabel                      matlab.ui.control.Label
        txtTrainingName                matlab.ui.control.EditField
        NetworkModelLabel              matlab.ui.control.Label
        cmbTrainingModel               matlab.ui.control.DropDown
        AboutTab                       matlab.ui.container.Tab
        lblObjectFinder                matlab.ui.control.Label
        lblCopyright                   matlab.ui.control.Label
        btnAppIcon                     matlab.ui.control.Button
        lblVersion                     matlab.ui.control.Label
        txtLicense                     matlab.ui.control.TextArea
        btnReportBug                   matlab.ui.control.Button
        btnHomePage                    matlab.ui.control.Button
        btnUserManual                  matlab.ui.control.Button
        Log                            matlab.ui.control.EditField
    end

    %% ObjectFinder - Recognize 3D structures in image stacks
    %  Copyright (C) 2016,2017,2018 Luca Della Santina
    %
    %  This file is part of ObjectFinder.
    %
    %  ObjectFinder is free software: you can redistribute it and/or modify
    %  it under the terms of the GNU General Public License as published by
    %  the Free Software Foundation, either version 3 of the License, or
    %  (at your option) any later version.
    %
    %  This program is distributed in the hope that it will be useful,
    %  but WITHOUT ANY WARRANTY; without even the implied warranty of
    %  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %  GNU General Public License for more details.
    %
    %  You should have received a copy of the GNU General Public License
    %  along with this program.  If not, see <https://www.gnu.org/licenses/>.
    %
    properties (Access = private)
        Settings
        Post
        PostChName
        Mask
        NeuralNet
        Dots
        Filter
        Skel
        TimerDelay
        Timer
        dlgCalc = [];
    end

    methods (Access = private)
    
        function RefreshValidObjects(app)
            % Refresh the list of available objects
            app.DetectSelObjects.Items = {'New Objects'};
            for i=1:numel(app.Dots)
                app.DetectSelObjects.Items{end+1} = app.Dots(i).Name;
            end
            
            if numel(app.Dots) > 0
                app.ReportSelObjects.Items  = {app.Dots(1).Name};
                for i=2:numel(app.Dots)
                    app.ReportSelObjects.Items{end+1} = app.Dots(i).Name;
                end
                app.lstColoSelObjects.Items  = app.ReportSelObjects.Items;
                app.ReportSelObjectsNN.Items = app.ReportSelObjects.Items;
            else
                app.ReportSelObjects.Items   = {};
                app.lstColoSelObjects.Items  = {};
                app.ReportSelObjectsNN.Items = {};
            end
            app.DetectSelObjectsValueChanged;
            app.ReportSelObjectsValueChanged;
            app.ReportSelObjectsNNValueChanged;            
        end
        
        function RefreshNeuralNets(app)
            NeuralNets = dir([userpath filesep 'ObjectFinder' filesep 'NeuralNets' filesep '*.mat']);
            app.InspectorNeuralNet.Items = {NeuralNets.name};            
        end
        
        function RefreshNearestNeighbors(app)
            app.ReportSelObjectsNN.Items = app.ReportSelObjects.Items;
            app.ReportSelObjectsValueChanged;            
        end
        
        function RefreshColocalizationDone(app)
            app.lstColoDone.Items = {};
            idx_src = find(contains(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value),1);
            
            % Refresh list of processed channels
            if idx_src >0
                if ~isempty(fieldnames(app.Dots(idx_src).Coloc))
                    for i=1:numel(app.Dots(idx_src).Coloc)
                        if isempty(app.lstColo.Items)
                            app.lstColoDone.Items = {[app.Dots(idx_src).Coloc(i).Fish1 '.tif']};
                        else
                            app.lstColoDone.Items{end+1} = [app.Dots(idx_src).Coloc(i).Fish1 '.tif'];
                        end
                    end
                end
            end
            app.lstColoDoneValueChanged;            
        end
        
        function loadImages(app, PostCh, MaskCh)
            % Load automatically images from the I folder, given the pass ID number of PostCh and MaskCh
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            app.Settings = struct;
            app.Settings.TPN = [pwd filesep];
            if ~isfolder([pwd filesep 'data']), mkdir([pwd filesep 'data']); end
            if ~isfolder([pwd filesep 'images']), mkdir([pwd filesep 'images']); end
            
            % Read images to be used for analysis
            tmpDir=[pwd filesep 'I' filesep];
            tmpFiles=dir([tmpDir '*.tif']);
            
            % Get dimensions of the first 3D TIF image (all other imagest must be same)
            if isempty(tmpFiles)
                msgbox('No TIF image available in the image folder.', 'File Error', 'error');
                return;
            end            
            for i = numel(tmpFiles):-1:1
                ImagesInfo(i).ImInfo = imfinfo([tmpDir tmpFiles(i).name]);
                zs(i) = numel(ImagesInfo(i).ImInfo);
                xs(i) = ImagesInfo(i).ImInfo.Width;
                ys(i) = ImagesInfo(i).ImInfo.Height;
            end
            
            % Retrieve XY and Z resolution from TIFF image descriptor from first image
            try
                tmpXYres = num2str(1/ImagesInfo(1).ImInfo(1).XResolution);
                if contains(ImagesInfo(1).ImInfo(1).ImageDescription, 'spacing=')
                    tmpPos = strfind(ImagesInfo(1).ImInfo(1).ImageDescription,'spacing=');
                    tmpZres = ImagesInfo(1).ImInfo(1).ImageDescription(tmpPos+8:end);
                    tmpZres = regexp(tmpZres,'\n','split');
                    tmpZres = tmpZres{1};
                else
                    tmpZres = tmpXYres; % for 2D images which have no spacing field
                end
            catch
                tmpXYres = '0.1'; % default values
                tmpZres  = '0.3'; % default values
            end
            
            % Read all TIF images into a single Iraw matrix (X,Y,Z,Imge#)
            fprintf('Loading image stacks... ');
            app.Log.Value = 'Loading image stacks... ';
            
            Images = struct;
            for i = numel(tmpFiles):-1:1
                Iraw=zeros(ys(i), xs(i), zs(i), 'uint8');
                for j = 1:zs(i)
                    Iraw(:,:,j)=imread([tmpDir tmpFiles(i).name], j);
                end
                Images(i).Iraw = Iraw;
            end
            fprintf('DONE \n');
            app.Log.Value = 'Loading image stacks... DONE';
            
            app.Settings.ImInfo.xNumVox     = xs(PostCh);
            app.Settings.ImInfo.yNumVox     = ys(PostCh);
            app.Settings.ImInfo.zNumVox     = zs(PostCh);
            app.Settings.ImInfo.PostCh      = PostCh;
            app.Settings.ImInfo.PostChName  = tmpFiles(PostCh).name;
            app.Settings.ImInfo.MaskCh      = MaskCh;
            app.Settings.ImInfo.xyum        = str2double(tmpXYres);
            app.Settings.ImInfo.zum         = str2double(tmpZres);
            app.Settings.debug              = false;
            
            % Write Channels into matlab files
            app.Log.Value = 'Saving selected channels... ';
            
            if app.Settings.ImInfo.PostCh
                Post=Images(app.Settings.ImInfo.PostCh).Iraw;
                save([pwd filesep 'Post.mat'], 'Post');
                app.Post = Post;
                app.lblImageFile.Text = ['Image file: ' tmpFiles(app.Settings.ImInfo.PostCh).name];
                app.lblResolution.Text = ['Voxel size: ' num2str(app.Settings.ImInfo.xyum) 'x ' num2str(app.Settings.ImInfo.xyum)  ' x' num2str(app.Settings.ImInfo.zum) ' µm'];
            end
            
            if app.Settings.ImInfo.MaskCh
                Mask = Images(app.Settings.ImInfo.MaskCh).Iraw(:,:,:);
                Mask = Mask / max(Mask(:)); % Normalize mask max value to 1
                save([pwd filesep 'Mask.mat'], 'Mask');
                saveastiff(app.Post, [pwd filesep 'images' filesep 'PostMask.tif']); %save 3-D tiff image of the masked Post
                app.Mask = Mask;
                app.lblMask.Text = ['Mask file: ' tmpFiles(app.Settings.ImInfo.PostCh).name];
            else
                % Create dummy mask with all ones to process the entire image
                Mask = ones(size(app.Post), 'uint8');
                save([pwd filesep 'Mask.mat'], 'Mask');
                app.Mask = Mask;
                app.lblMask.Text = 'Mask file: None';
            end
            app.Log.Value = 'Saving selected channels... DONE';
            
            close all; clear i j Iraw Imax Is tmp* xs ys zs ans;
            
            Settings = app.Settings;
            save([pwd filesep 'Settings.mat'], 'Settings');
            
            % Read Simple Neurite Skeleton if available in the I folder
            tmpDir   = [pwd filesep 'I' filesep];
            tmpFiles = dir([tmpDir '*.traces']);
            if ~isempty(tmpFiles)
                app.Log.Value = 'Converting Simple Neurite Tracer skeleton... ';
                Skel = convertSkelSNT2ml(tmpDir, tmpFiles(1).name);
                save([pwd filesep 'Skel.mat'], 'Skel');
                app.Skel = Skel;
                app.Log.Value = 'Converting Simple Neurite Tracer skeleton...DONE';
            end
            
            app.FindObjectsButton.Enable = 'on';
            app.btnCalcDiameter.Enable   = 'on';            
            app.Lamp.Color               = 'green';            
        end        

        function RefreshAutomateColocalization(app)
            % Populate sets of reference objects available for colocalization 
            if numel(app.Dots) > 0
                app.AutoColoRefObjs.Items  = {app.Dots(1).Name};
                for i=2:numel(app.Dots)
                    app.AutoColoRefObjs.Items{end+1} = app.Dots(i).Name;
                end
            else
                app.ReportSelObjects.Items   = {};
            end  
            
            switch app.AutoColoMaskOrObjs.Value
                case 'Mask' 
                    % Populate list of images available for colocalization
                    app.AutoColoChannel.Items = {};
                    fileList = dir([pwd filesep 'I' filesep '*.tif']);
                    for i = 1:numel(fileList)
                        if isempty(app.AutoColoChannel.Items)
                            app.AutoColoChannel.Items = {fileList(i).name};
                        else
                            app.AutoColoChannel.Items{end+1} = fileList(i).name;
                        end
                    end                
                case 'Objects'
                    % Populate sets of destination objects available for colocalization
                    app.AutoColoChannel.Items  = app.AutoColoRefObjs.Items;
            end
        end
        
    end

    methods (Access = public)
    
        function diam = convertVoxelsToDiameter(app, vox, xyRes, zRes)
            % Calculate the diameter of the sphere having volume equal to the passed number of voxels
            if nargin == 2
                xyRes = app.Settings.ImInfo.xyum;
                zRes = app.Settings.ImInfo.zum;
            end
            diam = ((vox*3*8*xyRes^2*zRes)/(4*pi))^(1/3);            
        end
    
        function vox = convertDiameterToVoxels(app, diam, xyRes, zRes)
            % Calculate volume of a sphere in vogel given its diameter in calibrated units
            if nargin == 2
                xyRes = app.Settings.ImInfo.xyum;
                zRes = app.Settings.ImInfo.zum;
            end
            vox = ceil((4/3*pi*(diam/2)^3) / (xyRes^2*zRes));
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Timer = timer;
            
            % Make sure there is a local folder in MATLAB's user path where to store settings
            if ~isfolder([userpath filesep 'ObjectFinder'])
                mkdir([userpath filesep 'ObjectFinder']);
                disp(['Creating a folder where all ObjectFinder settings will be stored: ' userpath filesep 'ObjectFinder']);
            end
            if ~isfolder([userpath filesep 'ObjectFinder' filesep 'NeuralNets'])
                mkdir([userpath filesep 'ObjectFinder' filesep 'NeuralNets']);
            end
            app.TrainingFolder.Value = [userpath filesep 'ObjectFinder' filesep 'NeuralNets'];
            
            % Check if current working directory is a valid ObjectFinder analysis folder and load data
            if ~isfolder([pwd filesep 'I'])
                app.Lamp.Color = 'white';
                app.Log.Value = 'Select a valid working folder using the first button';
                app.ChooseImagesButton.Enable = 'off';
                app.FindObjectsButton.Enable = 'off';
                app.FilteragainButton.Enable = 'off';
                app.ValidateobjectsButton.Enable = 'off';
            else
                app.ChooseImagesButton.Enable = 'on';
                app.Lamp.Color = 'green';
                app.Log.Value = pwd;
                app.lblFolder.Text = ['Folder:' pwd];
                app.Settings.TPN = [pwd filesep];
                
                % Populate list of channels for colocalization
                app.lstColo.Items = {};
                fileList = dir([pwd filesep 'I' filesep '*.tif']);
                for i=1:numel(fileList)
                    if isempty(app.lstColo.Items)
                        app.lstColo.Items = {fileList(i).name};
                    else
                        app.lstColo.Items{end+1} = fileList(i).name;
                    end
                end
                app.RefreshColocalizationDone;
                
                if exist([pwd filesep 'Settings.mat'], 'file')
                    load([pwd filesep 'Settings.mat'], 'Settings');
                    app.Settings                     = Settings;
                    app.Settings.TPN                 = [pwd filesep]; % update current working folder in Settings.mat
                    Settings                         = app.Settings;
                    save([pwd filesep 'Settings.mat'], 'Settings');
                    app.btnCalcDiameter.Enable = 'on';
                    app.lblResolution.Text = ['Voxel size: ' num2str(app.Settings.ImInfo.xyum) 'x ' num2str(app.Settings.ImInfo.xyum)  ' x' num2str(app.Settings.ImInfo.zum) ' µm'];                    
                end
                if exist([pwd filesep 'Post.mat'], 'file')
                    load([pwd filesep 'Post.mat'], 'Post');
                    app.Post                         = Post;
                else
                    app.Post = [];
                end
                if exist([pwd filesep 'Mask.mat'], 'file')
                    load([pwd filesep 'Mask.mat'], 'Mask');
                    app.Mask                         = Mask;
                else
                    app.Mask                         = [];
                end
                if exist([pwd filesep 'Dots.mat'], 'file')
                    load([pwd filesep 'Dots.mat'], 'Dots');
                    % Ensure Dots structure has a field for shape
                    if ~isfield(Dots, 'Shape')
                        Dots.Shape = struct;
                        Dots.Settings.objfinder.sphericity  = false;
                    end
                    
                    % Ensure Colocalization has fields for Method, NumVoxOverlap, NumPercOverlap
                    for idx_dot = 1:numel(Dots)
                        if ~isempty(fieldnames(Dots(idx_dot).Coloc))
                            for idx_coloc = 1:numel(Dots(idx_dot).Coloc)
                                if ~isfield(Dots(idx_dot).Coloc(idx_coloc), 'Method') || isempty(Dots(idx_dot).Coloc(idx_coloc).Method)
                                    Dots(idx_dot).Coloc(idx_coloc).Method = 'Unknown';
                                    Dots(idx_dot).Coloc(idx_coloc).NumVoxOverlap  = 0;
                                    Dots(idx_dot).Coloc(idx_coloc).NumPercOverlap  = 0;
                                end
                            end
                        end
                    end

                    app.Dots                         = Dots;
                    
                    app.RefreshValidObjects;
                    
                    app.FindObjectsButton.Enable     = 'on';
                    app.FilteragainButton.Enable     = 'on';
                    app.btnPlotDensityByDepth.Enable = 'on';
                    app.ValidateobjectsButton.Enable = 'on';
                else
                    app.Dots                         = [];
                    app.btnPlotDensityByDepth.Enable = 'off';
                    app.ValidateobjectsButton.Enable = 'off';
                    app.FilteragainButton.Enable     = 'off';
                end
                if exist([pwd filesep 'Skel.mat'], 'file')
                    load([pwd filesep 'Skel.mat'], 'Skel');
                    app.Skel                         = Skel;
                    app.btnPlotHeatmap.Enable        = 'on';
                    app.btnPlotSkeleton.Enable       = 'on';
                    app.btnPlotSholl.Enable          = 'on';
                else
                    app.Skel                         = [];
                    app.btnPlotHeatmap.Enable        = 'off';
                    app.btnPlotSkeleton.Enable       = 'off';
                    app.btnPlotSholl.Enable          = 'off';
                end
                app.RefreshValidObjects;
                app.DetectSelObjectsValueChanged;
                app.ReportSelObjectsValueChanged;
            end
        end

        % Button pushed function: ChooseImagesButton
        function ChooseImagesButtonPushed(app, event)
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            app.Settings = struct;
            app.Settings.TPN = [pwd filesep];
            if ~isfolder([pwd filesep 'data']), mkdir([pwd filesep 'data']); end
            if ~isfolder([pwd filesep 'images']), mkdir([pwd filesep 'images']); end
            
            % Read images to be used for analysis
            tmpDir=[pwd filesep 'I' filesep];
            tmpFiles=dir([tmpDir '*.tif']);
            
            % Get dimensions of the first 3D TIF image (all other imagest must be same)
            if isempty(tmpFiles)
                msgbox('No TIF image available in the image folder.', 'File Error', 'error');
                return;
            end            
            for i = numel(tmpFiles):-1:1
                ImagesInfo(i).ImInfo = imfinfo([tmpDir tmpFiles(i).name]);
                zs(i) = numel(ImagesInfo(i).ImInfo);
                xs(i) = ImagesInfo(i).ImInfo.Width;
                ys(i) = ImagesInfo(i).ImInfo.Height;
            end
            
            % Retrieve XY and Z resolution from TIFF image descriptor from first image
            try
                tmpXYres = num2str(1/ImagesInfo(1).ImInfo(1).XResolution);
                if contains(ImagesInfo(1).ImInfo(1).ImageDescription, 'spacing=')
                    tmpPos = strfind(ImagesInfo(1).ImInfo(1).ImageDescription,'spacing=');
                    tmpZres = ImagesInfo(1).ImInfo(1).ImageDescription(tmpPos+8:end);
                    tmpZres = regexp(tmpZres,'\n','split');
                    tmpZres = tmpZres{1};
                else
                    tmpZres = tmpXYres; % for 2D images which have no spacing field
                end
            catch
                tmpXYres = '0.1'; % default values
                tmpZres  = '0.3'; % default values
            end
            
            % Read all TIF images into a single Iraw matrix (X,Y,Z,Imge#)
            fprintf('Loading image stacks... ');
            app.Log.Value = 'Loading image stacks... ';
            
            Images = struct;
            for i = numel(tmpFiles):-1:1
                Iraw=zeros(ys(i), xs(i), zs(i), 'uint8');
                for j = 1:zs(i)
                    Iraw(:,:,j)=imread([tmpDir tmpFiles(i).name], j);
                end
                Images(i).Iraw = Iraw;
            end
            fprintf('DONE \n');
            app.Log.Value = 'Loading image stacks... DONE';
            
            % Show MIPs in a Figure with multiple panels (N*(2 x 3) table)
            figure('Name','Available images','NumberTitle','off', 'units', 'normalized', 'MenuBar', 'none', 'ToolBar', 'none',  'position', [0.05 0.05 0.9 0.9]);
            rows = max(ceil(numel(Images)/3),1);
            cols = ceil(numel(Images)/rows);
            for i = 1:numel(Images)
                subplot(rows,cols,i)
                Imax = squeeze(max(Images(i).Iraw,[],3)); % Create a MIP of each image to display
                if numel(unique(Imax)) == 2 
                    image(Imax*256/max(Imax(:))); % binary mask, stretch from 0 to 255
                else
                    image(Imax); % regular image
                end                
                title({['Image # ' num2str(i)]; tmpFiles(i).name}, 'interpreter', 'none');
                set(gca,'box','off', 'YTickLabel',[],'XTickLabel',[]);
                daspect([size(Imax,1) size(Imax,2) 1]); % Maintain aspect ratio of original image
            end
            colormap gray(256);
            
            % Ask user for image identity settings
            tmpPrompt = {'Objects channel #:','Mask channel # (0 = no mask):','XY resolution :','Z resolution :'};
            tmpAns = inputdlg(tmpPrompt, 'Assign channels', 1,{'1', '0', tmpXYres, tmpZres});
            
            if isempty(tmpAns)
                close all;
                app.Lamp.Color = 'green';
                return
            end
            
            app.Settings.ImInfo.xNumVox     = xs(str2double(tmpAns(1)));
            app.Settings.ImInfo.yNumVox     = ys(str2double(tmpAns(1)));
            app.Settings.ImInfo.zNumVox     = zs(str2double(tmpAns(1)));
            app.Settings.ImInfo.PostCh      = str2double(tmpAns(1));
            app.Settings.ImInfo.PostChName  = tmpFiles(app.Settings.ImInfo.PostCh).name;
            app.Settings.ImInfo.MaskCh      = str2double(tmpAns(2));
            app.Settings.ImInfo.xyum        = str2double(tmpAns(3));
            app.Settings.ImInfo.zum         = str2double(tmpAns(4));
            app.Settings.debug              = false;
            
            % Write Channels into matlab files
            app.Log.Value = 'Saving selected channels... ';
            
            if app.Settings.ImInfo.PostCh
                Post=Images(app.Settings.ImInfo.PostCh).Iraw;
                save([pwd filesep 'Post.mat'], 'Post');
                app.Post = Post;
                app.lblImageFile.Text = ['Image file: ' tmpFiles(app.Settings.ImInfo.PostCh).name];
                app.lblResolution.Text = ['Voxel size: ' num2str(app.Settings.ImInfo.xyum) 'x ' num2str(app.Settings.ImInfo.xyum)  ' x' num2str(app.Settings.ImInfo.zum) ' µm'];
            end
            
            if app.Settings.ImInfo.MaskCh
                Mask = Images(app.Settings.ImInfo.MaskCh).Iraw(:,:,:);
                Mask = Mask / max(Mask(:)); % Normalize mask max value to 1
                save([pwd filesep 'Mask.mat'], 'Mask');
                saveastiff(app.Post, [pwd filesep 'images' filesep 'PostMask.tif']); %save 3-D tiff image of the masked Post
                app.Mask = Mask;
                app.lblMask.Text = ['Mask file: ' tmpFiles(app.Settings.ImInfo.MaskCh).name];
            else
                % Create dummy mask with all ones to process the entire image
                Mask = ones(size(app.Post), 'uint8');
                save([pwd filesep 'Mask.mat'], 'Mask');
                app.Mask = Mask;
                app.lblMask.Text = 'Mask file: None';
            end
            app.Log.Value = 'Saving selected channels... DONE';
            
            close all; clear i j Iraw Imax Is tmp* xs ys zs ans;
            
            Settings = app.Settings;
            save([pwd filesep 'Settings.mat'], 'Settings');
            
            % Read Simple Neurite Skeleton if available in the I folder
            tmpDir   = [pwd filesep 'I' filesep];
            tmpFiles = dir([tmpDir '*.traces']);
            if ~isempty(tmpFiles)
                app.Log.Value = 'Converting Simple Neurite Tracer skeleton... ';
                Skel = convertSkelSNT2ml(tmpDir, tmpFiles(1).name);
                save([pwd filesep 'Skel.mat'], 'Skel');
                app.Skel = Skel;
                app.Log.Value = 'Converting Simple Neurite Tracer skeleton...DONE';
            end
            
            app.FindObjectsButton.Enable = 'on';
            app.btnCalcDiameter.Enable   = 'on';            
            app.Lamp.Color               = 'green';
        end

        % Button pushed function: FindObjectsButton
        function FindObjectsButtonPushed(app, event)
            app.Lamp.Color = 'yellow'; pause(0.2);

            % Warn the user if a set of objects with the same name already exists
            [~, Name, ~] = fileparts(app.Settings.ImInfo.PostChName);
            for i = 1 : numel(app.Dots)
                if strcmp(app.Dots(i).Name, Name)
                    switch questdlg({'A set of objects with the same name already exists.';'Do you want to overwrite it?';' ';
                                    ['Existing set name: ' app.Dots(i).Name];'If not sure, select "No" and rename it before proceeding.'},...
                                    'Overwrite objects?', 'Yes', 'No', 'No')
                        case 'Yes'
                            break;
                        case 'No'
                            app.Lamp.Color = 'Green';
                            app.Log.Value = 'Search aborted by the user';
                            return
                    end
                end
            end
                        
            % Calculate volume of the minimum / maximum object sizes allowed, assuming they are spheres
            % largest CtBP2 = elipsoid 1um-xy/2um-z diameter = ~330 voxels (.103x.103x0.3 um pixel size)
            MaxDotSize = app.convertDiameterToVoxels(app.MaxSizeXY.Value);
            MinDotSize = app.convertDiameterToVoxels(app.MinSizeXY.Value);
            
            app.Settings.objfinder.blockBuffer      = ceil(1.1 * app.MinSizeXY.Value/app.Settings.ImInfo.xyum);  % Overlapping buffer region between search block should be 1.1*big as the biggest dot to avoid artifacts.
            app.Settings.objfinder.blockSize        = max(64, ceil(3*app.MaxSizeXY.Value/app.Settings.ImInfo.xyum));
            app.Settings.objfinder.maxDotSize       = MaxDotSize;             % max dot size exclusion criteria.
            app.Settings.objfinder.minDotSize       = MinDotSize;             % min dot size exclusion criteria.
            app.Settings.objfinder.watershed        = app.Watershed.Value;    % False if you want to skip watershed segmentation during object search
            app.Settings.objfinder.sphericity       = app.Sphericity.Value;   % True if you want to calculare sphericity
            app.Settings.objfinder.blockSearch      = app.BlockSearch.Value;  % True to enable block search with multithreading
            app.Settings.objfinder.minIntensity     = app.MinIntensity.Value; % Minumum allowed voxel intensity (# times the local noise of the block)                        
            app.Settings.objfinder.localNoise       = app.LocalNoise.Value;   % If true noise level will be computer for each block, if false only one noise level per entire image

            % Seach objects inside the masked volume
            app.Log.Value = 'Searching objects in the volume (please wait)... ';
            app.Settings.objfinder.method = 'IterativeThresholding';
            Objs = findObjects(app.Post.*app.Mask, app.Settings);
            if isempty(Objs)
                app.Lamp.Color = 'Red';
                return
            end            
            app.Log.Value = 'Searching objects in the volume (please wait)...DONE';
            
            % Create fields about sphericity of objects (Rounding)
            Objs.Shape = struct;
            if app.Sphericity.Value
                app.Log.Value = 'Calculating sphericity of objects... ';
                Objs = fitSphere(Objs, app.Settings);
                app.Log.Value = 'Calculating sphericity of objects... DONE';
            end
            
            % Assign a name to Objects base on the raw image filename
            try
                [~,Objs.Name,~] = fileparts(app.Settings.ImInfo.PostChName);
            catch
                Objs.Name = 'PSD95';
            end
            
            % Filter all objects
            app.Settings.Filter.EdgeDotCut               = app.EdgeDotCut.Value;
            app.Settings.Filter.SingleZDotCut            = app.SingleZDotCut.Value;
            app.Settings.Filter.xyStableDots             = app.xyStableDots.Value;
            app.Settings.Filter.Thresholds.ITMax         = 0;  % custom thresholds disabled
            app.Settings.Filter.Thresholds.Vol           = 0;  % custom thresholds disabled
            app.Settings.Filter.Thresholds.MeanBright    = 0;  % custom thresholds disabled
            app.Settings.Filter.Thresholds.ITMaxDir      = 1;  % 1 means ">= than threshold"
            app.Settings.Filter.Thresholds.VolDir        = 1;  % 1 means ">= than threshold"
            app.Settings.Filter.Thresholds.MeanBrightDir = 1;  % 1 means ">= than threshold"
            
            Objs.Filter = filterObjects(app.Settings, Objs, app.Settings.Filter);
            Objs.Settings = app.Settings;
            
            % Add structs placeholder for parameters calculated later
            Objs.Density = struct; % Density measurements
            Objs.NN      = struct; % Nearest neighbor measurements
            Objs.Coloc   = struct; % Colocalization measurements
            Objs.Skel    = struct; % Skeleton measurements (e.g distance to cell body)
            
            % If a set of objects with the same name already exists overwrite them, otherwise append            
            if isempty(app.Dots)
                app.Dots = Objs;
            else                 
                AlreadyPresent  = false;
                for i = 1:numel(app.Dots)
                    if strcmp(app.Dots(i).Name, Objs.Name)
                        app.Dots(i) = Objs;
                        AlreadyPresent = true;
                        break
                    end
                end
                
                if ~AlreadyPresent
                    app.Dots(end+1) = Objs;
                end
            end
            
            app.RefreshValidObjects;
            app.DetectSelObjects.Value = Objs.Name;
            app.DetectSelObjectsValueChanged;
            app.ReportSelObjects.Value = Objs.Name;
            
            % Save dots on disk
            Dots = app.Dots;
            save([pwd filesep 'Dots.mat'],'Dots');
            Settings = app.Settings;
            save([pwd filesep 'Settings.mat'],'Settings');
            
            app.FilteragainButton.Enable = 'on';
            app.Lamp.Color = 'green';
        end

        % Button pushed function: FilteragainButton
        function FilteragainButtonPushed(app, event)
            % Filter objects according to the following post-processing criteria (SG)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            % Retrieve index of current objects
            idx = find(strcmp(app.DetectSelObjects.Items, app.DetectSelObjects.Value),1) -1;
            if idx
                app.Dots(idx).Settings.Filter.EdgeDotCut               = app.EdgeDotCut.Value;
                app.Dots(idx).Settings.Filter.SingleZDotCut            = app.SingleZDotCut.Value;
                app.Dots(idx).Settings.Filter.xyStableDots             = app.xyStableDots.Value;
                app.Dots(idx).Settings.Filter.Thresholds.ITMax         = 0;  % custom thresholds disabled
                app.Dots(idx).Settings.Filter.Thresholds.Vol           = 0;  % custom thresholds disabled
                app.Dots(idx).Settings.Filter.Thresholds.MeanBright    = 0;  % custom thresholds disabled
                app.Dots(idx).Settings.Filter.Thresholds.Oblong        = 0;  % custom thresholds disabled
                app.Dots(idx).Settings.Filter.Thresholds.PrincipalAxisLen = 0;  % custom thresholds disabled
                app.Dots(idx).Settings.Filter.Thresholds.ITMaxDir      = 1;  % 1 means ">= than threshold"
                app.Dots(idx).Settings.Filter.Thresholds.VolDir        = 1;  % 1 means ">= than threshold"
                app.Dots(idx).Settings.Filter.Thresholds.MeanBrightDir = 1;  % 1 means ">= than threshold"
                app.Dots(idx).Settings.Filter.Thresholds.OblongDir     = 1;  % 1 means ">= than threshold"
                app.Dots(idx).Settings.Filter.Thresholds.PrincipalAxisLenDir = 1;  % 1 means ">= than threshold"
                
                app.Log.Value = 'Filtering objects... ';
                app.Dots(idx).Filter = filterObjects(app.Settings, app.Dots(idx), app.Dots(idx).Settings.Filter);
                Dots = app.Dots;
                save([pwd filesep 'Dots.mat'],'Dots');
                app.Log.Value = 'Filtering objects...DONE';
                
                app.DetectSelObjectsValueChanged;
                app.ValidateobjectsButton.Enable = 'on';
                app.Lamp.Color = 'green';
            else
                app.Log.Value = 'Error: cannot find requested objects';
                app.Lamp.Color = 'red';
            end
        end

        % Button pushed function: ValidateobjectsButton
        function ValidateobjectsButtonPushed(app, event)
            app.Lamp.Color = 'yellow'; pause(0.2);
            app.Log.Value = ['Validating objects using ' app.InspectorType.Value];
            
            % Retrieve index of current objects
            idx = find(strcmp(app.DetectSelObjects.Items, app.DetectSelObjects.Value),1) -1;
            if idx > 0
                switch app.InspectorType.Value
                    case 'Volume Inspector'
                        if ~strcmp(app.PostChName, app.Dots(idx).Settings.ImInfo.PostChName)
                            app.Post = loadImage(app.Dots(idx).Settings.ImInfo.PostChName);
                        end
                        inspectVolume2D(app.Post.*app.Mask, app.Dots(idx), app.Dots(idx).Filter);
                        
                    case 'Bitplane Imaris (7.2.3 or older)'
                        exportObjectsToImaris7(app.Dots(idx)); % Transfer objects to Imaris
                        
                    case 'Bitplane Imaris (7.2.4 or newer)'
                        exportObjectsToImaris8(app.Dots(idx)); % Transfer objects to Imaris
                        
                    case 'Neural Net'
                        load([userpath filesep 'ObjectFinder' filesep 'NeuralNets' filesep app.InspectorNeuralNet.Value], 'NeuralNet');
                        app.NeuralNet = NeuralNet;
                        Filter = validateObjectWithNeuralNet(app.Dots(idx), app.NeuralNet);
                        save([pwd filesep 'Filter.mat'], 'Filter');
                end
                
                % Reload the filter from file as user might have changed it using the volume inspector
                if exist([pwd filesep 'Filter.mat'], 'file')
                    load([pwd filesep 'Filter.mat'], 'Filter');
                    app.Filter = Filter;
                    if app.InspectorNewObjects.Value
                        %duplicate current objects
                        app.Dots(end+1) = app.Dots(idx);
                        idx = numel(app.Dots);
                        app.Dots(idx).Name = [app.Dots(idx).Name '_valid'];
                        app.RefreshValidObjects;
                    end
                    app.Dots(idx).Filter = app.Filter;
                    Dots = app.Dots;
                    save([pwd filesep 'Dots.mat'],'Dots');
                    delete 'Filter.mat';
                end
                
                % Calculate density of validated objects
                app.Log.Value = 'Calculating density of validated objects... ';
                % If the cell has a skeleton proceed with these additional steps
                if exist([pwd filesep 'Skel.mat'], 'file')
                    load([pwd filesep 'Skel.mat'], 'Skel');
                    app.Skel = Skel;
                    app.Skel = calcSkelPathLength(app.Skel, false);
                    Skel = app.Skel;
                    save([pwd filesep 'Skel.mat'],'Skel');
                    
                    app.Dots(idx) = distDotsToCellBody(app.Dots(idx), app.Skel, app.Dots(idx).Settings);
                    app.Dots(idx) = distDotsToSkel(app.Dots(idx), app.Skel, app.Dots(idx).Settings);
                    % Generate heatmaps of object density
                    app.Dots(idx).Density = calcDensity(app.Dots(idx).Settings, app.Dots(idx), app.Skel, false);
                    % Calculate linear density along the skeleton segments
                    app.Dots(idx).Density.PathLengthStats = calcPathLengthStats(app.Dots(idx), app.Skel, false);
                    Dots = app.Dots;
                    save([pwd filesep 'Dots.mat'],'Dots');
                else
                    % Compute and plot object distribution as function of volume depth
                    app.Dots(idx).Density = calcDotDensityAlongZ(app.Dots(idx).Settings, getFilteredObjects(app.Dots(idx), app.Dots(idx).Filter), false);
                    Dots = app.Dots;
                    save([pwd filesep 'Dots'], 'Dots');
                end
                
                app.DetectSelObjectsValueChanged;
                app.ReportSelObjectsValueChanged;
                app.Log.Value = ['Validating objects using ' app.InspectorType.Value '...DONE'];
                app.Lamp.Color = 'green';
            end
        end

        % Callback function
        function CalcDensityButtonPushed(app, event)
            % deprecated
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            tmpDir = uigetdir;
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            if tmpDir
                cd(tmpDir);
                if exist('find','dir')
                    answer = questdlg('Data was analyzed with Objectfinder version 3, do you want to convert it?',...
                        'Old data format detected', 'Yes', 'No', 'Yes');
                    switch answer
                        case 'Yes'
                            convertObjectFinderDataV3toV4;
                            convertObjectFinderDataV4toV5;
                            app.startupFcn;
                    end
                elseif exist('Density.mat','file')
                    answer = questdlg('Data was analyzed with Objectfinder version 4, do you want to convert it?',...
                        'Old data format detected', 'Yes', 'No', 'Yes');
                    switch answer
                        case 'Yes'
                            convertObjectFinderDataV4toV5;
                            app.startupFcn;
                    end                    
                end
                app.startupFcn;
                app.RefreshColocalizationDone;
            end
            
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnPlotLocationsXY
        function btnPlotLocationsXYPushed(app, event)
            if ~isempty(app.Skel)
                app.btnPlotSkeletonPushed;
            end
            
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            idx = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            if idx > 0
                % Plot passing objects
                Objs = getFilteredObjects(app.Dots(idx), app.Dots(idx).Filter);
                tmpA = zeros(numel(Objs.Vol), 2);
                for i=1:numel(Objs.Vol)
                    tmpA(i,:)= mean(Objs.Vox(i).Pos(:,1:2));
                end
                
                scatter(tmpA(:,1), tmpA(:,2), 25, 'o', 'MarkerEdgeColor', [0 0.5 0.5]);
                xlim([0 app.Dots(idx).Settings.ImInfo.xNumVox]);
                ylim([0 app.Dots(idx).Settings.ImInfo.yNumVox]);
                box on;
                set(gca, 'XTick', [], 'YTick', []);
            end
            
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnPlotSkeleton
        function btnPlotSkeletonPushed(app, event)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            idx = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            if (idx > 0) && ~isempty(app.Skel)
                load([pwd filesep 'Skel.mat'], 'Skel');
                Skel.XYZ = Skel.FilStats.aXYZ;
                Skel.SomaPtID = Skel.FilStats.SomaPtID+1;
                Skel.FilStats.aEdges = Skel.FilStats.aEdges - Skel.FilStats.aEdges(1,1) + 1; % shift index edges so that first element starts with 1
                
                %% March edges to find matching pairs and recreate the branching pattern
                % For each branch first the march from cell body is computer, then
                % all points in common with previously calculated branches are removed
                % this leaves 1 segment per branch without any diplicate
                vNumberOfSpots = length(Skel.FilStats.aRad); % Store total number of points we need to iterate
                % Find position of terminal and biforcation points in the filament connectivity (aEdges)
                vNumberOfTerminals = 0;
                vTerminals = [];
                vNumberOfForks = 0;
                vForks = [];
                % Start investigating all spots except root (start from position #2 to exclude root)
                for vSpots = 2:vNumberOfSpots
                    vEdge = find(Skel.FilStats.aEdges==vSpots);
                    % if current edge is a terminal point of the skeleton, it should be listed once
                    if length(vEdge)==1
                        % disp('found a terminal point');
                        vNumberOfTerminals = vNumberOfTerminals + 1;
                        vTerminals(vNumberOfTerminals) = vSpots;
                    elseif length(vEdge)>2
                        % disp('found fork point');
                        vNumberOfForks = vNumberOfForks + 1;
                        vForks(vNumberOfForks) = vSpots;
                    end
                end
                
                % March backwards from each terminal point to the root in orher to find entire branch path
                vPaths=[]; % keeps an ongoing list of points already assigned to a branch
                for vTerminalIndex = 1:vNumberOfTerminals
                    vLength = 1;
                    vTerminal = vTerminals(vTerminalIndex); % start from terminal point
                    vPath = vTerminal;  % add the terminal point to current path
                    
                    vFound = true;
                    while vFound
                        % find among edges which one is connect to current terminal
                        % vEdge contains the number to wich vTerminal is connected
                        % vSide contains which side of the edge is vTerminal in this connection
                        [vEdge, vSide] = find(Skel.FilStats.aEdges==vTerminal);
                        
                        vFound = false;
                        for vNeighborIndex = 1:length(vEdge)
                            % looks like is marching both directions here
                            vNeighbor = Skel.FilStats.aEdges(vEdge(vNeighborIndex), 3-vSide(vNeighborIndex));
                            vNeighborSide = 3-vSide(vNeighborIndex); % Store side of the neighbor in the connectivity
                            if vNeighbor<vTerminal
                                vNewTerminal = vNeighbor;
                                vFound = true;
                            end
                        end
                        if vFound
                            vLength = vLength + 1;
                            vTerminal = vNewTerminal;
                            vPath(vLength) = vTerminal;
                        end
                    end
                    
                    vPath = fliplr(vPath); % Flip path so that is not going terminal->root but root->terminal instead
                    vPath = setdiff(vPath, vPaths); % Remove common part with the paths previously calculated
                    vPaths = cat(2,vPaths, vPath); % Add current path to paths
                    
                    Skel.branches(vTerminalIndex).XYZ = Skel.FilStats.aXYZ(vPath,:);
                    Skel.branches(vTerminalIndex).Rad = Skel.FilStats.aRad(vPath);
                    %Skels(i).branches(vTerminalIndex).Edges = [1:vLength-1;2:vLength]';
                    Skel.TotalBranches = numel(Skel.branches);
                end
                
                % Plot
                figure; hold on;
                for i=1:numel(Skel.branches)
                    plot(Skel.branches(i).XYZ(:,2)/app.Dots(idx).Settings.ImInfo.xyum, Skel.branches(i).XYZ(:,1)/app.Dots(idx).Settings.ImInfo.xyum, 'k-', 'LineWidth', 1.5);
                end
                scatter(Skel.FilStats.aXYZ(1,1)/app.Dots(idx).Settings.ImInfo.xyum, Skel.FilStats.aXYZ(1,2)/app.Dots(idx).Settings.ImInfo.xyum, 1000, 'o', 'LineWidth', 1.5, 'MarkerEdgeColor', [0.5 0 0.5]);
                xlim([1 app.Dots(idx).Settings.ImInfo.xNumVox]);
                ylim([1 app.Dots(idx).Settings.ImInfo.yNumVox]);
                box on;
                set(gca, 'XTick', [], 'YTick', []);
                pbaspect([1 1 1]);
            else
                msgbox('No skeleton available.', 'Plot Error', 'error');
            end
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnPlotDensityByDepth
        function btnPlotDensityByDepthPushed(app, event)
            % Compute and plot object distribution as function of volume depth
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            idx = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            if idx > 0
                app.Dots(idx).Density = calcDotDensityAlongZ(app.Settings, getFilteredObjects(app.Dots(idx), app.Dots(idx).Filter), false);
                
                % Plot object density along Z
                sizeBin = app.Settings.ImInfo.xyum * app.Settings.ImInfo.xNumVox...
                    *app.Settings.ImInfo.xyum * app.Settings.ImInfo.yNumVox...
                    *app.Settings.ImInfo.zum* app.Dots(idx).Density.binSize;
                
                tmpY = app.Dots(idx).Density.densityPerc / sizeBin;
                tmpX = 1:100;
                plot(tmpX, tmpY, 'k', 'MarkerSize', 8);
                box off;
                xlim([0 100]);
                ylim([0 max(tmpY)]);
                set(gca, 'XTick', [0:10:100]);
                set(gca, 'YTickMode', 'auto');
                ylabel('Density (objects/µm^3)');
                xlabel('Volume depth percentage');
                
                % Export data as Excel table inside the results folder
                if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                FileName    = [pwd filesep 'results' filesep app.Dots(idx).Name '_DensityByDepth.xls'];
                DepthPerc   = (1:100)';                     % Number of object on first column
                Density     = tmpY';                        % Volume of each object on second column
                TableData   = table(DepthPerc, Density);
                writetable(TableData, FileName);            % Write an Excel table with Depth-Density pairs
            end
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnPlotDensityLinear
        function btnPlotDensityLinearPushed(app, event)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            idx = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            if (idx > 0) && ~isempty(app.Skel)
                load([pwd filesep 'Skel.mat'], 'Skel');
                app.Skel = Skel;
                
                Objs                = getFilteredObjects(app.Dots(idx), app.Dots(idx).Filter);
                DotPathLengthList   = app.Skel.FilStats.SkelPathLength2Soma(Objs.Skel.ClosestSkelIDs); % For each object length of path to soma
                EdgePathLengthList  = app.Skel.FilStats.EdgePathLength2Soma; % For each skeleton segment, path length to soma
                EdgeLengthList      = app.Skel.SegStats.Lengths; % List of lengths of each skeleton segment
                EdgePathLengthMax   = ceil(max(EdgePathLengthList));
                
                DistBin             = 10; % Bin distance is 10 micron by default
                DistFromSoma        = 5:1:EdgePathLengthMax;
                clear NumDots EdgeLengths;
                for i = 1 : length(DistFromSoma)
                    NumDots(i)      = length(find((DotPathLengthList>DistFromSoma(i)-DistBin/2) & (DotPathLengthList<=DistFromSoma(i)+DistBin/2)));
                    EdgeLengths(i)  = sum(EdgeLengthList((EdgePathLengthList>DistFromSoma(i)-DistBin/2) & (EdgePathLengthList<=DistFromSoma(i)+DistBin/2)));
                end
                LinearDensity              = NumDots./EdgeLengths;
                
                DepthBin = 2;
                for z = 1 : Objs.Settings.ImInfo.zNumVox
                    MaskZ = (Objs.Pos(:,3) > z-DepthBin/2) & (Objs.Pos(:,3) <= z+DepthBin/2);
                    for i = 1 : length(DistFromSoma)
                        NumDots(i)      = length(find((DotPathLengthList(MaskZ)>DistFromSoma(i)-DistBin/2) & (DotPathLengthList(MaskZ)<=DistFromSoma(i)+DistBin/2)));
                        EdgeLengths(i)  = sum(EdgeLengthList((EdgePathLengthList>DistFromSoma(i)-DistBin/2) & (EdgePathLengthList<=DistFromSoma(i)+DistBin/2)));
                    end
                    LinDensity          = NumDots./EdgeLengths;
                    LinDensityByDepth(z)= mean(LinDensity>0); % As some distances from soma might not exist at the currenct Z depth, average only those for which there is a non-zero density
                end
                
                % Plot
                subplot(1,2,1)
                plot(DistFromSoma, LinearDensity, 'b', 'LineWidth', 2);
                xlim([0 EdgePathLengthMax]);
                ylim([0 max(1, max(LinearDensity))]);
                xlabel(['Distance from cell body (µm), binning = ' num2str(DistBin) ' µm']);
                ylabel('Linear density (average objects / µm)');
                
                subplot(1,2,2)
                plot((1:numel(LinDensityByDepth)).*Objs.Settings.ImInfo.zum, LinDensityByDepth, 'b', 'LineWidth', 2);
                xlabel(['Depth (µm), binning = ' num2str(DepthBin*Objs.Settings.ImInfo.zum) ' µm']);
                
                % Export data as Excel table inside the results folder
                if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                FileName    = [pwd filesep 'results' filesep app.Dots(idx).Name '_DensityAlongSkel.xls'];
                Distance    = DistFromSoma';                % Distance from the skeleton's origin point (usually cell soma)
                Density     = LinearDensity';               % Objects density at each distance from skeleton origin point
                TableData   = table(Distance, Density);
                writetable(TableData, FileName);            % Write an Excel table with Distance-Density pairs
                
                FileName    = [pwd filesep 'results' filesep app.Dots(idx).Name '_DensityAlongSkelByDepth.xls'];
                Depth       = (1:numel(LinDensityByDepth))';  % Depth in the volume (micron)
                DensityZ    = LinDensityByDepth';           % Objects linear density by depth
                TableData   = table(Depth, DensityZ);
                writetable(TableData, FileName);            % Write an Excel table with Distance-Density pairs
                
            else
                msgbox('No skeleton available.', 'Plot Error', 'error');
            end
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnPlotSholl
        function btnPlotShollPushed(app, event)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            idx = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            if (idx > 0) && ~isempty(app.Skel)
                load([pwd filesep 'Skel.mat'], 'Skel');
                app.Skel                 = Skel;
                app.Skel.XYZ             = app.Skel.FilStats.aXYZ;
                app.Skel.SomaPtID        = app.Skel.FilStats.SomaPtID+1;
                app.Skel.FilStats.aEdges = app.Skel.FilStats.aEdges - app.Skel.FilStats.aEdges(1,1) + 1; % shift index edges so that first element starts with 1
                
                %% March edges to find matching pairs and recreate the branching pattern
                % For each branch first the march from cell body is computer, then
                % all points in common with previously calculated branches are removed
                % this leaves 1 segment per branch without any diplicate
                vNumberOfSpots = length(app.Skel.FilStats.aRad); % Store total number of points we need to iterate
                % Find position of terminal and biforcation points in the filament connectivity (aEdges)
                vNumberOfTerminals = 0;
                vTerminals = [];
                vNumberOfForks = 0;
                vForks = [];
                % Start investigating all spots except root (start from position #2 to exclude root)
                for vSpots = 2:vNumberOfSpots
                    vEdge = find(app.Skel.FilStats.aEdges==vSpots);
                    % if current edge is a terminal point of the skeleton, it should be listed once
                    if length(vEdge)==1
                        % disp('found a terminal point');
                        vNumberOfTerminals = vNumberOfTerminals + 1;
                        vTerminals(vNumberOfTerminals) = vSpots;
                    elseif length(vEdge)>2
                        % disp('found fork point');
                        vNumberOfForks = vNumberOfForks + 1;
                        vForks(vNumberOfForks) = vSpots;
                    end
                end
                
                % March backwards from each terminal point to the root in orher to find entire branch path
                vPaths=[]; % keeps an ongoing list of points already assigned to a branch
                for vTerminalIndex = 1:vNumberOfTerminals
                    vLength = 1;
                    vTerminal = vTerminals(vTerminalIndex); % start from terminal point
                    vPath = vTerminal;  % add the terminal point to current path
                    
                    vFound = true;
                    while vFound
                        % find among edges which one is connect to current terminal
                        % vEdge contains the number to wich vTerminal is connected
                        % vSide contains which side of the edge is vTerminal in this connection
                        [vEdge, vSide] = find(app.Skel.FilStats.aEdges==vTerminal);
                        
                        vFound = false;
                        for vNeighborIndex = 1:length(vEdge)
                            % looks like is marching both directions here
                            vNeighbor = app.Skel.FilStats.aEdges(vEdge(vNeighborIndex), 3-vSide(vNeighborIndex));
                            vNeighborSide = 3-vSide(vNeighborIndex); % Store side of the neighbor in the connectivity
                            if vNeighbor<vTerminal
                                vNewTerminal = vNeighbor;
                                vFound = true;
                            end
                        end
                        if vFound
                            vLength = vLength + 1;
                            vTerminal = vNewTerminal;
                            vPath(vLength) = vTerminal;
                        end
                    end
                    
                    vPath = fliplr(vPath); % Flip path so that is not going terminal->root but root->terminal instead
                    vPath = setdiff(vPath, vPaths); % Remove common part with the paths previously calculated
                    vPaths = cat(2,vPaths, vPath); % Add current path to paths
                    
                    app.Skel.branches(vTerminalIndex).XYZ = app.Skel.FilStats.aXYZ(vPath,:);
                    app.Skel.branches(vTerminalIndex).Rad = app.Skel.FilStats.aRad(vPath);
                    %Skels(i).branches(vTerminalIndex).Edges = [1:vLength-1;2:vLength]';
                    app.Skel.TotalBranches = numel(app.Skel.branches);
                end
                app.Skel.Sholl = shollAnalysis(app.Skel, 1, false);
                
                % Plot
                plot(app.Skel.Sholl.intersections(:,1), app.Skel.Sholl.intersections(:,2), 'b', 'LineWidth', 2);
                xlim([0 app.Skel.Sholl.intersections(end,1)]);
                ylim([0 max(app.Skel.Sholl.intersections(:,2))]);
                xlabel('Distance from cell body (µm)');
                ylabel('Sholl intersections');
                
                % Export data as Excel table inside the results folder
                if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                FileName        = [pwd filesep 'results' filesep app.Dots(idx).Name '_ShollAnalysis.xls'];
                Distance        = app.Skel.Sholl.intersections(:,1); % Concentric distance from the skeleton's origin point (usually cell soma)
                Intersections   = app.Skel.Sholl.intersections(:,2); % Number of Sholl intersections at each distance from skeleton origin point
                TableData       = table(Distance, Intersections);
                writetable(TableData, FileName);                      % Write an Excel table with Distance-Intersections pairs
            else
                msgbox('No skeleton available.', 'Plot Error', 'error');
            end
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnPlotHeatmap
        function btnPlotHeatmapPushed(app, event)
            % Drap heatmap of linear density (objects over skeleton filament)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            idx = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            if (idx > 0) && ~isempty(app.Skel)
                Density = calcDensity(app.Dots(idx).Settings, app.Dots(idx), app.Skel, false);
                image(Density.DotDist ./ Density.DendDist * 256);
                cmap        = jet(256);
                cmap(1,:)   = 0;
                colormap(cmap);
                title('Linear density (objects/µm, blue=0 red=1)');
                colorbar('Ytick',[]);
                axis image; % adding color bar changes image x y dimention, so resize the image.
                axis off    % remove the axis
            else
                msgbox('No skeleton available.', 'Plot Error', 'error');
            end
            
            app.Lamp.Color = 'green';
        end

        % Callback function
        function SchedulerValueChanged(app, event)
            if app.Scheduler.Value
                app.ScheduledTime.Enable = 'on';
            else
                app.ScheduledTime.Enable = 'off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.Timer)
                stop(app.Timer);
            end
            delete(app);
        end

        % Button pushed function: btnBatchAdd
        function btnBatchAddButtonPushed(app, event)
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            tmpDir = uigetdir;
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            
            if tmpDir
                cd(tmpDir);
                if ~isfile("Settings.mat") || ~isfile("Post.mat") || ~isfile("Mask.mat") || app.chkBatchNewImages.Value
                    ChooseImagesButtonPushed(app, event);
                end
                
                if isempty(app.lstBatch.Items)
                    app.lstBatch.Items = {tmpDir};
                else
                    app.lstBatch.Items{end+1} = tmpDir;
                end
            end
            app.RefreshAutomateColocalization;
            app.lstBatchValueChanged;
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnBatchRemove
        function btnBatchRemoveButtonPushed(app, event)
            idx = find(strcmp(app.lstBatch.Items, app.lstBatch.Value));
            app.lstBatch.Items(idx) = [];
            app.RefreshAutomateColocalization;
            app.lstBatchValueChanged;
        end

        % Button pushed function: btnBatchClear
        function btnBatchClearButtonPushed(app, event)
            switch questdlg({'Do you want to remove all items in this list?';'Nothing will be deleted from disk.'},'Remove all items', 'No', 'Yes', 'No')
                case 'Yes'
                app.lstBatch.Items = {};
                app.lstBatchValueChanged;
                app.RefreshAutomateColocalization;
            end
        end

        % Button pushed function: btnBatchDown
        function btnBatchDownButtonPushed(app, event)
            idx = find(strcmp(app.lstBatch.Items, app.lstBatch.Value),1);
            val = app.lstBatch.Value;
            if idx < numel(app.lstBatch.Items)
                app.lstBatch.Items(idx) = app.lstBatch.Items(idx+1);
                app.lstBatch.Items{idx+1} = val;
                app.lstBatch.Value = val;
            end
        end

        % Button pushed function: btnBatchUp
        function btnBatchUpButtonPushed(app, event)
            idx = find(strcmp(app.lstBatch.Items, app.lstBatch.Value),1);
            val = app.lstBatch.Value;
            if idx > 1
                app.lstBatch.Items(idx) = app.lstBatch.Items(idx-1);
                app.lstBatch.Items{idx-1} = val;
                app.lstBatch.Value = val;
            end
        end

        % Button pushed function: btnBatchBottom
        function btnBatchBottomButtonPushed(app, event)
            idx = find(strcmp(app.lstBatch.Items, app.lstBatch.Value),1);
            val = app.lstBatch.Value;
            if idx < numel(app.lstBatch.Items)
                app.lstBatch.Items(idx) = [];
                app.lstBatch.Items{end+1} = val;
                app.lstBatch.Value = val;
            end
        end

        % Button pushed function: btnBatchTop
        function btnBatchTopButtonPushed(app, event)
            idx = find(strcmp(app.lstBatch.Items, app.lstBatch.Value),1);
            val = app.lstBatch.Value;
            if idx > 1
                app.lstBatch.Items(idx) = [];
                app.lstBatch.Items = [{val}, app.lstBatch.Items];
                app.lstBatch.Value = val;
            end
        end

        % Button pushed function: btnBatchStart
        function btnBatchStartButtonPushed(app, event)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            % Delay execution of batch processing if timer is enabled
            if app.BatchScheduler.Value
                if app.BatchScheduledTime.Value > hour(datetime)
                    app.TimerDelay = round(app.BatchScheduledTime.Value*60 - hour(datetime)*60 - minute(datetime)); % Custom time is today
                else
                    app.TimerDelay = round(app.BatchScheduledTime.Value+24)*60 - hour(datetime)*60 - minute(datetime); %Custom time is tomorrow
                end
                app.BatchLog.Value{end+1} = ['Waiting ' num2str(app.TimerDelay) ' minutes, then start searching for objects... '];
                app.Timer = timer('TimerFcn',@(~,~)disp('Time is up.'),'StartDelay',app.TimerDelay*60);
                start(app.Timer);
                wait(app.Timer);
                delete(app.Timer);
                app.BatchLog.Value{end} = [app.BatchLog.Value{end} 'Timer is up! starting analysis.'];
            end
            
            app.BatchLog.Value = {['-- Starting batch on ' datestr(now)]};
            app.BatchLog.Value{end+1} = '';
            app.btnBatchStart.Enable = 'off';
            
            TableData = table;
            for i = 1:numel(app.lstBatch.Items)
                
                % Stop batch processing if Stop button was pressed
                if app.btnBatchStop.Value
                    break;
                else
                    path = regexp(app.lstBatch.Items{i},filesep,'split');                    
                    app.BatchLog.Value{end+1} = ['Processing item # ' num2str(i) ': ' path{end}];
                    disp(['Processing item # ' num2str(i) ': ' path{end}]);
                end
                
                % Load files of the current item to process
                app.lstBatch.Value = app.lstBatch.Items{i}; pause(0.2);
                cd(app.lstBatch.Items{i});
                if ~isfolder([pwd filesep 'I'])
                    app.BatchLog.Value{end+1} = ['Error: Working folder not valid for analysis ' pwd];
                    continue;
                end
                
                app.Log.Value = pwd;
                app.Settings.TPN = [pwd filesep];
                if exist([pwd filesep 'Settings.mat'], 'file')
                    load([pwd filesep 'Settings.mat'], 'Settings');
                    app.Settings = Settings;
                    app.Settings.TPN = [pwd filesep];
                end
                if exist([pwd filesep 'Post.mat'], 'file')
                    load([pwd filesep 'Post.mat'], 'Post');
                    app.Post = Post;
                end
                if exist([pwd filesep 'Mask.mat'], 'file')
                    load([pwd filesep 'Mask.mat'], 'Mask');
                    app.Mask = Mask;
                end
                if exist([pwd filesep 'Dots.mat'], 'file')
                    load([pwd filesep 'Dots.mat'], 'Dots');
                    app.Dots = Dots;
                else
                    app.Dots = [];
                end
                
                if app.AutomateOperation.SelectedTab == app.AutoSearchTab
                    % Calculate volume of the minimum / maximum object sizes allowed, assuming they are spheres
                    MaxDotSize = app.convertDiameterToVoxels(app.BatchMaxSizeXY.Value);
                    MinDotSize = app.convertDiameterToVoxels(app.BatchMinSizeXY.Value);
                    
                    app.Settings.objfinder.blockBuffer      = ceil(1.1*app.BatchMinSizeXY.Value/app.Settings.ImInfo.xyum); % Overlapping buffer region between search block should be at least as big as the smallest dot we want to measure to make sure we are not missing any.
                    app.Settings.objfinder.blockSize        = max(64, ceil(3*app.BatchMaxSizeXY.Value/app.Settings.ImInfo.xyum));
                    app.Settings.objfinder.maxDotSize       = MaxDotSize;                   % max dot size exclusion criteria.
                    app.Settings.objfinder.minDotSize       = MinDotSize;                   % min dot size exclusion criteria.
                    app.Settings.objfinder.watershed        = app.BatchWatershed.Value;     % False if you want to skip watershed segmentation during object search
                    app.Settings.objfinder.blockSearch      = app.BatchBlockSearch.Value;   % True to enable searching in blocks for multithreaded speed
                    app.Settings.objfinder.sphericity       = app.BatchSphericity.Value;    % True if you want to calculare sphericity
                    app.Settings.objfinder.minIntensity     = app.BatchMinIntensity.Value;  % Minumum allowed voxel intensity (# times the local noise of the block)
                    app.Settings.objfinder.localNoise       = app.BatchLocalNoise.Value;    % If true noise level will be computer for each block, if false only one noise level per entire image
                    
                    % Seach objects inside the masked volume
                    app.BatchLog.Value{end+1}               = 'Searching objects... '; pause(0.2);
                    app.Settings.objfinder.method           = 'IterativeThresholding';
                    Objs                                    = findObjects(app.Post.*app.Mask, app.Settings);
                    app.BatchLog.Value{end}                 = [app.BatchLog.Value{end} 'DONE'];
                    
                    % Create fields about sphericity of objects (Rounding)
                    Objs.Shape = struct;
                    if app.BatchSphericity.Value
                        app.BatchLog.Value{end+1}           = 'Calculating sphericity of objects... ';
                        Objs                                = fitSphere(Objs, app.Settings);
                        app.BatchLog.Value{end}             = [app.BatchLog.Value{end} 'DONE'];
                    end
                    
                    % Assign a name to Objects base on the raw image filename
                    try
                        [~,Objs.Name,~] = fileparts(app.Settings.ImInfo.PostChName);
                    catch
                        Objs.Name = 'PSD95';
                    end
                    
                    % Filter objects according to filter panel settings
                    app.Settings.Filter.EdgeDotCut            = app.BatchEdgeDotCut.Value;
                    app.Settings.Filter.SingleZDotCut         = app.BatchSingleZDotCut.Value;
                    app.Settings.Filter.xyStableDots          = app.BatchxyStableDots.Value;
                    app.Settings.Filter.Thresholds.ITMax      = 0;
                    app.Settings.Filter.Thresholds.Vol        = 0;
                    app.Settings.Filter.Thresholds.MeanBright = 0;
                    switch app.BatchThreshParam.Value
                        case 'Score'
                            app.Settings.Filter.Thresholds.ITMax         = app.BatchThreshValue.Value;
                            app.Settings.Filter.Thresholds.ITMaxDir      = 1;
                        case 'Volume'
                            app.Settings.Filter.Thresholds.Vol           = app.BatchThreshValue.Value;
                            app.Settings.Filter.Thresholds.VolDir        = 1;
                        case 'Brightness'
                            app.Settings.Filter.Thresholds.Oblong        = app.BatchThreshValue.Value;
                            app.Settings.Filter.Thresholds.OblongDir     = 1;
                        case 'Roundness'
                            app.Settings.Filter.Thresholds.Roundness     = app.BatchThreshValue.Value;
                            app.Settings.Filter.Thresholds.RoundnessDir  = 1;
                        case 'Major Axis Lenght'
                            app.Settings.Filter.Thresholds.PrincipalAxisLen     = app.BatchThreshValue.Value;
                            app.Settings.Filter.Thresholds.PrincipalAxisLenhDir = 1;
                            
                    end
                    
                    app.BatchLog.Value{end+1} = 'Filtering objects... '; pause(0.2);
                    Objs.Filter = filterObjects(app.Settings, Objs, app.Settings.Filter);
                    Objs.Settings = app.Settings;
                    
                    % Add structs placeholder for parameters calculated later
                    Objs.Density = struct; % Density measurements
                    Objs.NN      = struct; % Nearest neighbor measurements
                    Objs.Coloc   = struct; % Colocalization measurements
                    Objs.Skel    = struct; % Skeleton measurements (e.g distance to cell body)
                    
                    % If a set of objects with the same name already exists overwrite them, otherwise append
                    if isempty(app.Dots)
                        app.Dots = Objs;
                    else
                        AlreadyPresent  = false;
                        for i = 1:numel(app.Dots)
                            if strcmp(app.Dots(i).Name, Objs.Name)
                                app.Dots(i) = Objs;
                                AlreadyPresent = true;
                                break
                            end
                        end
                        
                        if ~AlreadyPresent
                            app.Dots(end+1) = Objs;
                        end
                    end
                    
                    Dots= app.Dots;
                    save([pwd filesep 'Dots.mat'],'Dots');
                    Settings = app.Settings;
                    save([pwd filesep 'Settings.mat'],'Settings');
                    
                    app.BatchLog.Value{end} = [app.BatchLog.Value{end} 'DONE'];
                    app.BatchLog.Value{end+1} = ['Number of valid objects: ' num2str(numel(find(Objs.Filter.passF)))];
                    app.BatchLog.Value{end+1} = ' '; pause(0.5);
                end
                
                if app.AutomateOperation.SelectedTab == app.AutoColocalizeTab
                    switch app.AutoColoSwitchVoxPerc.Value
                        case 'Voxels'
                            NumVoxOverlap  = app.AutoColoVoxNum.Value;
                            NumPercOverlap = 0;
                        case 'Percent'
                            NumVoxOverlap  = 0;
                            NumPercOverlap = app.AutoColoVoxNum.Value;
                    end
                    
                    if app.chkAutoColoAll.Value
                        idx_srcs = 1:numel(app.Dots);
                    else
                        idx_srcs = find(strcmp(app.AutoColoRefObjs.Items, app.AutoColoRefObjs.Value),1);
                    end
                    
                    for idx = 1:numel(idx_srcs)
                        idx_src = idx_srcs(idx);
                        switch app.AutoColoMaskOrObjs.Value
                            
                            case 'Mask'
                                PathName = [pwd filesep 'I' filesep];
                                FileName = app.AutoColoChannel.Value;
                                if isempty(FileName)
                                    return
                                end
                                
                                Colo = loadImage(FileName);
                                if numel(unique(Colo)) == 2     % Ensure that loaded image is indeed a binary mask before proceeding
                                    Colo = Colo./max(Colo(:));  % Normalize mask values to 0-1 (ImageJ uses 0-255)
                                    save([pwd filesep 'Colo.mat'],'Colo', '-v7.3');
                                    ColocAuto = colocAutoMask(app.Dots(idx_src), app.Dots(idx_src).Filter, Colo, [PathName FileName], NumVoxOverlap, NumPercOverlap);
                                else
                                    disp('Selected image is not a binary mask');
                                    continue
                                end
                                
                            case 'Objects'
                                idx_dst = find(strcmp(app.AutoColoChannel.Items, app.AutoColoChannel.Value),1);
                                idx_NN  = 0;
                                
                                for nn = 1: numel(app.Dots(idx_src).NN)
                                    if numel(fieldnames(app.Dots(idx_src).NN(nn))) > 0
                                        if strcmp(app.Dots(idx_src).NN(nn).Name,  app.Dots(idx_dst).Name)
                                            idx_NN = nn; % Found the matching item
                                            break
                                        end
                                    end
                                end
                                
                                if (idx_NN == 0) && (idx_src >0) && (idx_dst >0)
                                    % Calculate nearest neighbor's distance and overlap
                                    xyum        = app.Dots(idx_src).Settings.ImInfo.xyum;
                                    zum         = app.Dots(idx_src).Settings.ImInfo.zum;
                                    tmpPts1     = app.Dots(idx_src).Pos;
                                    tmpVol1     = app.Dots(idx_src).Vol;
                                    tmpPts2     = app.Dots(idx_dst).Pos;
                                    tmpPts1N    = size(tmpPts1,1);
                                    NN          = struct;
                                    NN.Name     = app.Dots(idx_dst).Name;
                                    IncludeSelf = (idx_src ~= idx_dst); % Do not include self if calculating NN of objects against themselves
                                    tic;
                                    disp([' Calculating pairwise nearest neighbors (' app.Dots(idx_src).Name '->' app.Dots(idx_dst).Name ')']);
                                    app.BatchLog.Value{end+1}   = 'Calculating Nearest Neighbors...';
                                    for p1 = tmpPts1N:-1:1 % NN distance (Nearest p2 to each p1)
                                        xyDist            = hypot( (tmpPts1(p1,1)-tmpPts2(:,1))*xyum, (tmpPts1(p1,2)-tmpPts2(:,2))*xyum);
                                        xyzDist           = hypot( xyDist, (tmpPts1(p1,3)-tmpPts2(:,3))*zum); % calculate separately along Z because this dimension has different pixel size
                                        if IncludeSelf
                                            p2Distance        = min(xyzDist);                 % Distance to nearest neighbor
                                        else
                                            p2Distance = min(setdiff(xyzDist(:), min(xyzDist(:)))); % Distance to 2nd nearest neighbor (1st is self)
                                        end
                                        p2                = find(xyzDist == p2Distance,1);    % Insex of the nearest neighbor
                                        NN.Dist(p1)       = p2Distance;                       % Store calibrated distance
                                        NN.NeighborIdx(p1)= p2;                               % Store neighbor's index
                                        NN.VoxOverlap(p1) = size(intersect(app.Dots(idx_src).Vox(p1).Pos, app.Dots(idx_dst).Vox(p2).Pos,'rows'),1);
                                        NN.VoxOverlapPerc(p1) = 100 * NN.VoxOverlap(p1) / tmpVol1(p1); % Store voxel overlap as percent of source object volume
                                    end
                                    
                                    % Accumulate info inside current Objects
                                    if isempty(fieldnames(app.Dots(idx_src).NN))
                                        app.Dots(idx_src).NN = NN;
                                    else
                                        app.Dots(idx_src).NN(end+1) = NN;
                                    end
                                    idx_NN = numel(app.Dots(idx_src).NN);
                                    
                                    Dots = app.Dots;
                                    save([pwd filesep 'Dots.mat'], 'Dots');
                                    disp([' -- done in ' num2str(toc) ' seconds']);
                                    app.BatchLog.Value{end} = [app.BatchLog.Value{end} 'DONE'];
                                end
                                
                                if idx_NN > 0
                                    % Assign colocalized status to those objects that have more than NumVoxOverlap AND NumPercOverlap
                                    ColocAuto = colocAutoNN(app.Dots(idx_src), app.Dots(idx_src).NN(idx_NN), NumVoxOverlap, NumPercOverlap);
                                else
                                    disp('Nearest neighbor distances are not calculated');
                                end
                        end
                        
                        % Store colocalization results in Objs.Coloc struct array
                        if isempty(fieldnames(app.Dots(idx_src).Coloc))
                            app.Dots(idx_src).Coloc = ColocAuto;
                        else
                            % Check if we need to replace a previously done analysis with same mask
                            FoundColoc  = false;
                            for i=1:numel(app.Dots(idx_src).Coloc)
                                if strcmp(app.Dots(idx_src).Coloc(i).Source, ColocAuto.Source) && strcmp(app.Dots(idx_src).Coloc(i).Fish1, ColocAuto.Fish1)
                                    app.Dots(idx_src).Coloc(i) = ColocAuto;
                                    FoundColoc = true;
                                    break
                                end
                            end
                            
                            % If nothing to replace, just append to the list
                            if ~FoundColoc
                                app.Dots(idx_src).Coloc(end+1) = ColocAuto; % Coloc is saved later
                            end
                        end
                        
                        % If selected by user, also save colocalized objects as a new set
                        if app.chkColoSaveAsNewObjects.Value
                            app.Dots(end+1) = app.Dots(idx_src);
                            app.Dots(end).Name = [app.Dots(end).Name ' colocalized with ' ColocAuto.Fish1];
                            app.Dots(end).Filter.passF = (ColocAuto.ColocFlag == 1);
                            app.Dots(end).Coloc = struct;
                            app.RefreshValidObjects;
                        end
                        
                        Dots = app.Dots;
                        save([pwd filesep 'Dots.mat'], 'Dots');
                        
                        % If selected by user, create a table with colocalization statistics
                        if app.chkAutoColoSaveTable.Value
                            FolderName  = strsplit(pwd,filesep);
                            ColocObjs   = numel(find(ColocAuto.ColocFlag == 1));
                            TotalObjs   = numel(ColocAuto.ColocFlag);
                            ColocRate   = 100*ColocObjs/TotalObjs;
                            RowData = table(FolderName(end), {ColocAuto.Source}, {ColocAuto.Fish1}, TotalObjs, ColocObjs, ColocRate,...
                                'VariableNames', {'Folder', 'SourceObjs', 'Destination', 'TotalObjs', 'ColocObjs', 'ColocRate'});
                            
                            % Accumulate data of current mask size as table rows
                            if isempty(TableData)
                                TableData = RowData;
                            else
                                TableData = [TableData; RowData]; %#ok
                            end
                        end
                    end
                end
                
                % Interrupt batch processing at this point if user pressed the stop button
                if app.btnBatchStop.Value
                    app.BatchLog.Value{end+1}   = '-- Processing interrupted by user.';
                    app.btnBatchStop.Value      = false;
                    app.btnBatchStart.Enable    = 'on';                    
                    break
                end
            end

            % Write results table to disk as an Excel spreadsheet
            if ~isempty(TableData)
                % Select the root folder of current pwd, this is the common folder of all batch-analyzed experiments
                FolderName  = strsplit(pwd,filesep);
                FolderName  = strsplit(pwd, FolderName(end));
                FolderName  = FolderName{1};
                
                disp(['Writing results to ' FolderName 'AutomatedMeasurements.xls']);
                writetable(TableData, [FolderName 'AutomatedMeasurements.xls']);
            end            
            
            app.BatchLog.Value{end+1} = ['-- Batch complete! ' datestr(now) ' ---'];
            app.btnBatchStart.Enable = 'on';
            app.startupFcn;
            app.Lamp.Color = 'green';
        end

        % Value changed function: BatchScheduler
        function BatchSchedulerValueChanged(app, event)
            if app.BatchScheduler.Value
                app.BatchScheduledTime.Enable = 'on';
                app.lblBatchScheduler.Enable  = 'on';
            else
                app.BatchScheduledTime.Enable = 'off';
                app.lblBatchScheduler.Enable = 'off';
            end
        end

        % Callback function
        function btnColoAddButtonPushed(app, event)
            [FileName, PathName] = uigetfile([pwd filesep 'I' filesep '*.tif']);
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            if FileName
                if isempty(app.lstColo.Items)
                    app.lstColo.Items = {FileName};
                else
                    app.lstColo.Items{end+1} = FileName;
                end
                app.lstColo.Value = FileName;
            end
            
        end

        % Callback function
        function btnColoRemoveButtonPushed(app, event)
            idx = find(strcmp(app.lstColo.Items, app.lstColo.Value));
            app.lstColo.Items(idx) = [];
        end

        % Button pushed function: btnColocalize
        function btnColocalizeButtonPushed(app, event)
            % Load channel to colocalize
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            % Reloach objects image if different than the one stored in memory
            idx_src = find(strcmp(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value),1);
            if ~strcmp(app.PostChName, app.Dots(idx_src).Settings.ImInfo.PostChName)
                app.Post = loadImage(app.Dots(idx_src).Settings.ImInfo.PostChName);
            end
            
            PathName = [pwd filesep 'I' filesep];
            FileName = app.lstColo.Value{1};
            if ~isempty(FileName)
                Colo = loadImage(FileName);
                ColocManual = colocManual(app.Dots(idx_src), app.Dots(idx_src).Filter, app.Post, Colo, [PathName FileName], [], '');
                assignin('base', 'ColocManual', ColocManual);
                if ~isempty(ColocManual)  && ~isempty(fieldnames(ColocManual))
                    if isempty(fieldnames(app.Dots(idx_src).Coloc))
                        app.Dots(idx_src).Coloc = ColocManual;
                    else
                        % Check if we need to replace a previously done analysis with same mask
                        for j=1:numel(ColocManual)
                            FoundColocManual  = false;
                            for i=1:numel(app.Dots(idx_src).Coloc)
                                if strcmp(app.Dots(idx_src).Coloc(i).Source, ColocManual(j).Source) && strcmp(app.Dots(idx_src).Coloc(i).Fish1, ColocManual(j).Fish1)
                                    app.Dots(idx_src).Coloc(i) = ColocManual(j);
                                    FoundColocManual = true;
                                    break
                                end
                            end
                            
                            % If nothing to replace, just append to the list
                            if ~FoundColocManual
                                app.Dots(idx_src).Coloc(end+1) = ColocManual(j); % Coloc is saved later
                            end
                        end
                    end
                    
                    Dots = app.Dots;
                    save([pwd filesep 'Dots.mat'], 'Dots');
                    app.RefreshColocalizationDone;
                end
            end
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnColoDoneRemove
        function btnColoDoneRemoveButtonPushed(app, event)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            idx_src = find(strcmp(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value),1);
            idx = find(strcmp(app.lstColoDone.Items, app.lstColoDone.Value));
            
            if numel(app.Dots(idx_src).Coloc) > 1
                app.Dots(idx_src).Coloc(idx) = [];
                Dots = app.Dots;
                save([pwd filesep 'Dots.mat'], 'Dots');
            else
                app.btnColoDoneClearButtonPushed;
            end
            app.RefreshColocalizationDone;
            
            app.Lamp.Color = 'green';            
        end

        % Button pushed function: btnColoDoneClear
        function btnColoDoneClearButtonPushed(app, event)
            switch questdlg('Do you really want to permanently remove all items?','Delete all items', 'No', 'Yes', 'No')
                case 'Yes'
                    idx_src = find(strcmp(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value),1);
                    app.Dots(idx_src).Coloc = struct;
                    Dots = app.Dots;
                    save([pwd filesep 'Dots.mat'], 'Dots');
                    app.RefreshColocalizationDone;
            end            
        end

        % Button pushed function: btnColoRedoValid
        function btnColoRedoValidButtonPushed(app, event)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            % Reload objects image if different than the one stored in memory
            idx_src = find(strcmp(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value),1);
            if ~strcmp(app.PostChName, app.Dots(idx_src).Settings.ImInfo.PostChName)
                app.Post = loadImage(app.Dots(idx_src).Settings.ImInfo.PostChName);
            end

            switch numel(app.lstColoDone.Value)
                case 1 % User selected only one item on screen, display colocalization stats
                    FileName = app.lstColoDone.Value{1};
                    if ~isempty(FileName)
                        Colo        = loadImage(FileName);                                                
                        [~, idx]    = ismember(app.lstColoDone.Value, app.lstColoDone.Items); % Get index of selected list items
                        ColocManual = app.Dots(idx_src).Coloc(idx(1));                        
                        ColocManual = colocManualCheckDouble(app.Dots(idx_src), app.Dots(idx_src).Filter, app.Post, Colo, ColocManual, [], []);
                    end
                case 2 % User selected two items from list, display double colocalization stats
                    % Load colocalizing channels' images
                    FileName1       = app.lstColoDone.Value{1};
                    FileName2       = app.lstColoDone.Value{2};
                    if ~isempty(FileName1) && ~isempty(FileName2)
                        Colo        = loadImage(FileName1);
                        Colo2       = loadImage(FileName2);                                                
                        [~, idx]    = ismember(app.lstColoDone.Value, app.lstColoDone.Items); % Get index of selected list items
                        ColocManual = app.Dots(idx_src).Coloc(idx(1));
                        ColocManual2= app.Dots(idx_src).Coloc(idx(2));
                        ColocManual = colocManualCheckDouble(app.Dots(idx_src), app.Dots(idx_src).Filter, app.Post, Colo, ColocManual, Colo2, ColocManual2);
                    end
            end

            % Check whether the user aborted the manual colocalization before finishing
            if isempty(ColocManual) || isempty(fieldnames(ColocManual))
                disp('User aborted manual colocalization inspection, values are not saved to disk')
                app.Lamp.Color = 'green';
                return
            end
            
            if isempty(fieldnames(app.Dots(idx_src).Coloc))
                app.Dots(idx_src).Coloc = ColocManual;
            else
                % Check if we need to replace a previously done analysis with same mask
                for j=1:numel(ColocManual)
                    FoundColocManual  = false;
                    for i=1:numel(app.Dots(idx_src).Coloc)
                        if strcmp(app.Dots(idx_src).Coloc(i).Source, ColocManual(j).Source) && strcmp(app.Dots(idx_src).Coloc(i).Fish1, ColocManual(j).Fish1)
                            app.Dots(idx_src).Coloc(i) = ColocManual(j);
                            FoundColocManual = true;
                            break
                        end
                    end
                    
                    % If nothing to replace, just append to the list
                    if ~FoundColocManual
                        app.Dots(idx_src).Coloc(end+1) = ColocManual(j); % Coloc is saved later
                    end
                end
            end
            
            Dots = app.Dots;
            save([pwd filesep 'Dots.mat'], 'Dots');
            app.RefreshColocalizationDone;
            app.Lamp.Color = 'green';
        end

        % Value changed function: lstColoDone
        function lstColoDoneValueChanged(app, event)
            %get index of selected values
            %idx = find(strcmp(app.lstColoDone.Items, app.lstColoDone.Value),1);
            [~, idx] = ismember(app.lstColoDone.Value, app.lstColoDone.Items);
            idx_src = find(contains(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value),1);
            
            switch numel(app.lstColoDone.Value)
                case 1 % User selected only one item on screen, display colocalization stats
                    Colocalized1 = numel(find(app.Dots(idx_src).Coloc(idx).ColocFlag == 1));
                    TotalObjects = numel(app.Dots(idx_src).Coloc(idx).ColocFlag);
                    app.btnColoRedoDiscarded.Enable = 'on';
                    app.btnColoRedoValid.Enable     = 'on';
                    app.lblReferenceName.Text       = ['Reference objects: ' app.Dots(idx_src).Coloc(idx).Source ' (' num2str(numel(app.Dots(idx_src).Coloc(idx).ColocFlag)) ' valid objects)'];
                    app.lblColocRate.Text           = [app.Dots(idx_src).Coloc(idx).Fish1 ': ' num2str(Colocalized1) ' (' num2str(100*Colocalized1/TotalObjects) '% of valid objects)'];                    app.lblColoc2Rate.Text          = 'Colocalized: N/A';
                    app.lblColoc2Rate.Text          = 'Colocalized with: N/A';
                    app.lblColocRateDouble.Text     = 'Double colocalized: N/A';
                    if isfield(app.Dots(idx_src).Coloc(idx), 'Method') && contains(app.Dots(idx_src).Coloc(idx).Method, 'Auto')
                        app.lblColoMethod.Text      = ['Method: ' app.Dots(idx_src).Coloc(idx).Method];
                        app.lblColoVoxThresh.Text   = ['Vox. threshold: ' num2str(app.Dots(idx_src).Coloc(idx).NumVoxOverlap)];
                        app.lblColoPercThresh.Text  = ['Perc. threshold:' num2str(app.Dots(idx_src).Coloc(idx).NumPercOverlap)];
                    else
                        app.lblColoMethod.Text      = 'Method:';
                        app.lblColoVoxThresh.Text   = 'Vox. threshold:';
                        app.lblColoPercThresh.Text  = 'Perc. threshold:'; 
                    end
                    app.btnColoDoneUp.Enable        = 'on';
                    app.btnColoDoneDown.Enable      = 'on';
                    app.btnColoDoneRemove.Enable    = 'on';
                    
                case 2 % User selected two items from list, display double colocalization stats
                    Colocalized1 = numel(find(app.Dots(idx_src).Coloc(idx(1)).ColocFlag == 1));
                    Colocalized2 = numel(find(app.Dots(idx_src).Coloc(idx(2)).ColocFlag == 1));
                    DoubleColoc  = numel(find(app.Dots(idx_src).Coloc(idx(1)).ColocFlag == 1 & app.Dots(idx_src).Coloc(idx(2)).ColocFlag==1));                    
                    TotalObjects = numel(app.Dots(idx_src).Coloc(idx(1)).ColocFlag);
                    app.btnColoRedoDiscarded.Enable = 'on';
                    app.btnColoRedoValid.Enable     = 'on';
                    app.lblReferenceName.Text       = ['Reference objects: ' app.Dots(idx_src).Coloc(idx(1)).Source ' (' num2str(numel(app.Dots(idx_src).Coloc(idx(1)).ColocFlag)) ' valid objects)'];
                    app.lblColocRate.Text           = [app.Dots(idx_src).Coloc(idx(1)).Fish1 ': ' num2str(Colocalized1) ' (' num2str(100*Colocalized1/TotalObjects) '% of valid objects)'];
                    app.lblColoc2Rate.Text          = [app.Dots(idx_src).Coloc(idx(2)).Fish1 ': ' num2str(Colocalized2) ' (' num2str(100*Colocalized2/TotalObjects) '% of valid objects)'];                    
                    app.lblColocRateDouble.Text     = ['Double colocalized: ' num2str(DoubleColoc) ' (' num2str(100*DoubleColoc /TotalObjects) '% of valid, ' num2str(100*DoubleColoc/Colocalized1) '% of colocalized with ' app.Dots(idx_src).Coloc(idx(2)).Fish1 ')'];
                    app.lblColoMethod.Text          = 'Method:';
                    app.lblColoVoxThresh.Text       = 'Vox. threshold:';
                    app.lblColoPercThresh.Text      = 'Perc. threshold:';
                    app.btnColoDoneUp.Enable        = 'off';
                    app.btnColoDoneDown.Enable      = 'off';
                    app.btnColoDoneRemove.Enable    = 'off';
                    
                otherwise % Nothing selected or more than two items selected, do not display any info
                    app.btnColoRedoDiscarded.Enable = 'off';
                    app.btnColoRedoValid.Enable     = 'off';
                    app.lblReferenceName.Text       = 'Reference objects: none';
                    app.lblColocRate.Text           = 'Colocalized with: N/A';
                    app.lblColoc2Rate.Text          = 'Colocalized with: N/A';
                    app.lblColocRateDouble.Text     = 'Double colocalized: N/A';
                    app.lblColoMethod.Text          = 'Method:';
                    app.lblColoVoxThresh.Text       = 'Vox. threshold:';
                    app.lblColoPercThresh.Text      = 'Perc. threshold:';
                    app.btnColoDoneUp.Enable        = 'off';
                    app.btnColoDoneDown.Enable      = 'off';
                    app.btnColoDoneRemove.Enable    = 'off';
            end
        end

        % Button pushed function: btnColoRedoDiscarded
        function btnColoRedoDiscardedButtonPushed(app, event)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            % Reload objects image if different than the one stored in memory
            idx_src = find(strcmp(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value),1);
            if ~strcmp(app.PostChName, app.Dots(idx_src).Settings.ImInfo.PostChName)
                app.Post = loadImage(app.Dots(idx_src).Settings.ImInfo.PostChName);
            end

            switch numel(app.lstColoDone.Value)
                case 1 % User selected only one item on screen, display colocalization stats
                    FileName = app.lstColoDone.Value{1};
                    if ~isempty(FileName)
                        Colo        = loadImage(FileName);                                                
                        [~, idx]    = ismember(app.lstColoDone.Value, app.lstColoDone.Items); % Get index of selected list items
                        ColocManual = app.Dots(idx_src).Coloc(idx(1));                        
                        ColocManual = colocManualCheckNotColoc(app.Dots(idx_src), app.Dots(idx_src).Filter, app.Post, Colo, ColocManual, [], []);
                    end
                case 2 % User selected two items from list, display double colocalization stats
                    % Load colocalizing channels' images
                    FileName1       = app.lstColoDone.Value{1};
                    FileName2       = app.lstColoDone.Value{2};
                    if ~isempty(FileName1) && ~isempty(FileName2)
                        Colo        = loadImage(FileName1);
                        Colo2       = loadImage(FileName2);                                                
                        [~, idx]    = ismember(app.lstColoDone.Value, app.lstColoDone.Items); % Get index of selected list items
                        ColocManual = app.Dots(idx_src).Coloc(idx(1));
                        ColocManual2= app.Dots(idx_src).Coloc(idx(2));
                        ColocManual = colocManualCheckNotColoc(app.Dots(idx_src), app.Dots(idx_src).Filter, app.Post, Colo, ColocManual, Colo2, ColocManual2);
                    end
            end

            % Check whether the user aborted the manual colocalization before finishing
            if isempty(ColocManual) || isempty(fieldnames(ColocManual))
                disp('User aborted manual colocalization inspection, values are not saved to disk')
                app.Lamp.Color = 'green';
                return
            end
            
            if isempty(fieldnames(app.Dots(idx_src).Coloc))
                app.Dots(idx_src).Coloc = ColocManual;
            else
                % Check if we need to replace a previously done analysis with same mask
                for j=1:numel(ColocManual)
                    FoundColocManual  = false;
                    for i=1:numel(app.Dots(idx_src).Coloc)
                        if strcmp(app.Dots(idx_src).Coloc(i).Source, ColocManual(j).Source) && strcmp(app.Dots(idx_src).Coloc(i).Fish1, ColocManual(j).Fish1)
                            app.Dots(idx_src).Coloc(i) = ColocManual(j);
                            FoundColocManual = true;
                            break
                        end
                    end
                    
                    % If nothing to replace, just append to the list
                    if ~FoundColocManual
                        app.Dots(idx_src).Coloc(end+1) = ColocManual(j); % Coloc is saved later
                    end
                end
            end
            
            Dots = app.Dots;
            save([pwd filesep 'Dots.mat'], 'Dots');
            app.RefreshColocalizationDone;
            app.Lamp.Color = 'green';
        end

        % Value changed function: lstColo
        function lstColoValueChanged(app, event)
            switch numel(app.lstColoSelObjects.Value)
                case 1
                    switch numel(app.lstColo.Value)
                        case 1
                            app.btnColocalize.Enable     = 'on';
                            app.btnColocalize2.Enable    = 'off';
                            app.btnColocalizeMask.Enable = 'on';
                            app.btnColocalizeNN.Enable   = 'off';
                            app.ColoSharedVoxNum.Enable  = 'on';
                            app.ColoSwitchVoxPerc.Enable = 'on';
                        case 2
                            app.btnColocalize.Enable     = 'off';
                            app.btnColocalize2.Enable    = 'on';
                            app.btnColocalizeMask.Enable = 'off';
                            app.btnColocalizeNN.Enable   = 'off';
                            app.ColoSharedVoxNum.Enable  = 'off';
                            app.ColoSwitchVoxPerc.Enable = 'off';
                        otherwise
                            app.btnColocalize.Enable     = 'off';
                            app.btnColocalize2.Enable    = 'off';
                            app.btnColocalizeMask.Enable = 'off';
                            app.btnColocalizeNN.Enable   = 'off';
                            app.ColoSharedVoxNum.Enable  = 'off';
                            app.ColoSwitchVoxPerc.Enable = 'off';
                    end
                otherwise
                    app.btnColocalize.Enable     = 'off';
                    app.btnColocalize2.Enable    = 'off';
                    app.btnColocalizeMask.Enable = 'off';
                    app.btnColocalizeNN.Enable   = 'off';
                    app.ColoSharedVoxNum.Enable  = 'off';
                    app.ColoSwitchVoxPerc.Enable = 'off';
            end
        end

        % Button pushed function: btnColocalize2
        function btnColocalize2ButtonPushed(app, event)
            % Load channel to colocalize
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            % Reloach objects image if different than the one stored in memory
            idx_src = find(strcmp(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value),1);
            if ~strcmp(app.PostChName, app.Dots(idx_src).Settings.ImInfo.PostChName)
                app.Post = loadImage(app.Dots(idx_src).Settings.ImInfo.PostChName);
            end
            
            % Load colocalizing channels' images
            PathName = [pwd filesep 'I' filesep];
            FileName1 = app.lstColo.Value{1};
            FileName2 = app.lstColo.Value{2};
            if ~isempty(FileName1) && ~isempty(FileName2)
                Colo = loadImage(FileName1);
                Colo2 = loadImage(FileName2);
            end
            
            ColocManual = colocManual(app.Dots(idx_src), app.Dots(idx_src).Filter, app.Post, Colo, [PathName FileName1], Colo2, [PathName FileName2]);
            
            if ~isempty(ColocManual) && ~isempty(fieldnames(ColocManual))
                if isempty(fieldnames(app.Dots(idx_src).Coloc))
                    app.Dots(idx_src).Coloc = ColocManual;
                else
                    % Check if we need to replace a previously done analysis with same mask
                    for j=1:numel(ColocManual)
                        FoundColocManual  = false;
                        for i=1:numel(app.Dots(idx_src).Coloc)
                            if strcmp(app.Dots(idx_src).Coloc(i).Source, ColocManual(j).Source) && strcmp(app.Dots(idx_src).Coloc(i).Fish1, ColocManual(j).Fish1)
                                app.Dots(idx_src).Coloc(i) = ColocManual(j);
                                FoundColocManual = true;
                                break
                            end
                        end
                        
                        % If nothing to replace, just append to the list
                        if ~FoundColocManual
                            app.Dots(idx_src).Coloc(end+1) = ColocManual(j); % Coloc is saved later
                        end
                    end
                end
                
                Dots = app.Dots;
                save([pwd filesep 'Dots.mat'], 'Dots');
                app.RefreshColocalizationDone;
            end
            
            app.Lamp.Color = 'green';
            
        end

        % Button pushed function: btnColoDoneUp
        function btnColoDoneUpButtonPushed(app, event)
            idx_src = find(strcmp(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value),1);
            idx = find(strcmp(app.lstColoDone.Items, app.lstColoDone.Value),1);
            val = app.lstColoDone.Value;
            if idx > 1
                tmpColoc = app.Dots(idx_src).Coloc(idx-1);
                app.Dots(idx_src).Coloc(idx-1) = app.Dots(idx_src).Coloc(idx);
                app.Dots(idx_src).Coloc(idx) = tmpColoc;
                Dots = app.Dots;
                save([pwd filesep 'Dots.mat'], 'Dots');
                app.RefreshColocalizationDone;
                app.lstColoDone.Value = val;
            end
        end

        % Button pushed function: btnColoDoneDown
        function btnColoDoneDownButtonPushed(app, event)
            idx_src = find(strcmp(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value),1);
            idx = find(strcmp(app.lstColoDone.Items, app.lstColoDone.Value),1);
            val = app.lstColoDone.Value;
            if idx < numel(app.lstColoDone.Items)
                tmpColoc = app.Dots(idx_src).Coloc(idx+1);
                app.Dots(idx_src).Coloc(idx+1) = app.Dots(idx_src).Coloc(idx);
                app.Dots(idx_src).Coloc(idx) = tmpColoc;
                Dots = app.Dots;
                save([pwd filesep 'Dots.mat'], 'Dots');
                app.RefreshColocalizationDone;
                app.lstColoDone.Value = val;
            end
            
        end

        % Value changed function: lstBatch
        function lstBatchValueChanged(app, event)
            if isempty(app.lstBatch.Items)
                app.btnBatchRemove.Enable   = 'off';
                app.btnBatchClear.Enable    = 'off';
                app.btnBatchUp.Enable       = 'off';
                app.btnBatchDown.Enable     = 'off';
                app.btnBatchTop.Enable      = 'off';
                app.btnBatchBottom.Enable   = 'off';
            else
                app.btnBatchRemove.Enable   = 'on';
                app.btnBatchClear.Enable    = 'on';
                app.btnBatchUp.Enable       = 'on';
                app.btnBatchDown.Enable     = 'on';
                app.btnBatchTop.Enable      = 'on';
                app.btnBatchBottom.Enable   = 'on';
            end
        end

        % Value changed function: lstTraining
        function lstTrainingValueChanged(app, event)
            if isempty(app.lstTraining.Items)
                app.btnTrainingRemove.Enable             = 'off';
                app.btnTrainingClear.Enable              = 'off';
                app.btnTrainingUp.Enable                 = 'off';
                app.btnTrainingDown.Enable               = 'off';
                app.btnTrainingTop.Enable                = 'off';
                app.btnTrainingBottom.Enable             = 'off';
                app.GenerateTrainingDatasetButton.Enable = 'off';
                app.TrainNeuralNetworkButton.Enable      = 'off';
            else
                app.btnTrainingRemove.Enable             = 'on';
                app.btnTrainingClear.Enable              = 'on';
                app.btnTrainingUp.Enable                 = 'on';
                app.btnTrainingDown.Enable               = 'on';
                app.btnTrainingTop.Enable                = 'on';
                app.btnTrainingBottom.Enable             = 'on';
                app.GenerateTrainingDatasetButton.Enable = 'on';
            end
        end

        % Button pushed function: btnTrainingAdd
        function btnTrainingAddButtonPushed(app, event)
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            tmpDir = uigetdir;
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            if tmpDir
                cd(tmpDir);
                if ~exist([tmpDir filesep 'Dots.mat'], 'file')
                    disp(['Error: Working folder not valid for analysis ' tmpDir]);
                    app.Lamp.Color = 'red';
                    return
                end
                
                if isempty(app.lstTraining.Items)
                    app.lstTraining.Items = {tmpDir};
                else
                    app.lstTraining.Items{end+1} = tmpDir;
                end
            end
            app.lstTrainingValueChanged;
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnTrainingRemove
        function btnTrainingRemoveButtonPushed(app, event)
            idx = find(strcmp(app.lstTraining.Items, app.lstTraining.Value));
            app.lstTraining.Items(idx) = [];
            app.lstTrainingValueChanged;
        end

        % Button pushed function: btnTrainingClear
        function btnTrainingClearButtonPushed(app, event)
            switch questdlg({'Do you want to remove all items in this list?';'Nothing will be deleted from disk.'},'Remove all items', 'No', 'Yes', 'No')
                case 'Yes'
                    app.lstTraining.Items = {};
                    app.lstTrainingValueChanged;
            end
        end

        % Button pushed function: btnTrainingTop
        function btnTrainingTopButtonPushed(app, event)
            idx = find(strcmp(app.lstTraining.Items, app.lstTraining.Value),1);
            val = app.lstTraining.Value;
            if idx > 1
                app.lstTraining.Items(idx) = [];
                app.lstTraining.Items = [{val}, app.lstTraining.Items];
                app.lstTraining.Value = val;
            end
        end

        % Button pushed function: btnTrainingUp
        function btnTrainingUpButtonPushed(app, event)
            idx = find(strcmp(app.lstTraining.Items, app.lstTraining.Value),1);
            val = app.lstTraining.Value;
            if idx > 1
                app.lstTraining.Items(idx) = app.lstTraining.Items(idx-1);
                app.lstTraining.Items{idx-1} = val;
                app.lstTraining.Value = val;
            end
        end

        % Button pushed function: btnTrainingDown
        function btnTrainingDownButtonPushed(app, event)
            idx = find(strcmp(app.lstTraining.Items, app.lstTraining.Value),1);
            val = app.lstTraining.Value;
            if idx < numel(app.lstTraining.Items)
                app.lstTraining.Items(idx) = app.lstTraining.Items(idx+1);
                app.lstTraining.Items{idx+1} = val;
                app.lstTraining.Value = val;
            end
        end

        % Button pushed function: btnTrainingBottom
        function btnTrainingBottomButtonPushed(app, event)
            idx = find(strcmp(app.lstTraining.Items, app.lstTraining.Value),1);
            val = app.lstTraining.Value;
            if idx < numel(app.lstTraining.Items)
                app.lstTraining.Items(idx) = [];
                app.lstTraining.Items{end+1} = val;
                app.lstTraining.Value = val;
            end
        end

        % Button pushed function: TrainingFolderButton
        function TrainingFolderButtonPushed(app, event)
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            tmpDir = uigetdir;
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            if tmpDir
                app.TrainingFolder.Value = tmpDir;
                app.GenerateTrainingDatasetButton.Enable = 'on';
                app.TrainNeuralNetworkButton.Enable = 'off';
            else
                app.TrainingFolder.Value = '';
                app.GenerateTrainingDatasetButton.Enable = 'off';
                app.TrainNeuralNetworkButton.Enable = 'off';
            end
            
            app.Lamp.Color = 'green';
        end

        % Button pushed function: GenerateTrainingDatasetButton
        function GenerateTrainingDatasetButtonPushed(app, event)
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            % Create individual images for each passing and nonpassing object
            if ~isfolder([app.TrainingFolder.Value filesep 'Images'])
                mkdir([app.TrainingFolder.Value filesep 'Images']);
                mkdir([app.TrainingFolder.Value filesep 'Images' filesep 'Object']);
                mkdir([app.TrainingFolder.Value filesep 'Images' filesep 'Noise']);
            else
                rmdir([app.TrainingFolder.Value filesep 'Images' filesep 'Object'], 's');
                rmdir([app.TrainingFolder.Value filesep 'Images' filesep 'Noise'], 's');
                rmdir([app.TrainingFolder.Value filesep 'Images'], 's');
                mkdir([app.TrainingFolder.Value filesep 'Images']);
                mkdir([app.TrainingFolder.Value filesep 'Images' filesep 'Object']);
                mkdir([app.TrainingFolder.Value filesep 'Images' filesep 'Noise']);
            end
            
            try
                switch app.cmbTrainingModel.Value
                    case 'AlexNet',             net = eval('alexnet');
                    case 'VGG-16',              net = eval('vgg16');
                    case 'VGG-19',              net = eval('vgg19');
                    case 'SqueezeNet',          net = eval('squeezenet');
                    case 'GoogleNet',           net = eval('googlenet');
                    case 'Inception-v3',        net = eval('inceptionv3');
                    case 'ResNet-50',           net = eval('resnet50');
                    case 'ResNet-101',          net = eval('resnet101');
                    case 'Inception-ResNet-v2', net = eval('inceptionresnetv2');
                end
                sz = net.Layers(1).InputSize;
            catch
                msgbox([app.cmbTrainingModel.Value ' not available, please install it using the AddOn explorer'], 'NeuralNet Error', 'error');
                app.Lamp.Color = 'red';
                return
            end
            
            % Store each object as a separate image in the proper training subfolder
            for exp = 1:numel(app.lstTraining.Items)
                
                app.lstTraining.Value = app.lstTraining.Items{exp};
                cd(app.lstTraining.Items{exp});
                if ~isfolder([app.lstTraining.Items{exp} filesep 'I'])
                    disp(['Error: Working folder not valid for analysis ' app.lstTraining.Items{exp}]);
                    continue;
                else
                    % Load objects from the current experiment
                    load([app.lstTraining.Items{exp} filesep 'Dots.mat'], 'Dots');
                end
                
                %Load Image Matrix of each object, save each axis MIP to Noise or Cell Folder
                for i=1:length(Dots(1).Vox)
                    minPt = min(Dots(1).Vox(i).Pos(:,1:3));
                    maxPt = max(Dots(1).Vox(i).Pos(:,1:3));
                    imMat = zeros(maxPt-minPt+1);
                    for j=1:size(Dots(1).Vox(i).Pos, 1)
                        pt = Dots(1).Vox(i).Pos(j,:) - minPt + 1;
                        imMat(pt(1),pt(2),pt(3)) = Dots(1).Vox(i).RawBright(j);
                    end
                    
                    % Create maximum intensity projections along cardinal axes
                    z = imresize(squeeze(max(imMat,[],3)), [sz(1) sz(2)]);
                    y = imresize(squeeze(max(imMat,[],1)), [sz(1) sz(2)]);
                    x = imresize(squeeze(max(imMat,[],2)), [sz(1) sz(2)]);
                    I = cat(3,z,y,x); % Encode each MIP as a R-G-B color
                    
                    [~,expName,~] = fileparts(app.lstTraining.Items{exp});
                    objsName = Dots(1).Name;
                    
                    if(Dots(1).Filter.passF(i))
                        imwrite(I, [app.TrainingFolder.Value filesep 'Images' filesep 'Object' filesep expName '_' objsName '_' num2str(i) '.tif']);
                    else
                        imwrite(I, [app.TrainingFolder.Value filesep 'Images' filesep 'Noise' filesep expName '_' objsName '_' num2str(i) '.tif']);
                    end
                end
            end
            
            app.TrainNeuralNetworkButton.Enable = 'on';
            app.Lamp.Color = 'green';
        end

        % Button pushed function: TrainNeuralNetworkButton
        function TrainNeuralNetworkButtonPushed(app, event)
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            images = imageDatastore([app.TrainingFolder.Value filesep 'Images'],'IncludeSubfolders',true,'LabelSource','foldernames');
            % split images, 0.7 means 70% of images are used for training, 30% for validation
            [trainingImages, validationImages] = splitEachLabel(images,app.TrainSplitRatio.Value/100,'randomized');
            
            % Create the neural network
            try
                switch app.cmbTrainingModel.Value
                    case 'AlexNet',             net = eval('alexnet');
                    case 'VGG-16',              net = eval('vgg16');
                    case 'VGG-19',              net = eval('vgg19');
                    case 'SqueezeNet',          net = eval('squeezenet');
                    case 'GoogleNet',           net = eval('googlenet');
                    case 'Inception-v3',        net = eval('inceptionv3');
                    case 'ResNet-50',           net = eval('resnet50');
                    case 'ResNet-101',          net = eval('resnet101');
                    case 'Inception-ResNet-v2', net = eval('inceptionresnetv2');
                end
            catch
                msgbox([app.cmbTrainingModel.Value ' not available, please install it using the AddOn explorer'], 'NeuralNet Error', 'error');
                app.Lamp.Color = 'red';
                return
            end
            layersTransfer  = net.Layers(1:end-3);
            numClasses      = numel(categories(trainingImages.Labels));
            layers          = [
                layersTransfer
                fullyConnectedLayer(numClasses,'WeightLearnRateFactor',20,'BiasLearnRateFactor',20)
                softmaxLayer
                classificationLayer];
            
            % Minibatchsize is the number of points processed upon which the stats
            %    of accuracy reached by the net are updated on screen
            % MaxEpoch is how many times the network should be trained with that dataset
            % InitialLearnRate dunno what it is, do not change
            % netTransfer is the trained network to save for later use
            
            miniBatchSize           = app.TrainMiniBatchSize.Value;
            numIterationsPerEpoch   = floor(numel(trainingImages.Labels)/miniBatchSize);
            
            options                 = trainingOptions('sgdm',...
                'Momentum',app.TrainMomentum.Value/100,...
                'MiniBatchSize',miniBatchSize,...
                'MaxEpochs',app.TrainMaxEpochs.Value,...
                'InitialLearnRate',app.TrainInitialLearningRate.Value,...
                'Verbose',false,...
                'Plots','training-progress',...
                'ValidationData',validationImages,...
                'ValidationFrequency',numIterationsPerEpoch);
            
            % Train the network using training dataet
            NeuralNet.Name            = app.txtTrainingName.Value;
            NeuralNet.netTransfer     = trainNetwork(trainingImages,layers,options);
            NeuralNet.predictedLabels = classify(NeuralNet.netTransfer,validationImages);
            NeuralNet.valLabels       = validationImages.Labels;
            NeuralNet.accuracy        = mean(NeuralNet.predictedLabels == NeuralNet.valLabels);
            
            save([app.TrainingFolder.Value filesep NeuralNet.Name], 'NeuralNet');
            app.RefreshNeuralNets;
            
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnColoDoneTop
        function btnColoDoneTopButtonPushed(app, event)
            idx_src = find(strcmp(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value),1);
            idx = find(strcmp(app.lstColoDone.Items, app.lstColoDone.Value),1);
            val = app.lstColoDone.Value;
            if idx > 1
                tmpColoc = app.Dots(idx_src).Coloc(1);
                app.Dots(idx_src).Coloc(1) = app.Dots(idx_src).Coloc(idx);
                app.Dots(idx_src).Coloc(idx) = tmpColoc;
                Dots = app.Dots;
                save([pwd filesep 'Dots.mat'], 'Dots');
                app.RefreshColocalizationDone;
                app.lstColoDone.Value = val;
            end
        end

        % Button pushed function: btnColoDoneBottom
        function btnColoDoneBottomButtonPushed(app, event)
            idx_src = find(strcmp(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value),1);
            idx = find(strcmp(app.lstColoDone.Items, app.lstColoDone.Value),1);
            val = app.lstColoDone.Value;
            if idx < numel(app.lstColoDone.Items)
                tmpColoc = app.Dots(idx_src).Coloc(end);
                app.Dots(idx_src).Coloc(end) = app.Dots(idx_src).Coloc(idx);
                app.Dots(idx_src).Coloc(idx) = tmpColoc;
                Dots = app.Dots;
                save([pwd filesep 'Dots.mat'], 'Dots');
                app.RefreshColocalizationDone;
                app.lstColoDone.Value = val;
            end
        end

        % Button pushed function: btnColocalizeMask
        function btnColocalizeMaskButtonPushed(app, event)
            % Load channel to colocalize
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            idx_src = find(strcmp(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value),1);
            
            PathName = [pwd filesep 'I' filesep];
            FileName = app.lstColo.Value{1};            
            if isempty(FileName)
                return
            end
            Colo = loadImage(FileName);

            % Ensure that loaded image is indeed a binary mask before proceeding            
            if numel(unique(Colo)) ~= 2
                errordlg('Selected image is not a binary mask','File Error');
                return
            end
            
            % Normalize mask values to 0-1 (ImageJ uses 0-255)
            Colo = Colo./max(Colo(:)); 
            save([pwd filesep 'Colo.mat'],'Colo', '-v7.3');
            
            switch app.ColoSwitchVoxPerc.Value
                case 'Voxels'
                    NumVoxOverlap  = app.ColoSharedVoxNum.Value;
                    NumPercOverlap = 0;
                case '%'
                    NumVoxOverlap  = 0;
                    NumPercOverlap = app.ColoSharedVoxNum.Value;
            end
            
            ColocAuto = colocAutoMask(app.Dots(idx_src), app.Dots(idx_src).Filter, Colo, [PathName FileName], NumVoxOverlap, NumPercOverlap);
            
            if isempty(fieldnames(app.Dots(idx_src).Coloc))
                app.Dots(idx_src).Coloc = ColocAuto;
            else                 
                % Check if we need to replace a previously done analysis with same mask
                FoundColocManual  = false;
                for i=1:numel(app.Dots(idx_src).Coloc)
                    if strcmp(app.Dots(idx_src).Coloc(i).Source, ColocAuto.Source) && strcmp(app.Dots(idx_src).Coloc(i).Fish1, ColocAuto.Fish1)
                        app.Dots(idx_src).Coloc(i) = ColocAuto;
                        FoundColocManual = true;
                        break
                    end
                end
                
                % If nothing to replace, just append to the list
                if ~FoundColocManual
                    app.Dots(idx_src).Coloc(end+1) = ColocAuto; % Coloc is saved later
                end
            end
            
            % If selected by user, also save colocalized objects as a new set
            if app.chkColoSaveAsNewObjects.Value
                app.Dots(end+1) = app.Dots(idx_src);
                app.Dots(end).Name = [app.Dots(end).Name ' colocalized with ' ColocAuto.Fish1];
                app.Dots(end).Filter.passF = (ColocAuto.ColocFlag == 1);
                app.Dots(end).Coloc = struct;
                app.RefreshValidObjects;
            end
            
            Dots = app.Dots;
            save([pwd filesep 'Dots.mat'], 'Dots');
            app.RefreshColocalizationDone;
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnRenameObjects
        function btnRenameObjectsButtonPushed(app, event)
            % Find index of current objects (0= new objects set)
            idx = find(strcmp(app.DetectSelObjects.Items, app.DetectSelObjects.Value),1) -1;
            opts.Resize = 'off';
            opts.WindowStyle = 'modal';
            opts.Interpreter = 'none';
            answer = inputdlg('New name for selected objects','Rename objects', [1 40],{app.DetectSelObjects.Value},opts);
            if ~isempty(answer)
                app.Dots(idx).Name = answer{:};
                app.RefreshValidObjects;
                app.DetectSelObjects.Value = app.Dots(idx).Name;
                app.DetectSelObjectsValueChanged;
                
                Dots= app.Dots;
                save([pwd filesep 'Dots.mat'], 'Dots');
            end
        end

        % Value changed function: DetectSelObjects
        function DetectSelObjectsValueChanged(app, event)
            app.Lamp.Color = 'Yellow';
            % Find index of current objects (0= new objects set)
            idx = find(strcmp(app.DetectSelObjects.Items, app.DetectSelObjects.Value),1) -1;
            
            if idx > 0
                % Load detection settings from current objects in the GUI
                app.Settings.ImImfo                 = app.Dots(idx).Settings.ImInfo;
                app.Watershed.Value                 = app.Dots(idx).Settings.objfinder.watershed;
                app.BlockSearch.Value               = app.Dots(idx).Settings.objfinder.blockSearch;
                app.Sphericity.Value                = app.Dots(idx).Settings.objfinder.sphericity;
                if isfield(app.Dots(idx).Settings.objfinder, 'localNoise')
                    app.LocalNoise.Value            = app.Dots(idx).Settings.objfinder.localNoise;
                else
                    app.LocalNoise.Value            = app.BlockSearch.Value;
                end
                app.MinIntensity.Value              = app.Dots(idx).Settings.objfinder.minIntensity;
                app.SingleZDotCut.Value             = app.Dots(idx).Settings.Filter.SingleZDotCut;
                app.EdgeDotCut.Value                = app.Dots(idx).Settings.Filter.EdgeDotCut;
                app.xyStableDots.Value              = app.Dots(idx).Settings.Filter.xyStableDots;
                app.CurrentObjectsPanel.Title       = ['Current Objects (Valid: ' num2str(numel(find(app.Dots(idx).Filter.passF))) ' / ' num2str(numel(app.Dots(idx).Filter.passF)) ')'];
                app.FilteragainButton.Enable        = 'on';
                app.btnRecalculateSphericity.Enable = 'on';
                app.ValidateobjectsButton.Enable    = 'on';
                app.btnRenameObjects.Enable         = 'on';
                app.btnDeleteObjects.Enable         = 'on';
                app.btnCopyObjects.Enable           = 'on';
                app.btnCalcDiameter.Enable          = 'on';
                app.btnPurgeInvalid.Enable          = 'on';
                app.lblImageFile.Text               = ['Image file: ' app.Dots(idx).Settings.ImInfo.PostChName];
                app.lblResolution.Text              = ['Voxel size: ' num2str(app.Dots(idx).Settings.ImInfo.xyum) ' x ' num2str(app.Dots(idx).Settings.ImInfo.xyum) ' x ' num2str(app.Dots(idx).Settings.ImInfo.zum) ' µm'];
            else
                % Load default values for the GUI
                app.CurrentObjectsPanel.Title       = 'Current Objects';
                app.FilteragainButton.Enable        = 'off';
                app.btnRecalculateSphericity.Enable = 'off';
                app.ValidateobjectsButton.Enable    = 'off';
                app.btnRenameObjects.Enable         = 'off';
                app.btnDeleteObjects.Enable         = 'off';
                app.btnCopyObjects.Enable           = 'off';
                app.btnPurgeInvalid.Enable          = 'off';
                app.lblImageFile.Text               = 'Image file: N/A';
            end
            
            app.Lamp.Color = 'Green';
        end

        % Value changed function: ReportSelObjects
        function ReportSelObjectsValueChanged(app, event)
            % Find index of current objects (0= new objects set)
            idx = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            
            if idx > 0
                % Calculate objects properties
                Objs                             = getFilteredObjects(app.Dots(idx), app.Dots(idx).Filter);
                ObjsNum                          = numel(find(app.Dots(idx).Filter.passF));
                ObjsTotNum                       = numel(app.Dots(idx).Filter.passF);
                ObjsPerc                         = 100*ObjsNum/ObjsTotNum;
                VoxelVolume                      = app.Dots(idx).Settings.ImInfo.xyum * app.Dots(idx).Settings.ImInfo.xyum * app.Dots(idx).Settings.ImInfo.zum;
                
                % Display object stats
                app.lblReportValid.Text          = ['Valid objects: ' num2str(ObjsNum) ' / ' num2str(ObjsTotNum) ' (' sprintf('%0.2f',ObjsPerc) '%)'];
                app.lblReportAvgBright.Text      = ['Objects brighness: ' num2str(round(mean(Objs.MeanBright))) ' ' char(177) ' ' num2str(round(std(Objs.MeanBright)))];
                app.lblReportAvgSize.Text        = ['Objects size: ' sprintf('%0.2f', mean(Objs.Vol)*VoxelVolume) ' ' char(177) ' ' sprintf('%0.2f', std(Objs.Vol)*VoxelVolume) ' µm^3'];
                
                % Calculate image stats
                SrcImgRes     = [app.Dots(idx).Settings.ImInfo.xyum, app.Dots(idx).Settings.ImInfo.xyum, app.Dots(idx).Settings.ImInfo.zum];
                SrcImgSizePx  = [app.Dots(idx).Settings.ImInfo.xNumVox, app.Dots(idx).Settings.ImInfo.yNumVox, app.Dots(idx).Settings.ImInfo.zNumVox];
                SrcImgSizeUm  = SrcImgSizePx.*SrcImgRes;
                MaskSizePx    = numel(find(app.Mask));
                MaskSizeUm    = MaskSizePx * (SrcImgRes(1) * SrcImgRes(2) * SrcImgRes(3));
                ObjsMask      = zeros(Objs.Settings.ImInfo.yNumVox, Objs.Settings.ImInfo.xNumVox, Objs.Settings.ImInfo.zNumVox, 'uint8');
                for i = 1:numel(Objs.Vox)
                    ObjsMask(Objs.Vox(i).Ind) = 1;
                end
                
                % Display image stats
                app.lblReportSrcImgName.Text     = ['Source image: ' app.Dots(idx).Settings.ImInfo.PostChName];
                app.lblReportSrcImgRes.Text      = ['Voxel size: ' num2str(SrcImgRes(1)) ' x ' num2str(SrcImgRes(2)) ' x ' num2str(SrcImgRes(3)) ' µm'];
                app.lblReportSrcImgVol.Text      = ['Image volume: ' num2str(round(SrcImgSizeUm(1)*SrcImgSizeUm(2)*SrcImgSizeUm(3))) ' µm^3'];
                app.lblReportMask.Text           = ['Masked volume: ' num2str(round(MaskSizeUm)) ' µm^3 (' sprintf('%0.2f',100*MaskSizePx / (SrcImgSizePx(1)*SrcImgSizePx(2)*SrcImgSizePx(3))) '% of image)'];
                app.lblReportVolOccupancy.Text   = ['Volume occupancy: ' sprintf('%0.2f',100*sum(Objs.Vol)/(SrcImgSizePx(1)*SrcImgSizePx(2)*SrcImgSizePx(3))) '% (' sprintf('%0.2f',100*numel(find(ObjsMask(app.Mask==1)))/numel(find(app.Mask))) '% of mask)'];
                
                app.btnPlotDensityByDepth.Enable = 'on';
                app.btnPlotObjBrightDist.Enable  = 'on';
                app.btnPlotObjSizeDist.Enable    = 'on';
                app.btnPlotHeatmap.Enable        = 'on';
                app.btnPlotLocationsXY.Enable    = 'on';
                app.btnPlotLocationsZ.Enable     = 'on';
                app.btnPlotSholl.Enable          = 'on';
                app.btnPlotSkeleton.Enable       = 'on';
                app.btnCreateMask.Enable         = 'on';
            else
                % Display object stats
                app.lblReportValid.Text          = 'Valid objects: N/A';
                app.lblReportAvgBright.Text      = 'Objects brighness: N/A';
                app.lblReportAvgSize.Text        = 'Objects size: N/A';
                app.lblReportSrcImgName.Text     = 'Source image: N/A';
                app.lblReportSrcImgRes.Text      = 'Voxel size: N/A';
                app.lblReportSrcImgVol.Text      = 'Image volume: N/A';
                app.lblReportMask.Text           = 'Masked volume: N/A';
                app.lblReportVolOccupancy.Text   = 'Volume occupancy: N/A';
                
                app.btnPlotDensityByDepth.Enable = 'off';
                app.btnPlotObjBrightDist.Enable  = 'off';
                app.btnPlotObjSizeDist.Enable    = 'off';
                app.btnPlotHeatmap.Enable        = 'off';
                app.btnPlotLocationsXY.Enable    = 'off';
                app.btnPlotLocationsZ.Enable     = 'off';
                app.btnPlotSholl.Enable          = 'off';
                app.btnPlotSkeleton.Enable       = 'off';
                app.btnCreateMask.Enable         = 'off';
            end
            
            app.ReportSelObjectsNNValueChanged;
        end

        % Button pushed function: btnPlotLocationsZ
        function btnPlotLocationsZButtonPushed(app, event)
            % Plot objects along the XZ axis
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            idx = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            if idx > 0
                % Plot passing objects along the YZ axis
                Objs = getFilteredObjects(app.Dots(idx), app.Dots(idx).Filter);
                tmpA = zeros(numel(Objs.Vol), 2);
                for i=1:numel(Objs.Vol)
                    tmpA(i,:)= mean(Objs.Vox(i).Pos(:,2:3));
                end
                
                scatter(tmpA(:,1), tmpA(:,2), 25, 'o', 'MarkerEdgeColor', [0 0.5 0.5]);
                xlim([0 app.Dots(idx).Settings.ImInfo.yNumVox]);
                ylim([0 app.Dots(idx).Settings.ImInfo.zNumVox]);
                box on;
                set(gca, 'XTick', [], 'YTick', []);
            end
            
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnDeleteObjects
        function btnDeleteObjectsButtonPushed(app, event)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            idx = find(strcmp(app.DetectSelObjects.Items, app.DetectSelObjects.Value),1) -1;
            if idx > 0
                app.Dots(idx) = [];
                Dots = app.Dots;
                save([pwd filesep 'Dots.mat'], 'Dots');
                app.RefreshValidObjects;
                app.DetectSelObjects.Value = 'New Objects';
                app.DetectSelObjectsValueChanged;
            end
            
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnCreateMask
        function btnCreateMaskButtonPushed(app, event)
            % Find index of current objects (0= new objects set)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            idx = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            
            if idx > 0
                Objs        = getFilteredObjects(app.Dots(idx), app.Dots(idx).Filter);
                ObjsMask    = zeros(Objs.Settings.ImInfo.yNumVox, ...
                    Objs.Settings.ImInfo.xNumVox, ...
                    Objs.Settings.ImInfo.zNumVox, 'uint8');
                
                for i = 1:numel(Objs.Vox)
                    ObjsMask(Objs.Vox(i).Ind) = 1;
                end
                saveastiff(ObjsMask, [pwd filesep 'I' filesep app.Dots(idx).Name '_objects_mask.tif']);
            end
            
            app.Lamp.Color = 'green';
        end

        % Button pushed function: btnPlotObjSizeDist
        function btnPlotObjSizeDistButtonPushed(app, event)
            idx = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            
            if idx > 0
                Objs        = getFilteredObjects(app.Dots(idx), app.Dots(idx).Filter);
                VoxelVolume = app.Dots(idx).Settings.ImInfo.xyum * app.Dots(idx).Settings.ImInfo.xyum * app.Dots(idx).Settings.ImInfo.zum;
                Data        = Objs.Vol.*VoxelVolume; % Convert volumes from voxels to micron^3
                
                % Fit data with selected distribution type and plot
                figure('Name', 'Objects size distribution', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
                subplot(1,2,1);
                h               = histfit(Data, [], app.ReportDistFitType.Value);
                h(1).FaceColor  = [.8 .8 1]; % violet histogram
                xlabel('Volume (µm^3)');
                ylabel('Number of objects');
                legend({app.Dots(idx).Name, [app.ReportDistFitType.Value ' distribution']});
                title([app.Dots(idx).Name ' size distribution']);
                
                % Retrieve data from plot
                VolumeBin   = get(h(1),'xdata')';   % Number of object on first column
                Count       = get(h(1),'ydata')';   % Volume of each object on second column
                FitVolumeBin= get(h(2),'xdata')';   % Bin number for the fitted distribution
                FitCount    = get(h(2),'ydata')';   % Fitted values
                
                % Calculate and plot Cumulative distribution data
                CumCount        = Count;
                for i = 1:numel(Count)
                    if i==1
                        CumCount(i) = 100* Count(i)/sum(Count);
                    else
                        CumCount(i) = 100* Count(i)/sum(Count) + CumCount(i-1);
                    end
                end
                subplot(1,2,2);
                plot(VolumeBin, CumCount, '-o', 'MarkerFaceColor', [.8 .8 1], 'MarkerSize', 8, 'MarkerEdgeColor', [0 0 0]);
                ylim([0 100]);
                box off;
                xlabel('Volume (µm^3)');
                ylabel('Percentage of objects');
                title([app.Dots(idx).Name ' size cumulative distribution']);
                
                % Export individual data as Excel table inside the results folder
                if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                FileName    = [pwd filesep 'results' filesep app.Dots(idx).Name '_Volume_rawdata.xls'];
                Object      = (1:numel(Objs.Vol))'; % Number of object on first column
                Volume      = Data';                % Volume of each object on second column
                TableData   = table(Object,Volume);
                writetable(TableData, FileName);    % Write an Excel table with Object-Volume pairs
                
                % Export distribution data as Excel table inside the results folder
                FileName    = [pwd filesep 'results' filesep app.Dots(idx).Name '_Volume_distribution.xls'];
                TableData   = table(VolumeBin, Count, CumCount);
                writetable(TableData, FileName);    % Write an Excel table with Object-Volume pairs
                
                FileName    = [pwd filesep 'results' filesep app.Dots(idx).Name '_Volume_distribution_fit_' app.ReportDistFitType.Value '.xls'];
                TableData   = table(FitVolumeBin, FitCount);
                writetable(TableData, FileName);    % Write an Excel table with Object-Volume pairs
            end
        end

        % Button pushed function: btnPlotObjBrightDist
        function btnPlotObjBrightDistButtonPushed(app, event)
            idx = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            
            if idx > 0
                Objs = getFilteredObjects(app.Dots(idx), app.Dots(idx).Filter);
                figure('Name', 'Objects average brightness distribution', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
                subplot(1,2,1);
                
                % Fit data with selected distribution type and plot
                h = histfit(Objs.MeanBright, [], app.ReportDistFitType.Value);
                h(1).FaceColor = [.8 .8 1]; % violet histogram
                xlim([0 256]);
                xlabel('Brightness level');
                ylabel('Number of objects');
                legend({app.Dots(idx).Name, [app.ReportDistFitType.Value ' distribution']});
                title([app.Dots(idx).Name ' average brighness distribution']);
                
                % Collect plotted data
                BrightnessBin   = get(h(1),'xdata')';   % Number of object on first column
                Count           = get(h(1),'ydata')';   % Volume of each object on second column
                FitBrightnessBin= get(h(2),'xdata')';   % Bin number for the fitted distribution
                FitCount        = get(h(2),'ydata')';   % Fitted values
                
                % Calculate and plot Cumulative distribution data
                CumCount        = Count;
                for i = 1:numel(Count)
                    if i==1
                        CumCount(i) = 100* Count(i)/sum(Count);
                    else
                        CumCount(i) = 100* Count(i)/sum(Count) + CumCount(i-1);
                    end
                end
                subplot(1,2,2);
                plot(BrightnessBin, CumCount, '-o', 'MarkerFaceColor', [.8 .8 1], 'MarkerSize', 8, 'MarkerEdgeColor', [0 0 0]);
                ylim([0 100]);
                xlim([0 255]);
                box off;
                xlabel('Brighness level');
                ylabel('Percentage of objects');
                title([app.Dots(idx).Name ' average brightness cumulative distribution']);
                
                % Export data as Excel table inside the results folder
                if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                FileName    = [pwd filesep 'results' filesep app.Dots(idx).Name '_Brighness_rawdata.xls'];
                Object      = (1:numel(Objs.MeanBright))'; % Number of object on first column
                Brightness  = Objs.MeanBright';            % Volume of each object on second column
                TableData   = table(Object, Brightness);
                writetable(TableData, FileName);           % Write an Excel table with Object-Brightness pairs
                
                % Export distribution data as Excel table inside the results folder
                FileName        = [pwd filesep 'results' filesep app.Dots(idx).Name '_Brightness_distribution.xls'];
                TableData       = table(BrightnessBin, Count, CumCount);
                writetable(TableData, FileName);    % Write an Excel table with Object-Volume pairs
                
                FileName    = [pwd filesep 'results' filesep app.Dots(idx).Name '_Brightness_distribution_fit_' app.ReportDistFitType.Value '.xls'];
                TableData   = table(FitBrightnessBin, FitCount);
                writetable(TableData, FileName);    % Write an Excel table with Object-Volume pairs
                
            end
        end

        % Value changed function: lstColoSelObjects
        function lstColoSelObjectsValueChanged(app, event)
            switch numel(app.lstColoSelObjects.Value)
                case 1
                    app.btnColocalize.Enable     = 'off';
                    app.btnColocalize2.Enable    = 'off';
                    app.btnColocalizeMask.Enable = 'off';
                    app.btnColocalizeNN.Enable   = 'off';
                    app.ColoSharedVoxNum.Enable  = 'off';
                    app.ColoSwitchVoxPerc.Enable = 'off';
                    app.RefreshColocalizationDone;
                case 2
                    app.btnColocalize.Enable     = 'off';
                    app.btnColocalize2.Enable    = 'off';
                    app.btnColocalizeMask.Enable = 'off';
                    app.btnColocalizeNN.Enable   = 'on';
                    app.ColoSharedVoxNum.Enable  = 'on';
                    app.ColoSwitchVoxPerc.Enable = 'on';
                    app.lstColoDone.Items        = {};
                otherwise
                    app.btnColocalize.Enable     = 'off';
                    app.btnColocalize2.Enable    = 'off';
                    app.btnColocalizeMask.Enable = 'off';
                    app.btnColocalizeNN.Enable   = 'off';
                    app.ColoSharedVoxNum.Enable  = 'off';
                    app.ColoSwitchVoxPerc.Enable = 'off';
                    app.lstColoDone.Items        = {};
            end
            
        end

        % Callback function
        function btnRefreshSelObjectsNNPushed(app, event)
            app.ReportSelObjectsNN.Items = app.ReportSelObjects.Items;
            app.ReportSelObjectsValueChanged;
        end

        % Button pushed function: CalculateNN
        function CalculateNNButtonPushed(app, event)
            app.Lamp.Color = 'yellow';  pause(0.2);
            
            idx_src = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            idx_dst = find(strcmp(app.ReportSelObjectsNN.Items, app.ReportSelObjectsNN.Value),1);
            
            if (idx_src >0) && (idx_dst >0)
                % Calculate nearest neighbor's distance and overlap
                xyum        = app.Dots(idx_src).Settings.ImInfo.xyum;
                zum         = app.Dots(idx_src).Settings.ImInfo.zum;
                tmpPts1     = app.Dots(idx_src).Pos;
                tmpVol1     = app.Dots(idx_src).Vol;
                tmpPts2     = app.Dots(idx_dst).Pos;
                tmpPts1N    = size(tmpPts1,1);
                NN          = struct;
                NN.Name     = app.Dots(idx_dst).Name;
                IncludeSelf = (idx_src ~= idx_dst); % Do not include self if calculating NN of objects against themselves
                tic;
                disp([' Calculating pairwise nearest neighbors (' app.Dots(idx_src).Name '->' app.Dots(idx_dst).Name ')']);
                for p1 = tmpPts1N:-1:1 % NN distance (Nearest p2 to each p1)
                    xyDist            = hypot( (tmpPts1(p1,1)-tmpPts2(:,1))*xyum, (tmpPts1(p1,2)-tmpPts2(:,2))*xyum);
                    xyzDist           = hypot( xyDist, (tmpPts1(p1,3)-tmpPts2(:,3))*zum); % calculate separately along Z because this dimension has different pixel size
                    if IncludeSelf
                        p2Distance        = min(xyzDist);                 % Distance to nearest neighbor
                    else
                        p2Distance = min(setdiff(xyzDist(:), min(xyzDist(:)))); % Distance to 2nd nearest neighbor (1st is self)
                    end
                    p2                = find(xyzDist == p2Distance,1);    % Insex of the nearest neighbor
                    NN.Dist(p1)       = p2Distance;                       % Store calibrated distance
                    NN.NeighborIdx(p1)= p2;                               % Store neighbor's index
                    NN.VoxOverlap(p1) = size(intersect(app.Dots(idx_src).Vox(p1).Pos, app.Dots(idx_dst).Vox(p2).Pos,'rows'),1);
                    NN.VoxOverlapPerc(p1) = 100 * NN.VoxOverlap(p1) / tmpVol1(p1); % Store voxel overlap as percent of source object volume
                end
                
                % Accumulate info inside current Objects
                if isempty(fieldnames(app.Dots(idx_src).NN))
                    app.Dots(idx_src).NN = NN;
                else
                    app.Dots(idx_src).NN(end+1) = NN;
                end
                Dots = app.Dots;
                save([pwd filesep 'Dots.mat'], 'Dots');
                disp([' -- done in ' num2str(toc) ' seconds']);
                app.ReportSelObjectsNNValueChanged;
            end
            
            app.Lamp.Color = 'green';
        end

        % Value changed function: ReportSelObjectsNN
        function ReportSelObjectsNNValueChanged(app, event)
            idx_src = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            idx_dst = 0;
            
            % Find index of the matching NN analysis against requested name from the listbox
            if idx_src > 0
                for i=1: numel(app.Dots(idx_src).NN)
                    if numel(fieldnames(app.Dots(idx_src).NN(i))) > 0
                        if strcmp(app.Dots(idx_src).NN(i).Name,  app.ReportSelObjectsNN.Value)
                            idx_dst = i; % Found the matching item
                            break
                        end
                    end
                end
                
                if idx_dst > 0
                    idx_valid = find(app.Dots(idx_src).Filter.passF);
                    app.lblNNdistance.Text      = ['Nearest neighbor distance: '  sprintf('%0.3f', mean(app.Dots(idx_src).NN(idx_dst).Dist(idx_valid))) ' ' char(177) ' ' sprintf('%0.3f', std(app.Dots(idx_src).NN(idx_dst).Dist(idx_valid))) ' µm'];
                    app.lblNNvoxelOverlap.Text  = ['Percent voxels overlapping: ' sprintf('%0.2f', mean(app.Dots(idx_src).NN(idx_dst).VoxOverlapPerc(idx_valid))) ' ' char(177) ' ' sprintf('%0.2f', std(app.Dots(idx_src).NN(idx_dst).VoxOverlapPerc(idx_valid))) ' %'];
                else
                    app.lblNNdistance.Text      = 'Nearest neighbor distance: N/A';
                    app.lblNNvoxelOverlap.Text  = 'Percent voxels overlapping: N/A';
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            idx_src = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            idx_dst = 0;
            
            % Find index of the matching NN analysis against requested name from the listbox
            if idx_src > 0
                for i=1: numel(app.Dots(idx_src).NN)
                    if numel(fieldnames(app.Dots(idx_src).NN(i))) > 0
                        if strcmp(app.Dots(idx_src).NN(i).Name,  app.ReportSelObjectsNN.Value)
                            idx_dst = i; % Found the matching item
                            break
                        end
                    end
                end
                
                if idx_dst > 0
                    app.Dots(idx_src).NN(idx_dst) = [];
                    app.ReportSelObjectsNNValueChanged;
                end
            end
        end

        % Button pushed function: btnPlotNNdistDist
        function btnPlotNNdistDistButtonPushed(app, event)
            idx_src = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            idx_dst = 0;
            
            % Find index of the matching NN analysis against requested name from the listbox
            if idx_src > 0
                for i=1: numel(app.Dots(idx_src).NN)
                    if numel(fieldnames(app.Dots(idx_src).NN(i))) > 0
                        if strcmp(app.Dots(idx_src).NN(i).Name,  app.ReportSelObjectsNN.Value)
                            idx_dst = i; % Found the matching item
                            idx_dst_dots = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjectsNN.Value),1);
                            break
                        end
                    end
                end
                
                if idx_dst > 0
                    idx_valid = find(app.Dots(idx_src).Filter.passF);
                    data = app.Dots(idx_src).NN(idx_dst).Dist(idx_valid);
                    figure('Name', 'Nearest Neighbor distance distribution', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
                    subplot(1,2,1);
                    
                    % Fit data with selected distribution type and plot
                    h = histfit(data, [], app.ReportDistFitType.Value);
                    h(1).FaceColor = [.8 .8 1]; % violet histogram
                    xlabel('Distance (µm)');
                    ylabel('Number of objects');
                    xlim([0 max(data)]);
                    legend({[app.Dots(idx_dst_dots).Name ' -> ' app.Dots(idx_src).Name],[app.ReportDistFitType.Value ' distribution']});
                    title(['Nearest ' app.Dots(idx_dst_dots).Name ' to ' app.Dots(idx_src).Name]);
                    
                    % Collect plotted data
                    NNdistBin       = get(h(1),'xdata')';   % Bin data
                    Count           = get(h(1),'ydata')';   % Count data
                    FitNNdistBin    = get(h(2),'xdata')';   % Fitted Bin data
                    FitCount        = get(h(2),'ydata')';   % Fitted Count data
                    
                    % Calculate and plot Cumulative distribution data
                    CumCount        = Count;
                    for i = 1:numel(Count)
                        if i==1
                            CumCount(i) = 100* Count(i)/sum(Count);
                        else
                            CumCount(i) = 100* Count(i)/sum(Count) + CumCount(i-1);
                        end
                    end
                    subplot(1,2,2);
                    plot(NNdistBin, CumCount, '-o', 'MarkerFaceColor', [.8 .8 1], 'MarkerSize', 8, 'MarkerEdgeColor', [0 0 0]);
                    ylim([0 100]);
                    box off;
                    xlabel('Distance (µm)');
                    ylabel('Percentage of objects');
                    title(['Nearest ' app.Dots(idx_dst_dots).Name ' to ' app.Dots(idx_src).Name ' cumulative distribution']);
                    
                    % Export data as Excel table inside the results folder
                    if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                    FileName    = [pwd filesep 'results' filesep app.Dots(idx_src).Name  '-' app.Dots(idx_dst_dots).Name '_NNdistance_rawdata.xls'];
                    Object      = (1:numel(idx_valid))'; % Number of object on first column
                    NNdist      = data';                 % Volume of each object on second column
                    TableData   = table(Object, NNdist);
                    writetable(TableData, FileName);     % Write an Excel table with Object-NNdistance pairs
                    
                    % Export distribution data as Excel table inside the results folder
                    FileName        = [pwd filesep 'results' filesep app.Dots(idx_src).Name '-' app.Dots(idx_dst_dots).Name '_NNdistance_distribution.xls'];
                    TableData       = table(NNdistBin, Count, CumCount);
                    writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
                    
                    FileName        = [pwd filesep 'results' filesep app.Dots(idx_src).Name '-' app.Dots(idx_dst_dots).Name '_NNdistance_distribution_fit_' app.ReportDistFitType.Value '.xls'];
                    TableData       = table(FitNNdistBin, FitCount);
                    writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
                end
            end
        end

        % Button pushed function: btnPlotNNoverlapDist
        function btnPlotNNoverlapDistButtonPushed(app, event)
            idx_src = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            idx_dst = 0;
            
            % Find index of the matching NN analysis against requested name from the listbox
            if idx_src > 0
                for i=1: numel(app.Dots(idx_src).NN)
                    if numel(fieldnames(app.Dots(idx_src).NN(i))) > 0
                        if strcmp(app.Dots(idx_src).NN(i).Name,  app.ReportSelObjectsNN.Value)
                            idx_dst = i; % Found the matching item
                            idx_dst_dots = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjectsNN.Value),1);
                            break
                        end
                    end
                end
                
                if idx_dst > 0
                    idx_valid = find(app.Dots(idx_src).Filter.passF);
                    data = app.Dots(idx_src).NN(idx_dst).VoxOverlapPerc(idx_valid);
                    figure('Name', 'Nearest Neighbor overlap distribution', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
                    subplot(1,2,1);
                    
                    % Fit data with selected distribution type and plot
                    h = histfit(data, [], app.ReportDistFitType.Value);
                    h(1).FaceColor = [.8 .8 1]; % violet histogram
                    xlim([-10 110]);
                    xlabel('Volume overlap (%)');
                    ylabel('Number of objects');
                    legend({[app.Dots(idx_dst_dots).Name ' -> ' app.Dots(idx_src).Name],[app.ReportDistFitType.Value ' distribution']});
                    title(['Nearest ' app.Dots(idx_dst_dots).Name ' to ' app.Dots(idx_src).Name]);
                    
                    % Collect data from plot
                    NNoverlapBin    = get(h(1),'xdata')';   % Bin data
                    Count           = get(h(1),'ydata')';   % Count data
                    FitNNoverlapBin = get(h(2),'xdata')';   % Fitted Bin data
                    FitCount        = get(h(2),'ydata')';   % Fitted Count data
                    
                    % Calculate and plot Cumulative distribution data
                    CumCount        = Count;
                    for i = 1:numel(Count)
                        if i==1
                            CumCount(i) = 100* Count(i)/sum(Count);
                        else
                            CumCount(i) = 100* Count(i)/sum(Count) + CumCount(i-1);
                        end
                    end
                    subplot(1,2,2);
                    plot(NNoverlapBin, CumCount, '-o', 'MarkerFaceColor', [.8 .8 1], 'MarkerSize', 8, 'MarkerEdgeColor', [0 0 0]);
                    ylim([0 100]);
                    box off;
                    xlabel('Volume overlap (%)');
                    ylabel('Percentage of objects');
                    title(['Nearest ' app.Dots(idx_dst_dots).Name ' to ' app.Dots(idx_src).Name ' cumulative distribution']);
                    
                    % Export data as Excel table inside the results folder
                    if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                    FileName    = [pwd filesep 'results' filesep app.Dots(idx_src).Name '-' app.Dots(idx_dst_dots).Name '_NNoverlap_rawdata.xls'];
                    Object      = (1:numel(idx_valid))'; % Number of object on first column
                    NNoverlap   = data';                 % Volume of each object on second column
                    TableData   = table(Object, NNoverlap);
                    writetable(TableData, FileName);     % Write an Excel table with Object-NNdistance pairs
                    
                    % Export distribution data as Excel table inside the results folder
                    FileName        = [pwd filesep 'results' filesep app.Dots(idx_src).Name '-' app.Dots(idx_dst_dots).Name '_NNoverlap_distribution.xls'];
                    TableData       = table(NNoverlapBin, Count, CumCount);
                    writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
                    
                    FileName        = [pwd filesep 'results' filesep app.Dots(idx_src).Name '-' app.Dots(idx_dst_dots).Name '_NNoverlap_distribution_fit_' app.ReportDistFitType.Value '.xls'];
                    TableData       = table(FitNNoverlapBin, FitCount);
                    writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
                    
                end
            end
            
        end

        % Button pushed function: btnColocalizeNN
        function btnColocalizeNNButtonPushed(app, event)
            idx_src = find(strcmp(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value{1}),1);
            idx_dst = find(strcmp(app.lstColoSelObjects.Items, app.lstColoSelObjects.Value{2}),1);
            idx_NN  = 0;
            
            for i= 1: numel(app.Dots(idx_src).NN)
                if numel(fieldnames(app.Dots(idx_src).NN(i))) > 0
                    if strcmp(app.Dots(idx_src).NN(i).Name,  app.Dots(idx_dst).Name)
                        idx_NN = i; % Found the matching item
                        break
                    end
                end
            end
            
            if idx_NN > 0
                switch app.ColoSwitchVoxPerc.Value
                    case 'Voxels'
                        NumVoxOverlap  = app.ColoSharedVoxNum.Value;
                        NumPercOverlap = 0;
                    case '%'
                        NumVoxOverlap  = 0;
                        NumPercOverlap = app.ColoSharedVoxNum.Value;
                end
                
                % Assign colocalized status to those objects that have more than NumVoxOverlap AND NumPercOverlap
                ColocAuto = colocAutoNN(app.Dots(idx_src), app.Dots(idx_src).NN(idx_NN), NumVoxOverlap, NumPercOverlap);
                
                if isempty(fieldnames(app.Dots(idx_src).Coloc))
                    app.Dots(idx_src).Coloc = ColocAuto;
                else
                    % Check if we need to replace a previously done analysis with same mask
                    FoundColocManual  = false;
                    for i=1:numel(app.Dots(idx_src).Coloc)
                        if strcmp(app.Dots(idx_src).Coloc(i).Source, ColocAuto.Source) && strcmp(app.Dots(idx_src).Coloc(i).Fish1, ColocAuto.Fish1)
                            app.Dots(idx_src).Coloc(i) = ColocAuto;
                            FoundColocManual = true;
                            break
                        end
                    end
                    
                    % If nothing to replace, just append to the list
                    if ~FoundColocManual
                        app.Dots(idx_src).Coloc(end+1) = ColocAuto; % Coloc is saved later
                    end
                end
                Dots = app.Dots;
                save([pwd filesep 'Dots.mat'], 'Dots');
                app.RefreshColocalizationDone;
            end
        end

        % Button pushed function: btnPlotObjRoundDist
        function btnPlotObjRoundDistButtonPushed(app, event)
            idx = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            
            if idx > 0 && isfield(app.Dots(idx).Shape, 'Oblong')
                Objs = getFilteredObjects(app.Dots(idx), app.Dots(idx).Filter);
                figure('Name', 'Objects space distribution', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
                subplot(1,2,1);
                
                % Fit data with selected distribution type and plot
                h = histfit(Objs.Shape.Oblong, [], app.ReportDistFitType.Value);
                h(1).FaceColor = [.8 .8 1]; % violet histogram
                xlim([0 1]);
                xlabel('Oblongness (0: line, 1: sphere)');
                ylabel('Number of objects');
                legend({app.Dots(idx).Name, [app.ReportDistFitType.Value ' distribution']});
                title([app.Dots(idx).Name ' shape distribution']);
                
                % Collect plotted data
                SphericityBin   = get(h(1),'xdata')';   % Bin data
                Count           = get(h(1),'ydata')';   % Count data
                FitSphericityBin= get(h(2),'xdata')';   % Fitted Bin data
                FitCount        = get(h(2),'ydata')';   % Fitted Count data
                
                % Calculate and plot Cumulative distribution data
                CumCount        = Count;
                for i = 1:numel(Count)
                    if i==1
                        CumCount(i) = 100* Count(i)/sum(Count);
                    else
                        CumCount(i) = 100* Count(i)/sum(Count) + CumCount(i-1);
                    end
                end
                subplot(1,2,2);
                plot(SphericityBin, CumCount, '-o', 'MarkerFaceColor', [.8 .8 1], 'MarkerSize', 8, 'MarkerEdgeColor', [0 0 0]);
                ylim([0 100]);
                xlim([0 1]);
                box off;
                xlabel('Oblongness (0: line, 1: sphere)');
                ylabel('Percentage of objects');
                title([app.Dots(idx).Name ' shape cumulative distribution']);
                
                % Export data as Excel table inside the results folder
                if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                FileName    = [pwd filesep 'results' filesep app.Dots(idx).Name '_Oblongness_rawdata.xls'];
                Object      = (1:numel(Objs.Shape.Oblong))'; % Number of object on first column
                Oblongness  = Objs.Shape.Oblong';            % Volume of each object on second column
                TableData   = table(Object, Oblongness);
                writetable(TableData, FileName);             % Write an Excel table with Object-Oblongness pairs
                
                % Export distribution data as Excel table inside the results folder
                FileName        = [pwd filesep 'results' filesep app.Dots(idx).Name '_Sphericity_distribution.xls'];
                TableData       = table(SphericityBin, Count, CumCount);
                writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
                
                FileName        = [pwd filesep 'results' filesep app.Dots(idx).Name '_Sphericity_distribution_fit_' app.ReportDistFitType.Value '.xls'];
                TableData       = table(FitSphericityBin, FitCount);
                writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
            else
                msgbox('No shape information available.', 'Plot Error', 'error');
            end
        end

        % Button pushed function: btnPlotObjMajorAxisDist
        function btnPlotObjMajorAxisDistButtonPushed(app, event)
            idx = find(strcmp(app.ReportSelObjects.Items, app.ReportSelObjects.Value),1);
            
            if idx > 0 && isfield(app.Dots(idx).Shape, 'PrincipalAxisLen')
                Objs = getFilteredObjects(app.Dots(idx), app.Dots(idx).Filter);
                figure('Name', 'Objects length distribution', 'units', 'normalized', 'position', [0.1 0.2 0.8 0.5]);
                subplot(1,2,1);
                
                % Fit data with selected distribution type and plot
                h = histfit(Objs.Shape.PrincipalAxisLen(:,1)*app.Dots(idx).Settings.ImInfo.xyum, [], app.ReportDistFitType.Value);
                h(1).FaceColor = [.8 .8 1]; % violet histogram
                xlabel('Major axis length (µm)');
                ylabel('Number of objects');
                legend({app.Dots(idx).Name, [app.ReportDistFitType.Value ' distribution']});
                title([app.Dots(idx).Name ' length distribution']);
                
                % Collect plotted data
                MajorAxisBin    = get(h(1),'xdata')';   % Bin data
                Count           = get(h(1),'ydata')';   % Count data
                FitMajorAxisBin = get(h(2),'xdata')';   % Fitted Bin data
                FitCount        = get(h(2),'ydata')';   % Fitted Count data
                
                % Calculate and plot Cumulative distribution data
                CumCount        = Count;
                for i = 1:numel(Count)
                    if i==1
                        CumCount(i) = 100* Count(i)/sum(Count);
                    else
                        CumCount(i) = 100* Count(i)/sum(Count) + CumCount(i-1);
                    end
                end
                subplot(1,2,2);
                plot(MajorAxisBin, CumCount, '-o', 'MarkerFaceColor', [.8 .8 1], 'MarkerSize', 8, 'MarkerEdgeColor', [0 0 0]);
                ylim([0 100]);
                box off;
                xlabel('Major axis length (µm)');
                ylabel('Percentage of objects');
                title([app.Dots(idx).Name ' length cumulative distribution']);
                
                % Export data as Excel table inside the results folder
                if ~isfolder([pwd filesep 'results']), mkdir([pwd filesep 'results']); end
                FileName    = [pwd filesep 'results' filesep app.Dots(idx).Name '_MajorAxisLength_rawdata.xls'];
                Object      = (1:size(Objs.Shape.PrincipalAxisLen,1))'; % Number of object on first column
                MajorAxis   = Objs.Shape.PrincipalAxisLen(:,1)*app.Dots(idx).Settings.ImInfo.xyum; % Length of the major principal axis
                TableData   = table(Object, MajorAxis);
                writetable(TableData, FileName);
                
                % Export distribution data as Excel table inside the results folder
                FileName        = [pwd filesep 'results' filesep app.Dots(idx).Name '_MajorAxisLength_distribution.xls'];
                TableData       = table(MajorAxisBin, Count, CumCount);
                writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
                
                FileName        = [pwd filesep 'results' filesep app.Dots(idx).Name '_MajorAxisLength_distribution_fit_' app.ReportDistFitType.Value '.xls'];
                TableData       = table(FitMajorAxisBin, FitCount);
                writetable(TableData, FileName);        % Write an Excel table with Object-Volume pairs
            else
                msgbox('No shape information available.', 'Plot Error', 'error');
            end
        end

        % Button pushed function: btnRecalculateSphericity
        function btnRecalculateSphericityButtonPushed(app, event)
            % Recalculate sphericity of objects
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            % Retrieve index of current objects
            idx = find(strcmp(app.DetectSelObjects.Items, app.DetectSelObjects.Value),1) -1;
            if idx
                app.Dots(idx).Settings.objfinder.sphericity            = true;
                
                app.Log.Value = 'Calculating sphericity of objects... ';
                app.Dots(idx) = fitSphere(app.Dots(idx), app.Dots(idx).Settings);
                Dots = app.Dots;
                save([pwd filesep 'Dots.mat'],'Dots');
                app.DetectSelObjectsValueChanged;
                app.Log.Value = 'Calculating sphericity of objects... DONE';
                app.Lamp.Color = 'green';
            else
                app.Log.Value = 'Error: cannot find requested objects';
                app.Lamp.Color = 'red';
            end
        end

        % Value changed function: InspectorType
        function InspectorTypeValueChanged(app, event)
            value = app.InspectorType.Value;
            switch value
                case 'Neural Net'
                    app.InspectorNeuralNet.Enable = 'on';
                    app.RefreshNeuralNets;
                otherwise
                    app.InspectorNeuralNet.Enable = 'off';
            end
        end

        % Button pushed function: btnCopyObjects
        function btnCopyObjectsButtonPushed(app, event)
            app.Lamp.Color = 'yellow'; pause(0.2);
            
            % Find index of current objects (0= new objects set)
            idx = find(strcmp(app.DetectSelObjects.Items, app.DetectSelObjects.Value),1) -1;
            
            app.Dots(end+1) = app.Dots(idx);
            app.Dots(end).Name = [app.Dots(end).Name ' (copy)'];
            app.RefreshValidObjects;
            app.DetectSelObjects.Value = app.Dots(end).Name;
            app.DetectSelObjectsValueChanged;
            
            Dots= app.Dots;
            save([pwd filesep 'Dots.mat'], 'Dots');
            
            app.Lamp.Color = 'green';
            
        end

        % Button pushed function: btnReportBug
        function btnReportBugButtonPushed(app, event)
            web('https://github.com/lucadellasantina/ObjectFinder/issues', '-browser');
        end

        % Button pushed function: btnHomePage
        function btnHomePageButtonPushed(app, event)
            web('https://lucadellasantina.github.io/ObjectFinder', '-browser');
        end

        % Button pushed function: btnUserManual
        function btnUserManualButtonPushed(app, event)
            web('https://github.com/lucadellasantina/ObjectFinder/wiki', '-browser');
        end

        % Button pushed function: btnCalcDiameter
        function btnCalcDiameterButtonPushed(app, event)
            if isempty(app.dlgCalc) || ~isprop(app.dlgCalc,'UIFigure')
                app.dlgCalc = DiameterCalculator(app);
            else
                app.dlgCalc.UIFigure.Visible = 'on';
            end
            % Center the dialog on main window
            pos = app.dlgCalc.UIFigure.Position;
            posSelf = app.UIFigure.Position;
            pos(1) = ceil(posSelf(1)+posSelf(3)/2-pos(3)/2);
            pos(2) = ceil(posSelf(2)+posSelf(4)/2-pos(4)/2);
            app.dlgCalc.UIFigure.Position = pos;
            
            % Populate calculator with current size range in voxels
            app.dlgCalc.MinSizeVox.Value = app.convertDiameterToVoxels(app.MinSizeXY.Value);
            app.dlgCalc.MaxSizeVox.Value = app.convertDiameterToVoxels(app.MaxSizeXY.Value);
        end

        % Value changed function: BlockSearch
        function BlockSearchValueChanged(app, event)
            if app.BlockSearch.Value
                app.LocalNoise.Enable = 'on';
                app.LocalNoise.Value  = true;
            else
                app.LocalNoise.Value = false;
                app.LocalNoise.Enable = 'off';
            end
        end

        % Value changed function: BatchBlockSearch
        function BatchBlockSearchValueChanged(app, event)
            if app.BatchBlockSearch.Value
                app.BatchLocalNoise.Enable = 'on';
                app.BatchLocalNoise.Value  = true;
            else
                app.BatchLocalNoise.Enable = 'off';
                app.BatchLocalNoise.Value  = false;
            end
        end

        % Button pushed function: btnPurgeInvalid
        function btnPurgeInvalidButtonPushed(app, event)
            % Find index of current objects (0= new objects set)
            idx = find(strcmp(app.DetectSelObjects.Items, app.DetectSelObjects.Value),1) -1;
            
            if idx > 0
                app.Lamp.Color = 'Yellow';
                app.Log.Value = 'Purging invalid objects from current set...'; pause(0.2);
                app.Dots(idx) = getFilteredObjects(app.Dots(idx), app.Dots(idx).Filter);
                
                app.RefreshValidObjects;
                app.DetectSelObjects.Value = app.Dots(idx).Name;
                app.DetectSelObjectsValueChanged;
                
                Dots= app.Dots;
                save([pwd filesep 'Dots.mat'], 'Dots');
                app.Log.Value = 'Purging invalid objects from current set...DONE';
                app.Lamp.Color = 'Green';
            end
        end

        % Button pushed function: btnBatchAddMultiple
        function btnBatchAddMultipleButtonPushed(app, event)
            app.Lamp.Color = 'yellow';  pause(0.2);

            rootDir = uigetdir(pwd , 'Select root folder');
            app.UIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.UIFigure.Visible = 'on';  %Fixes glitch by which app loses focus

            if ~rootDir
                app.Lamp.Color = 'Green';
                return;
            else
                cd(rootDir);
            end
            
            dirs = dir;                 % List the content of rootDir
            dirs = dirs([dirs.isdir]);  % Keep only subfolders            
            dirs(1:2) = [];             % remove first items from list ("." and "..")

            for d = 1:numel(dirs)
                % Enter the subfolder
                currDir = [dirs(d).folder filesep dirs(d).name];
                cd(currDir); 

                % Load the image/mask to process for this folder
                if (d > 1) && app.chkBatchPropagateSettings.Value                    
                    % Use the image/mask ID number used for the first folder
                    app.loadImages(app.Settings.ImInfo.PostCh, app.Settings.ImInfo.MaskCh);
                    
                elseif ~isfile("Settings.mat") || ~isfile("Post.mat") || ~isfile("Mask.mat") || app.chkBatchNewImages.Value                    
                    % Ask user to specify which image/mask to process
                    app.ChooseImagesButtonPushed;
                end
                
                % Add current subfolder to the processing list
                if isempty(app.lstBatch.Items)
                    app.lstBatch.Items = {currDir};
                else
                    app.lstBatch.Items{end+1} = currDir;
                end
            end
            
            app.RefreshAutomateColocalization;
            cd(rootDir);            
            app.lstBatchValueChanged;
            app.Lamp.Color = 'green';            
        end

        % Value changed function: AutoColoMaskOrObjs
        function AutoColoMaskOrObjsValueChanged(app, event)
            app.RefreshAutomateColocalization;            
        end

        % Value changed function: chkAutoColoAll
        function chkAutoColoAllValueChanged(app, event)
            if app.chkAutoColoAll.Value
                app.AutoColoRefObjs.Enable = 'off';                
            else
                app.AutoColoRefObjs.Enable = 'on';
            end            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'ObjectFinder';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Lamp = uilamp(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DetectTab = uitab(app.TabGroup);
            app.DetectTab.Title = 'Detect';
            app.SelectFolderButton = uibutton(app.DetectTab, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Icon = '48px-HomeFolder.png';
            app.SelectFolderButton.Text = 'Select Folder';
            app.ChooseImagesButton = uibutton(app.DetectTab, 'push');
            app.ChooseImagesButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImagesButtonPushed, true);
            app.ChooseImagesButton.Icon = '48px-Image_upload.png';
            app.ChooseImagesButton.Enable = 'off';
            app.ChooseImagesButton.Text = 'Choose Images';
            app.FindObjectsButton = uibutton(app.DetectTab, 'push');
            app.FindObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @FindObjectsButtonPushed, true);
            app.FindObjectsButton.Icon = '48px-FindObjects.png';
            app.FindObjectsButton.Enable = 'off';
            app.FindObjectsButton.Text = 'Find Objects';
            app.ValidateobjectsButton = uibutton(app.DetectTab, 'push');
            app.ValidateobjectsButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateobjectsButtonPushed, true);
            app.ValidateobjectsButton.Icon = '48px-Inspect.png';
            app.ValidateobjectsButton.Enable = 'off';
            app.ValidateobjectsButton.Text = 'Validate objects';
            app.SearchsettingsPanel = uipanel(app.DetectTab);
            app.SearchsettingsPanel.Title = 'Search settings';
            app.MaxLabel_3 = uilabel(app.SearchsettingsPanel);
            app.MaxLabel_3.Text = 'Max.:';
            app.MaxSizeXY = uieditfield(app.SearchsettingsPanel, 'numeric');
            app.MaxSizeXY.Limits = [0 Inf];
            app.MaxSizeXY.Value = 5;
            app.Watershed = uicheckbox(app.SearchsettingsPanel);
            app.Watershed.Text = 'Watershed';
            app.Watershed.Value = true;
            app.MinLabel_3 = uilabel(app.SearchsettingsPanel);
            app.MinLabel_3.Text = 'Min.:';
            app.MinSizeXY = uieditfield(app.SearchsettingsPanel, 'numeric');
            app.MinSizeXY.Value = 0.5;
            app.BlockSearch = uicheckbox(app.SearchsettingsPanel);
            app.BlockSearch.ValueChangedFcn = createCallbackFcn(app, @BlockSearchValueChanged, true);
            app.BlockSearch.Text = 'Block search';
            app.BlockSearch.Value = true;
            app.Sphericity = uicheckbox(app.SearchsettingsPanel);
            app.Sphericity.Text = 'Shape info';
            app.btnRecalculateSphericity = uibutton(app.SearchsettingsPanel, 'push');
            app.btnRecalculateSphericity.ButtonPushedFcn = createCallbackFcn(app, @btnRecalculateSphericityButtonPushed, true);
            app.btnRecalculateSphericity.Icon = '48px-Blow-Fish.png';
            app.btnRecalculateSphericity.Enable = 'off';
            app.btnRecalculateSphericity.Text = {'Measure '; 'shape'};
            app.ExpectedsizerangediametermLabel = uilabel(app.SearchsettingsPanel);
            app.ExpectedsizerangediametermLabel.Text = 'Expected size range (diameter, µm)';
            app.MinimumobjectintensitytimesabovenoiseLabel = uilabel(app.SearchsettingsPanel);
            app.MinimumobjectintensitytimesabovenoiseLabel.Text = 'Minimum object intensity (# times above noise):';
            app.MinIntensity = uispinner(app.SearchsettingsPanel);
            app.MinIntensity.Limits = [1 Inf];
            app.MinIntensity.Value = 1;
            app.SingleZDotCut = uicheckbox(app.SearchsettingsPanel);
            app.SingleZDotCut.Text = 'On a sigle Z plane';
            app.SingleZDotCut.Value = true;
            app.EdgeDotCut = uicheckbox(app.SearchsettingsPanel);
            app.EdgeDotCut.Text = 'On mask''s edge';
            app.xyStableDots = uicheckbox(app.SearchsettingsPanel);
            app.xyStableDots.Text = 'Unstable centroids';
            app.FilteragainButton = uibutton(app.SearchsettingsPanel, 'push');
            app.FilteragainButton.ButtonPushedFcn = createCallbackFcn(app, @FilteragainButtonPushed, true);
            app.FilteragainButton.Icon = '48px-Filter.png';
            app.FilteragainButton.Enable = 'off';
            app.FilteragainButton.Text = {'Filter'; 'again'};
            app.btnCalcDiameter = uibutton(app.SearchsettingsPanel, 'push');
            app.btnCalcDiameter.ButtonPushedFcn = createCallbackFcn(app, @btnCalcDiameterButtonPushed, true);
            app.btnCalcDiameter.Icon = '48px-calculator.png';
            app.btnCalcDiameter.Enable = 'off';
            app.btnCalcDiameter.Text = {'Calculate'; 'diameter'};
            app.ExcludecandidateobjectsiftheirvoxelsareLabel = uilabel(app.SearchsettingsPanel);
            app.ExcludecandidateobjectsiftheirvoxelsareLabel.Text = 'Exclude candidate objects if their voxels are:';
            app.LocalNoise = uicheckbox(app.SearchsettingsPanel);
            app.LocalNoise.Text = 'Local noise';
            app.LocalNoise.Value = true;
            app.ValidationsettingsPanel = uipanel(app.DetectTab);
            app.ValidationsettingsPanel.Title = 'Validation settings';
            app.ToolLabel = uilabel(app.ValidationsettingsPanel);
            app.ToolLabel.Text = 'Tool:';
            app.InspectorType = uidropdown(app.ValidationsettingsPanel);
            app.InspectorType.Items = {'Volume Inspector', 'Bitplane Imaris (7.2.3 or older)', 'Bitplane Imaris (7.2.4 or newer)', 'Neural Net'};
            app.InspectorType.ValueChangedFcn = createCallbackFcn(app, @InspectorTypeValueChanged, true);
            app.InspectorType.Value = 'Volume Inspector';
            app.NeuralNetLabel = uilabel(app.ValidationsettingsPanel);
            app.NeuralNetLabel.Text = 'Neural Net:';
            app.InspectorNeuralNet = uidropdown(app.ValidationsettingsPanel);
            app.InspectorNeuralNet.Items = {};
            app.InspectorNeuralNet.Enable = 'off';
            app.InspectorNeuralNet.Value = {};
            app.InspectorNewObjects = uicheckbox(app.ValidationsettingsPanel);
            app.InspectorNewObjects.Text = 'Create a new set of objects after validation is completed';
            app.btnPurgeInvalid = uibutton(app.ValidationsettingsPanel, 'push');
            app.btnPurgeInvalid.ButtonPushedFcn = createCallbackFcn(app, @btnPurgeInvalidButtonPushed, true);
            app.btnPurgeInvalid.Icon = '48px-trash.png';
            app.btnPurgeInvalid.Enable = 'off';
            app.btnPurgeInvalid.Text = {'Purge'; 'invalid'};
            app.CurrentObjectsPanel = uipanel(app.DetectTab);
            app.CurrentObjectsPanel.Title = 'Current objects';
            app.DetectSelObjects = uidropdown(app.CurrentObjectsPanel);
            app.DetectSelObjects.Items = {'New Objects'};
            app.DetectSelObjects.ValueChangedFcn = createCallbackFcn(app, @DetectSelObjectsValueChanged, true);
            app.DetectSelObjects.Value = 'New Objects';
            app.btnRenameObjects = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnRenameObjects.ButtonPushedFcn = createCallbackFcn(app, @btnRenameObjectsButtonPushed, true);
            app.btnRenameObjects.Icon = '48px-Text-x-generic_with_pencil.png';
            app.btnRenameObjects.Enable = 'off';
            app.btnRenameObjects.Text = 'Rename';
            app.btnDeleteObjects = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnDeleteObjects.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteObjectsButtonPushed, true);
            app.btnDeleteObjects.Icon = '48px-Edit-delete-2.png';
            app.btnDeleteObjects.Enable = 'off';
            app.btnDeleteObjects.Text = 'Delete';
            app.btnCopyObjects = uibutton(app.CurrentObjectsPanel, 'push');
            app.btnCopyObjects.ButtonPushedFcn = createCallbackFcn(app, @btnCopyObjectsButtonPushed, true);
            app.btnCopyObjects.Icon = '48px-Edit-copy.png';
            app.btnCopyObjects.Enable = 'off';
            app.btnCopyObjects.Text = 'Duplicate';
            app.lblFolder = uilabel(app.CurrentObjectsPanel);
            app.lblFolder.Text = 'Folder: N/A';
            app.lblImageFile = uilabel(app.CurrentObjectsPanel);
            app.lblImageFile.Text = 'Image file: N/A';
            app.lblMask = uilabel(app.CurrentObjectsPanel);
            app.lblMask.Text = 'Mask file: N/A';
            app.lblResolution = uilabel(app.CurrentObjectsPanel);
            app.lblResolution.Text = 'Voxel size: N/A';
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.pnlObjDensity = uipanel(app.ReportTab);
            app.pnlObjDensity.Title = 'Plot Density';
            app.btnPlotDensityByDepth = uibutton(app.pnlObjDensity, 'push');
            app.btnPlotDensityByDepth.ButtonPushedFcn = createCallbackFcn(app, @btnPlotDensityByDepthPushed, true);
            app.btnPlotDensityByDepth.Icon = '48px-Whale-Tail.png';
            app.btnPlotDensityByDepth.Text = 'By Depth';
            app.btnPlotDensityLinear = uibutton(app.pnlObjDensity, 'push');
            app.btnPlotDensityLinear.ButtonPushedFcn = createCallbackFcn(app, @btnPlotDensityLinearPushed, true);
            app.btnPlotDensityLinear.Icon = '48px-Tentacle.png';
            app.btnPlotDensityLinear.Text = 'Along Skel';
            app.btnPlotHeatmap = uibutton(app.pnlObjDensity, 'push');
            app.btnPlotHeatmap.ButtonPushedFcn = createCallbackFcn(app, @btnPlotHeatmapPushed, true);
            app.btnPlotHeatmap.Icon = '48px-Heatmap.png';
            app.btnPlotHeatmap.Text = 'As Heatmap';
            app.btnPlotSholl = uibutton(app.pnlObjDensity, 'push');
            app.btnPlotSholl.ButtonPushedFcn = createCallbackFcn(app, @btnPlotShollPushed, true);
            app.btnPlotSholl.Icon = '48px-Sholl.png';
            app.btnPlotSholl.Text = 'Sholl Analysis';
            app.pnlObjPosition = uipanel(app.ReportTab);
            app.pnlObjPosition.Title = 'Plot Position';
            app.btnPlotLocationsXY = uibutton(app.pnlObjPosition, 'push');
            app.btnPlotLocationsXY.ButtonPushedFcn = createCallbackFcn(app, @btnPlotLocationsXYPushed, true);
            app.btnPlotLocationsXY.Icon = '48px-Map-Treaure.png';
            app.btnPlotLocationsXY.Text = 'Locations XY';
            app.btnPlotSkeleton = uibutton(app.pnlObjPosition, 'push');
            app.btnPlotSkeleton.ButtonPushedFcn = createCallbackFcn(app, @btnPlotSkeletonPushed, true);
            app.btnPlotSkeleton.Icon = '48px-Fish-Bone.png';
            app.btnPlotSkeleton.Text = 'Skeleton';
            app.btnPlotLocationsZ = uibutton(app.pnlObjPosition, 'push');
            app.btnPlotLocationsZ.ButtonPushedFcn = createCallbackFcn(app, @btnPlotLocationsZButtonPushed, true);
            app.btnPlotLocationsZ.Icon = '48px-Map-Treaure.png';
            app.btnPlotLocationsZ.Text = 'Locations XZ';
            app.btnCreateMask = uibutton(app.pnlObjPosition, 'push');
            app.btnCreateMask.ButtonPushedFcn = createCallbackFcn(app, @btnCreateMaskButtonPushed, true);
            app.btnCreateMask.Icon = '48px-Masks.png';
            app.btnCreateMask.Text = 'Create Mask';
            app.pnlObjStats = uipanel(app.ReportTab);
            app.pnlObjStats.Title = 'Select a set of objects to display their properties';
            app.ReportSelObjects = uidropdown(app.pnlObjStats);
            app.ReportSelObjects.Items = {};
            app.ReportSelObjects.ValueChangedFcn = createCallbackFcn(app, @ReportSelObjectsValueChanged, true);
            app.ReportSelObjects.Value = {};
            app.lblReportValid = uilabel(app.pnlObjStats);
            app.lblReportValid.Text = 'Valid objects: N/A';
            app.lblReportAvgSize = uilabel(app.pnlObjStats);
            app.lblReportAvgSize.Text = 'Size (average): N/A';
            app.lblReportAvgBright = uilabel(app.pnlObjStats);
            app.lblReportAvgBright.Text = 'Brightness (average): N/A';
            app.lblReportSrcImgName = uilabel(app.pnlObjStats);
            app.lblReportSrcImgName.Text = 'Source image: N/A';
            app.lblReportSrcImgVol = uilabel(app.pnlObjStats);
            app.lblReportSrcImgVol.Text = 'Image volume: N/A';
            app.lblReportVolOccupancy = uilabel(app.pnlObjStats);
            app.lblReportVolOccupancy.Text = 'Volume occupancy: N/A';
            app.lblReportSrcImgRes = uilabel(app.pnlObjStats);
            app.lblReportSrcImgRes.Text = 'Voxel size : N/A';
            app.lblReportMask = uilabel(app.pnlObjStats);
            app.lblReportMask.Text = 'Masked region: N/A';
            app.pnlNearestNeighbor = uipanel(app.ReportTab);
            app.pnlNearestNeighbor.Title = 'Nearest neighbor analysis';
            app.NeighboringobjectsDropDownLabel = uilabel(app.pnlNearestNeighbor);
            app.NeighboringobjectsDropDownLabel.Text = 'Neighboring objects:';
            app.ReportSelObjectsNN = uidropdown(app.pnlNearestNeighbor);
            app.ReportSelObjectsNN.Items = {};
            app.ReportSelObjectsNN.ValueChangedFcn = createCallbackFcn(app, @ReportSelObjectsNNValueChanged, true);
            app.ReportSelObjectsNN.Value = {};
            app.CalculateNN = uibutton(app.pnlNearestNeighbor, 'push');
            app.CalculateNN.ButtonPushedFcn = createCallbackFcn(app, @CalculateNNButtonPushed, true);
            app.CalculateNN.Icon = '48px-Measure.png';
            app.CalculateNN.Text = 'Calculate';
            app.Button_2 = uibutton(app.pnlNearestNeighbor, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = '48px-Edit-delete-2.png';
            app.Button_2.Text = '';
            app.lblNNdistance = uilabel(app.pnlNearestNeighbor);
            app.lblNNdistance.Text = 'Nearest neighbor distance: N/A';
            app.lblNNvoxelOverlap = uilabel(app.pnlNearestNeighbor);
            app.lblNNvoxelOverlap.Text = 'Percent voxels overlapping: N/A';
            app.btnPlotNNdistDist = uibutton(app.pnlNearestNeighbor, 'push');
            app.btnPlotNNdistDist.ButtonPushedFcn = createCallbackFcn(app, @btnPlotNNdistDistButtonPushed, true);
            app.btnPlotNNdistDist.Text = 'Distance distribution';
            app.btnPlotNNoverlapDist = uibutton(app.pnlNearestNeighbor, 'push');
            app.btnPlotNNoverlapDist.ButtonPushedFcn = createCallbackFcn(app, @btnPlotNNoverlapDistButtonPushed, true);
            app.btnPlotNNoverlapDist.Text = 'Overlap distribution';
            app.pnlObjPosition_2 = uipanel(app.ReportTab);
            app.pnlObjPosition_2.Title = 'Plot Distribution';
            app.btnPlotObjSizeDist = uibutton(app.pnlObjPosition_2, 'push');
            app.btnPlotObjSizeDist.ButtonPushedFcn = createCallbackFcn(app, @btnPlotObjSizeDistButtonPushed, true);
            app.btnPlotObjSizeDist.Icon = '48px-Whale-Small-Fish.png';
            app.btnPlotObjSizeDist.Text = 'Volume';
            app.btnPlotObjBrightDist = uibutton(app.pnlObjPosition_2, 'push');
            app.btnPlotObjBrightDist.ButtonPushedFcn = createCallbackFcn(app, @btnPlotObjBrightDistButtonPushed, true);
            app.btnPlotObjBrightDist.Icon = '48px-Angler-Fish.png';
            app.btnPlotObjBrightDist.Text = 'Brightness';
            app.FittingdistributionLabel = uilabel(app.pnlObjPosition_2);
            app.FittingdistributionLabel.Text = 'Fitting distribution:';
            app.ReportDistFitType = uidropdown(app.pnlObjPosition_2);
            app.ReportDistFitType.Items = {'beta', 'burr', 'exponential', 'gamma', 'inversegaussian', 'logistic', 'loglogistic', 'lognormal', 'nakagami', 'negative binomial', 'normal', 'poisson', 'rayleigh', 'rician', 'weibull', 'kernel'};
            app.ReportDistFitType.Value = 'normal';
            app.btnPlotObjRoundDist = uibutton(app.pnlObjPosition_2, 'push');
            app.btnPlotObjRoundDist.ButtonPushedFcn = createCallbackFcn(app, @btnPlotObjRoundDistButtonPushed, true);
            app.btnPlotObjRoundDist.Icon = '48px-Blow-Fish.png';
            app.btnPlotObjRoundDist.Text = 'Sphericity';
            app.btnPlotObjMajorAxisDist = uibutton(app.pnlObjPosition_2, 'push');
            app.btnPlotObjMajorAxisDist.ButtonPushedFcn = createCallbackFcn(app, @btnPlotObjMajorAxisDistButtonPushed, true);
            app.btnPlotObjMajorAxisDist.Icon = '48px-Crab.png';
            app.btnPlotObjMajorAxisDist.Text = 'Major axis';
            app.AutomateTab = uitab(app.TabGroup);
            app.AutomateTab.Title = 'Automate';
            app.ProcessinglistPanel = uipanel(app.AutomateTab);
            app.ProcessinglistPanel.Title = 'Processing list';
            app.lstBatch = uilistbox(app.ProcessinglistPanel);
            app.lstBatch.Items = {};
            app.lstBatch.ValueChangedFcn = createCallbackFcn(app, @lstBatchValueChanged, true);
            app.lstBatch.Value = {};
            app.btnBatchAdd = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchAdd.ButtonPushedFcn = createCallbackFcn(app, @btnBatchAddButtonPushed, true);
            app.btnBatchAdd.Icon = '48px-List-add.png';
            app.btnBatchAdd.Text = '';
            app.btnBatchRemove = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchRemove.ButtonPushedFcn = createCallbackFcn(app, @btnBatchRemoveButtonPushed, true);
            app.btnBatchRemove.Icon = '48px-List-remove.png';
            app.btnBatchRemove.Enable = 'off';
            app.btnBatchRemove.Text = '';
            app.btnBatchUp = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchUp.ButtonPushedFcn = createCallbackFcn(app, @btnBatchUpButtonPushed, true);
            app.btnBatchUp.Icon = '48px-Go-up.png';
            app.btnBatchUp.Enable = 'off';
            app.btnBatchUp.Text = '';
            app.btnBatchDown = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchDown.ButtonPushedFcn = createCallbackFcn(app, @btnBatchDownButtonPushed, true);
            app.btnBatchDown.Icon = '48px-Go-down.png';
            app.btnBatchDown.Enable = 'off';
            app.btnBatchDown.Text = '';
            app.btnBatchTop = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchTop.ButtonPushedFcn = createCallbackFcn(app, @btnBatchTopButtonPushed, true);
            app.btnBatchTop.Icon = '48px-Go-top.png';
            app.btnBatchTop.Enable = 'off';
            app.btnBatchTop.Text = '';
            app.btnBatchBottom = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchBottom.ButtonPushedFcn = createCallbackFcn(app, @btnBatchBottomButtonPushed, true);
            app.btnBatchBottom.Icon = '48px-Go-bottom.png';
            app.btnBatchBottom.Enable = 'off';
            app.btnBatchBottom.Text = '';
            app.btnBatchClear = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchClear.ButtonPushedFcn = createCallbackFcn(app, @btnBatchClearButtonPushed, true);
            app.btnBatchClear.Icon = '48px-Edit-clear.png';
            app.btnBatchClear.Enable = 'off';
            app.btnBatchClear.Text = '';
            app.btnBatchAddMultiple = uibutton(app.ProcessinglistPanel, 'push');
            app.btnBatchAddMultiple.ButtonPushedFcn = createCallbackFcn(app, @btnBatchAddMultipleButtonPushed, true);
            app.btnBatchAddMultiple.Icon = '48px-Add-Folder.png';
            app.btnBatchAddMultiple.Text = '';
            app.chkBatchNewImages = uicheckbox(app.ProcessinglistPanel);
            app.chkBatchNewImages.Text = 'Select new images to analyze';
            app.chkBatchPropagateSettings = uicheckbox(app.ProcessinglistPanel);
            app.chkBatchPropagateSettings.Text = 'Use first folder settings for all folders';
            app.btnBatchStart = uibutton(app.AutomateTab, 'push');
            app.btnBatchStart.ButtonPushedFcn = createCallbackFcn(app, @btnBatchStartButtonPushed, true);
            app.btnBatchStart.Icon = '72px-Batch-process.png';
            app.btnBatchStart.Text = 'Start';
            app.BatchLog = uitextarea(app.AutomateTab);
            app.BatchLog.Value = {'Activity log:'};
            app.btnBatchStop = uibutton(app.AutomateTab, 'state');
            app.btnBatchStop.Icon = '48px-stop-hand.png';
            app.btnBatchStop.Text = 'Stop';
            app.AutomateOperation = uitabgroup(app.AutomateTab);
            app.AutoSearchTab = uitab(app.AutomateOperation);
            app.AutoSearchTab.Title = 'Search';
            app.BatchWatershed = uicheckbox(app.AutoSearchTab);
            app.BatchWatershed.Text = 'Watershed';
            app.BatchWatershed.Value = true;
            app.BatchBlockSearch = uicheckbox(app.AutoSearchTab);
            app.BatchBlockSearch.ValueChangedFcn = createCallbackFcn(app, @BatchBlockSearchValueChanged, true);
            app.BatchBlockSearch.Text = 'Block search';
            app.BatchBlockSearch.Value = true;
            app.BatchSphericity = uicheckbox(app.AutoSearchTab);
            app.BatchSphericity.Text = 'Shape info';
            app.ExpecteddiameterrangeofobjectsmLabel = uilabel(app.AutoSearchTab);
            app.ExpecteddiameterrangeofobjectsmLabel.Text = 'Expected diameter range of objects (µm)';
            app.BatchSingleZDotCut = uicheckbox(app.AutoSearchTab);
            app.BatchSingleZDotCut.Text = 'On a Single Z plane';
            app.BatchSingleZDotCut.Value = true;
            app.BatchEdgeDotCut = uicheckbox(app.AutoSearchTab);
            app.BatchEdgeDotCut.Text = 'On mask''s edge';
            app.BatchxyStableDots = uicheckbox(app.AutoSearchTab);
            app.BatchxyStableDots.Text = 'Unstable centroids';
            app.ExcludecandidateobjectsiftheirvoxelsareLabel_2 = uilabel(app.AutoSearchTab);
            app.ExcludecandidateobjectsiftheirvoxelsareLabel_2.Text = 'Exclude candidate objects if their voxels are:';
            app.BatchLocalNoise = uicheckbox(app.AutoSearchTab);
            app.BatchLocalNoise.Text = 'Local noise';
            app.BatchLocalNoise.Value = true;
            app.MaxLabel_4 = uilabel(app.AutoSearchTab);
            app.MaxLabel_4.Text = 'Max';
            app.BatchMaxSizeXY = uieditfield(app.AutoSearchTab, 'numeric');
            app.BatchMaxSizeXY.Limits = [0 Inf];
            app.BatchMaxSizeXY.Value = 5;
            app.MinLabel_4 = uilabel(app.AutoSearchTab);
            app.MinLabel_4.Text = 'Min';
            app.BatchMinSizeXY = uieditfield(app.AutoSearchTab, 'numeric');
            app.BatchMinSizeXY.Limits = [0 Inf];
            app.BatchMinSizeXY.Value = 0.5;
            app.ismorethanLabel = uilabel(app.AutoSearchTab);
            app.ismorethanLabel.Text = 'is more than';
            app.BatchThreshValue = uieditfield(app.AutoSearchTab, 'numeric');
            app.MinimumintensityofobjectstimesthenoiselevelLabel = uilabel(app.AutoSearchTab);
            app.MinimumintensityofobjectstimesthenoiselevelLabel.Text = 'Minimum intensity of objects (# times the noise level):';
            app.BatchMinIntensity = uispinner(app.AutoSearchTab);
            app.BatchMinIntensity.Limits = [1 Inf];
            app.BatchMinIntensity.Value = 1;
            app.ValidateifLabel = uilabel(app.AutoSearchTab);
            app.ValidateifLabel.Text = 'Validate if';
            app.BatchThreshParam = uidropdown(app.AutoSearchTab);
            app.BatchThreshParam.Items = {'None', 'Score', 'Volume', 'Brightness', 'Roundness', 'Major Axis Length'};
            app.BatchThreshParam.Value = 'None';
            app.AutoColocalizeTab = uitab(app.AutomateOperation);
            app.AutoColocalizeTab.Title = 'Colocalize';
            app.ReferenceObjectsDropDownLabel = uilabel(app.AutoColocalizeTab);
            app.ReferenceObjectsDropDownLabel.Text = 'Reference Objects:';
            app.AutoColoRefObjs = uidropdown(app.AutoColocalizeTab);
            app.AutoColoRefObjs.Items = {};
            app.AutoColoRefObjs.Value = {};
            app.AutoColoChannel = uidropdown(app.AutoColocalizeTab);
            app.AutoColoChannel.Items = {};
            app.AutoColoChannel.Value = {};
            app.TocolocalizewithSwitchLabel = uilabel(app.AutoColocalizeTab);
            app.TocolocalizewithSwitchLabel.Text = 'To colocalize with:';
            app.AutoColoMaskOrObjs = uiswitch(app.AutoColocalizeTab, 'slider');
            app.AutoColoMaskOrObjs.Items = {'Mask', 'Objects'};
            app.AutoColoMaskOrObjs.ValueChangedFcn = createCallbackFcn(app, @AutoColoMaskOrObjsValueChanged, true);
            app.AutoColoMaskOrObjs.Value = 'Mask';
            app.MustshareatleastSpinnerLabel = uilabel(app.AutoColocalizeTab);
            app.MustshareatleastSpinnerLabel.Text = 'Must share at least:';
            app.AutoColoVoxNum = uispinner(app.AutoColocalizeTab);
            app.AutoColoSwitchVoxPerc = uiswitch(app.AutoColocalizeTab, 'slider');
            app.AutoColoSwitchVoxPerc.Items = {'Voxels', 'Percent'};
            app.AutoColoSwitchVoxPerc.Value = 'Voxels';
            app.chkAutoColoAll = uicheckbox(app.AutoColocalizeTab);
            app.chkAutoColoAll.ValueChangedFcn = createCallbackFcn(app, @chkAutoColoAllValueChanged, true);
            app.chkAutoColoAll.Text = 'Process all available object sets';
            app.chkAutoColoSaveTable = uicheckbox(app.AutoColocalizeTab);
            app.chkAutoColoSaveTable.Text = 'Save colocalization statistics in spreasheet';
            app.BatchScheduledTime = uiknob(app.AutomateTab, 'continuous');
            app.BatchScheduledTime.Limits = [1 24];
            app.BatchScheduledTime.Enable = 'off';
            app.BatchScheduledTime.Value = 19;
            app.BatchScheduler = uicheckbox(app.AutomateTab);
            app.BatchScheduler.ValueChangedFcn = createCallbackFcn(app, @BatchSchedulerValueChanged, true);
            app.BatchScheduler.Text = '';
            app.lblBatchScheduler = uilabel(app.AutomateTab);
            app.lblBatchScheduler.Enable = 'off';
            app.lblBatchScheduler.Text = 'Start at a custiom time:';
            app.ColocalizeTab = uitab(app.TabGroup);
            app.ColocalizeTab.Title = 'Colocalize';
            app.ChannelstocolocalizeobjectswithPanel = uipanel(app.ColocalizeTab);
            app.ChannelstocolocalizeobjectswithPanel.Title = 'Channel(s) to colocalize objects with';
            app.lstColo = uilistbox(app.ChannelstocolocalizeobjectswithPanel);
            app.lstColo.Items = {};
            app.lstColo.Multiselect = 'on';
            app.lstColo.ValueChangedFcn = createCallbackFcn(app, @lstColoValueChanged, true);
            app.lstColo.Value = {};
            app.ProcessedchannelsPanel = uipanel(app.ColocalizeTab);
            app.ProcessedchannelsPanel.Title = 'Processed channels';
            app.lstColoDone = uilistbox(app.ProcessedchannelsPanel);
            app.lstColoDone.Items = {};
            app.lstColoDone.Multiselect = 'on';
            app.lstColoDone.ValueChangedFcn = createCallbackFcn(app, @lstColoDoneValueChanged, true);
            app.lstColoDone.Value = {};
            app.btnColoDoneRemove = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoDoneRemove.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoneRemoveButtonPushed, true);
            app.btnColoDoneRemove.Icon = '48px-List-remove.png';
            app.btnColoDoneRemove.Enable = 'off';
            app.btnColoDoneRemove.Text = '';
            app.btnColoDoneClear = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoDoneClear.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoneClearButtonPushed, true);
            app.btnColoDoneClear.Icon = '48px-Edit-clear.png';
            app.btnColoDoneClear.Text = '';
            app.btnColoRedoValid = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoRedoValid.ButtonPushedFcn = createCallbackFcn(app, @btnColoRedoValidButtonPushed, true);
            app.btnColoRedoValid.Icon = 'Fruit-2cherries-check.png';
            app.btnColoRedoValid.Enable = 'off';
            app.btnColoRedoValid.Text = {'Check '; 'colocalized'};
            app.lblColocRate = uilabel(app.ProcessedchannelsPanel);
            app.lblColocRate.Text = 'Colocalized with: N/A';
            app.lblReferenceName = uilabel(app.ProcessedchannelsPanel);
            app.lblReferenceName.Text = 'Reference objects: N/A';
            app.btnColoRedoDiscarded = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoRedoDiscarded.ButtonPushedFcn = createCallbackFcn(app, @btnColoRedoDiscardedButtonPushed, true);
            app.btnColoRedoDiscarded.Icon = '48px-Fruit-2cherries-cross.png';
            app.btnColoRedoDiscarded.Enable = 'off';
            app.btnColoRedoDiscarded.Text = {'Check not'; 'colocalized'};
            app.btnColoDoneUp = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoDoneUp.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoneUpButtonPushed, true);
            app.btnColoDoneUp.Icon = '48px-Go-up.png';
            app.btnColoDoneUp.Enable = 'off';
            app.btnColoDoneUp.Text = '';
            app.btnColoDoneDown = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoDoneDown.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoneDownButtonPushed, true);
            app.btnColoDoneDown.Icon = '48px-Go-down.png';
            app.btnColoDoneDown.Enable = 'off';
            app.btnColoDoneDown.Text = '';
            app.lblColoc2Rate = uilabel(app.ProcessedchannelsPanel);
            app.lblColoc2Rate.Text = 'Colocalized with: N/A';
            app.lblColocRateDouble = uilabel(app.ProcessedchannelsPanel);
            app.lblColocRateDouble.Text = 'Double colocalized: N/A';
            app.btnColoDoneTop = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoDoneTop.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoneTopButtonPushed, true);
            app.btnColoDoneTop.Icon = '48px-Go-top.png';
            app.btnColoDoneTop.Enable = 'off';
            app.btnColoDoneTop.Text = '';
            app.btnColoDoneBottom = uibutton(app.ProcessedchannelsPanel, 'push');
            app.btnColoDoneBottom.ButtonPushedFcn = createCallbackFcn(app, @btnColoDoneBottomButtonPushed, true);
            app.btnColoDoneBottom.Icon = '48px-Go-bottom.png';
            app.btnColoDoneBottom.Enable = 'off';
            app.btnColoDoneBottom.Text = '';
            app.lblColoMethod = uilabel(app.ProcessedchannelsPanel);
            app.lblColoMethod.Text = 'Method:';
            app.lblColoVoxThresh = uilabel(app.ProcessedchannelsPanel);
            app.lblColoVoxThresh.Text = 'Vox threshold:';
            app.lblColoPercThresh = uilabel(app.ProcessedchannelsPanel);
            app.lblColoPercThresh.Text = 'Perc. threshold:';
            app.ManualanalysisPanel = uipanel(app.ColocalizeTab);
            app.ManualanalysisPanel.Title = 'Manual analysis';
            app.btnColocalize = uibutton(app.ManualanalysisPanel, 'push');
            app.btnColocalize.ButtonPushedFcn = createCallbackFcn(app, @btnColocalizeButtonPushed, true);
            app.btnColocalize.Icon = '48px-Fruit-cherries.png';
            app.btnColocalize.Enable = 'off';
            app.btnColocalize.Text = {'Colocalize'; '1 channel'};
            app.btnColocalize2 = uibutton(app.ManualanalysisPanel, 'push');
            app.btnColocalize2.ButtonPushedFcn = createCallbackFcn(app, @btnColocalize2ButtonPushed, true);
            app.btnColocalize2.Icon = '48px-Fruit-3cherries.png';
            app.btnColocalize2.Enable = 'off';
            app.btnColocalize2.Text = {'Colocalize'; '2 channels'};
            app.AutomatedanalysisPanel = uipanel(app.ColocalizeTab);
            app.AutomatedanalysisPanel.Title = 'Automated analysis';
            app.btnColocalizeMask = uibutton(app.AutomatedanalysisPanel, 'push');
            app.btnColocalizeMask.ButtonPushedFcn = createCallbackFcn(app, @btnColocalizeMaskButtonPushed, true);
            app.btnColocalizeMask.Icon = '48px-Masks.png';
            app.btnColocalizeMask.Enable = 'off';
            app.btnColocalizeMask.Text = {'Colocalize'; 'Binary Mask'};
            app.btnColocalizeNN = uibutton(app.AutomatedanalysisPanel, 'push');
            app.btnColocalizeNN.ButtonPushedFcn = createCallbackFcn(app, @btnColocalizeNNButtonPushed, true);
            app.btnColocalizeNN.Icon = '48px-Measure.png';
            app.btnColocalizeNN.Enable = 'off';
            app.btnColocalizeNN.Text = {'Nearest'; 'Neighbor'};
            app.ColocalizedifsharingatleastLabel = uilabel(app.AutomatedanalysisPanel);
            app.ColocalizedifsharingatleastLabel.Text = {'Colocalized if'; 'sharing at least:'};
            app.ColoSharedVoxNum = uispinner(app.AutomatedanalysisPanel);
            app.ColoSharedVoxNum.Limits = [1 Inf];
            app.ColoSharedVoxNum.ValueDisplayFormat = '%.0f';
            app.ColoSharedVoxNum.Enable = 'off';
            app.ColoSharedVoxNum.Value = 30;
            app.ColoSwitchVoxPerc = uiswitch(app.AutomatedanalysisPanel, 'slider');
            app.ColoSwitchVoxPerc.Items = {'Voxels', '%'};
            app.ColoSwitchVoxPerc.Enable = 'off';
            app.ColoSwitchVoxPerc.Value = '%';
            app.ObjectstocolocalizePanel = uipanel(app.ColocalizeTab);
            app.ObjectstocolocalizePanel.Title = 'Objects to colocalize';
            app.lstColoSelObjects = uilistbox(app.ObjectstocolocalizePanel);
            app.lstColoSelObjects.Items = {};
            app.lstColoSelObjects.Multiselect = 'on';
            app.lstColoSelObjects.ValueChangedFcn = createCallbackFcn(app, @lstColoSelObjectsValueChanged, true);
            app.lstColoSelObjects.Value = {};
            app.chkColoSaveAsNewObjects = uicheckbox(app.ColocalizeTab);
            app.chkColoSaveAsNewObjects.Text = 'Create also a new set of objects containing colocalized objects as validated';
            app.NeuralNetTab = uitab(app.TabGroup);
            app.NeuralNetTab.Title = 'Neural Net';
            app.TrainingDatasetPanel = uipanel(app.NeuralNetTab);
            app.TrainingDatasetPanel.Title = 'Choose analyzed data to use as training dataset for a new Neural Network';
            app.lstTraining = uilistbox(app.TrainingDatasetPanel);
            app.lstTraining.Items = {};
            app.lstTraining.ValueChangedFcn = createCallbackFcn(app, @lstTrainingValueChanged, true);
            app.lstTraining.Value = {};
            app.btnTrainingAdd = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingAdd.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingAddButtonPushed, true);
            app.btnTrainingAdd.Icon = '48px-List-add.png';
            app.btnTrainingAdd.Text = '';
            app.btnTrainingRemove = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingRemove.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingRemoveButtonPushed, true);
            app.btnTrainingRemove.Icon = '48px-List-remove.png';
            app.btnTrainingRemove.Enable = 'off';
            app.btnTrainingRemove.Text = '';
            app.btnTrainingUp = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingUp.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingUpButtonPushed, true);
            app.btnTrainingUp.Icon = '48px-Go-up.png';
            app.btnTrainingUp.Enable = 'off';
            app.btnTrainingUp.Text = '';
            app.btnTrainingDown = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingDown.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingDownButtonPushed, true);
            app.btnTrainingDown.Icon = '48px-Go-down.png';
            app.btnTrainingDown.Enable = 'off';
            app.btnTrainingDown.Text = '';
            app.btnTrainingTop = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingTop.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingTopButtonPushed, true);
            app.btnTrainingTop.Icon = '48px-Go-top.png';
            app.btnTrainingTop.Enable = 'off';
            app.btnTrainingTop.Text = '';
            app.btnTrainingBottom = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingBottom.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingBottomButtonPushed, true);
            app.btnTrainingBottom.Icon = '48px-Go-bottom.png';
            app.btnTrainingBottom.Enable = 'off';
            app.btnTrainingBottom.Text = '';
            app.btnTrainingClear = uibutton(app.TrainingDatasetPanel, 'push');
            app.btnTrainingClear.ButtonPushedFcn = createCallbackFcn(app, @btnTrainingClearButtonPushed, true);
            app.btnTrainingClear.Icon = '48px-Edit-clear.png';
            app.btnTrainingClear.Enable = 'off';
            app.btnTrainingClear.Text = '';
            app.TrainingsettingsPanel = uipanel(app.NeuralNetTab);
            app.TrainingsettingsPanel.Title = 'Training settings';
            app.FractionofobjectstousefortrainingSpinnerLabel = uilabel(app.TrainingsettingsPanel);
            app.FractionofobjectstousefortrainingSpinnerLabel.Text = 'Percent objects used for training. Remaining objects used for validation.';
            app.TrainSplitRatio = uispinner(app.TrainingsettingsPanel);
            app.TrainSplitRatio.Step = 5;
            app.TrainSplitRatio.Limits = [0 100];
            app.TrainSplitRatio.Value = 70;
            app.MiniBatchSizenumberofpointsuponwhichtrainingstatsupdateLabel = uilabel(app.TrainingsettingsPanel);
            app.MiniBatchSizenumberofpointsuponwhichtrainingstatsupdateLabel.Text = 'Mini Batch Size (number of points upon which training stats update)';
            app.TrainMiniBatchSize = uieditfield(app.TrainingsettingsPanel, 'numeric');
            app.TrainMiniBatchSize.Limits = [10 Inf];
            app.TrainMiniBatchSize.Value = 200;
            app.MaximumnumberortrainingepochsLabel = uilabel(app.TrainingsettingsPanel);
            app.MaximumnumberortrainingepochsLabel.Text = 'Maximum number or training epochs';
            app.TrainMaxEpochs = uieditfield(app.TrainingsettingsPanel, 'numeric');
            app.TrainMaxEpochs.Limits = [10 Inf];
            app.TrainMaxEpochs.Value = 20;
            app.MomentumpercentcontributionoftheprevioussteptotrainingLabel = uilabel(app.TrainingsettingsPanel);
            app.MomentumpercentcontributionoftheprevioussteptotrainingLabel.Text = 'Momentum (percent contribution of the previous step to training)';
            app.TrainMomentum = uispinner(app.TrainingsettingsPanel);
            app.TrainMomentum.Step = 5;
            app.TrainMomentum.Limits = [0 100];
            app.TrainMomentum.Value = 90;
            app.Initiallearningrate0to1zeromeansslowestLabel = uilabel(app.TrainingsettingsPanel);
            app.Initiallearningrate0to1zeromeansslowestLabel.Text = 'Initial learning rate (0 to 1, zero means slowest)';
            app.TrainInitialLearningRate = uispinner(app.TrainingsettingsPanel);
            app.TrainInitialLearningRate.Step = 0.01;
            app.TrainInitialLearningRate.Limits = [0.01 1];
            app.TrainInitialLearningRate.Value = 0.01;
            app.TrainingFolderButton = uibutton(app.NeuralNetTab, 'push');
            app.TrainingFolderButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingFolderButtonPushed, true);
            app.TrainingFolderButton.Icon = '48px-folder-save.png';
            app.TrainingFolderButton.Text = '';
            app.TrainNeuralNetworkButton = uibutton(app.NeuralNetTab, 'push');
            app.TrainNeuralNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNeuralNetworkButtonPushed, true);
            app.TrainNeuralNetworkButton.Icon = '48px-monkey.png';
            app.TrainNeuralNetworkButton.Enable = 'off';
            app.TrainNeuralNetworkButton.Text = {'Train'; 'Neural Network'};
            app.GenerateTrainingDatasetButton = uibutton(app.NeuralNetTab, 'push');
            app.GenerateTrainingDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTrainingDatasetButtonPushed, true);
            app.GenerateTrainingDatasetButton.Icon = '48px-database.png';
            app.GenerateTrainingDatasetButton.Enable = 'off';
            app.GenerateTrainingDatasetButton.Text = {'Generate'; 'Training Dataset'};
            app.TrainingFolder = uieditfield(app.NeuralNetTab, 'text');
            app.TrainingFolder.Editable = 'off';
            app.TrainingfolderLabel = uilabel(app.NeuralNetTab);
            app.TrainingfolderLabel.Text = 'Training folder:';
            app.NameLabel = uilabel(app.NeuralNetTab);
            app.NameLabel.Text = 'Name:';
            app.txtTrainingName = uieditfield(app.NeuralNetTab, 'text');
            app.txtTrainingName.Value = 'AlexNet';
            app.NetworkModelLabel = uilabel(app.NeuralNetTab);
            app.NetworkModelLabel.Text = 'Network Model:';
            app.cmbTrainingModel = uidropdown(app.NeuralNetTab);
            app.cmbTrainingModel.Items = {'AlexNet', 'VGG-16', 'VGG-19', 'SqueezeNet', 'GoogleNet', 'Inception-v3', 'ResNet-50', 'ResNet-101', 'Inception-ResNet-v2'};
            app.cmbTrainingModel.Value = 'AlexNet';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.lblObjectFinder = uilabel(app.AboutTab);
            app.lblObjectFinder.Text = 'ObjectFinder';
            app.lblCopyright = uilabel(app.AboutTab);
            app.lblCopyright.Text = '© 2016-2019 Luca Della Santina';
            app.btnAppIcon = uibutton(app.AboutTab, 'push');
            app.btnAppIcon.Icon = 'app_icon_big.png';
            app.btnAppIcon.Text = '';
            app.lblVersion = uilabel(app.AboutTab);
            app.lblVersion.Text = 'Version 6.5';
            app.txtLicense = uitextarea(app.AboutTab);
            app.txtLicense.Value = {'GNU GENERAL PUBLIC LICENSE'; 'Version 3'; ' 29 June 2007'; ''; 'Copyright © 2007 Free Software Foundation'; ' Inc. <https://fsf.org/>'; ''; 'Everyone is permitted to copy and distribute verbatim copies of this license document'; ' but changing it is not allowed.'; ''; 'Preamble'; 'The GNU General Public License is a free'; ' copyleft license for software and other kinds of works.'; ''; 'The licenses for most software and other practical works are designed to take away your freedom to share and change the works. By contrast'; ' the GNU General Public License is intended to guarantee your freedom to share and change all versions of a program--to make sure it remains free software for all its users. We'; ' the Free Software Foundation'; ' use the GNU General Public License for most of our software'; ' it applies also to any other work released this way by its authors. You can apply it to your programs too.'; ''; 'When we speak of free software'; ' we are referring to freedom'; ' not price. Our General Public Licenses are designed to make sure that you have the freedom to distribute copies of free software (and charge for them if you wish)'; ' that you receive source code or can get it if you want it'; ' that you can change the software or use pieces of it in new free programs'; ' and that you know you can do these things.'; ''; 'To protect your rights'; ' we need to prevent others from denying you these rights or asking you to surrender the rights. Therefore'; ' you have certain responsibilities if you distribute copies of the software'; ' or if you modify it: responsibilities to respect the freedom of others.'; ''; 'For example'; ' if you distribute copies of such a program'; ' whether gratis or for a fee'; ' you must pass on to the recipients the same freedoms that you received. You must make sure that they'; ' too'; ' receive or can get the source code. And you must show them these terms so they know their rights.'; ''; 'Developers that use the GNU GPL protect your rights with two steps: (1) assert copyright on the software'; ' and (2) offer you this License giving you legal permission to copy'; ' distribute and/or modify it.'; ''; 'For the developers'' and authors'' protection'; ' the GPL clearly explains that there is no warranty for this free software. For both users'' and authors'' sake'; ' the GPL requires that modified versions be marked as changed'; ' so that their problems will not be attributed erroneously to authors of previous versions.'; ''; 'Some devices are designed to deny users access to install or run modified versions of the software inside them'; ' although the manufacturer can do so. This is fundamentally incompatible with the aim of protecting users'' freedom to change the software. The systematic pattern of such abuse occurs in the area of products for individuals to use'; ' which is precisely where it is most unacceptable. Therefore'; ' we have designed this version of the GPL to prohibit the practice for those products. If such problems arise substantially in other domains'; ' we stand ready to extend this provision to those domains in future versions of the GPL'; ' as needed to protect the freedom of users.'; ''; 'Finally'; ' every program is threatened constantly by software patents. States should not allow patents to restrict development and use of software on general-purpose computers'; ' but in those that do'; ' we wish to avoid the special danger that patents applied to a free program could make it effectively proprietary. To prevent this'; ' the GPL assures that patents cannot be used to render the program non-free.'; ''; 'The precise terms and conditions for copying'; ' distribution and modification follow.'; ''; 'TERMS AND CONDITIONS'; '0. Definitions.'; '“This License” refers to version 3 of the GNU General Public License.'; ''; '“Copyright” also means copyright-like laws that apply to other kinds of works'; ' such as semiconductor masks.'; ''; '“The Program” refers to any copyrightable work licensed under this License. Each licensee is addressed as “you”. “Licensees” and “recipients” may be individuals or organizations.'; ''; 'To “modify” a work means to copy from or adapt all or part of the work in a fashion requiring copyright permission'; ' other than the making of an exact copy. The resulting work is called a “modified version” of the earlier work or a work “based on” the earlier work.'; ''; 'A “covered work” means either the unmodified Program or a work based on the Program.'; ''; 'To “propagate” a work means to do anything with it that'; ' without permission'; ' would make you directly or secondarily liable for infringement under applicable copyright law'; ' except executing it on a computer or modifying a private copy. Propagation includes copying'; ' distribution (with or without modification)'; ' making available to the public'; ' and in some countries other activities as well.'; ''; 'To “convey” a work means any kind of propagation that enables other parties to make or receive copies. Mere interaction with a user through a computer network'; ' with no transfer of a copy'; ' is not conveying.'; ''; 'An interactive user interface displays “Appropriate Legal Notices” to the extent that it includes a convenient and prominently visible feature that (1) displays an appropriate copyright notice'; ' and (2) tells the user that there is no warranty for the work (except to the extent that warranties are provided)'; ' that licensees may convey the work under this License'; ' and how to view a copy of this License. If the interface presents a list of user commands or options'; ' such as a menu'; ' a prominent item in the list meets this criterion.'; ''; '1. Source Code.'; 'The “source code” for a work means the preferred form of the work for making modifications to it. “Object code” means any non-source form of a work.'; ''; 'A “Standard Interface” means an interface that either is an official standard defined by a recognized standards body'; ' or'; ' in the case of interfaces specified for a particular programming language'; ' one that is widely used among developers working in that language.'; ''; 'The “System Libraries” of an executable work include anything'; ' other than the work as a whole'; ' that (a) is included in the normal form of packaging a Major Component'; ' but which is not part of that Major Component'; ' and (b) serves only to enable use of the work with that Major Component'; ' or to implement a Standard Interface for which an implementation is available to the public in source code form. A “Major Component”'; ' in this context'; ' means a major essential component (kernel'; ' window system'; ' and so on) of the specific operating system (if any) on which the executable work runs'; ' or a compiler used to produce the work'; ' or an object code interpreter used to run it.'; ''; 'The “Corresponding Source” for a work in object code form means all the source code needed to generate'; ' install'; ' and (for an executable work) run the object code and to modify the work'; ' including scripts to control those activities. However'; ' it does not include the work''s System Libraries'; ' or general-purpose tools or generally available free programs which are used unmodified in performing those activities but which are not part of the work. For example'; ' Corresponding Source includes interface definition files associated with source files for the work'; ' and the source code for shared libraries and dynamically linked subprograms that the work is specifically designed to require'; ' such as by intimate data communication or control flow between those subprograms and other parts of the work.'; ''; 'The Corresponding Source need not include anything that users can regenerate automatically from other parts of the Corresponding Source.'; ''; 'The Corresponding Source for a work in source code form is that same work.'; ''; '2. Basic Permissions.'; 'All rights granted under this License are granted for the term of copyright on the Program'; ' and are irrevocable provided the stated conditions are met. This License explicitly affirms your unlimited permission to run the unmodified Program. The output from running a covered work is covered by this License only if the output'; ' given its content'; ' constitutes a covered work. This License acknowledges your rights of fair use or other equivalent'; ' as provided by copyright law.'; ''; 'You may make'; ' run and propagate covered works that you do not convey'; ' without conditions so long as your license otherwise remains in force. You may convey covered works to others for the sole purpose of having them make modifications exclusively for you'; ' or provide you with facilities for running those works'; ' provided that you comply with the terms of this License in conveying all material for which you do not control copyright. Those thus making or running the covered works for you must do so exclusively on your behalf'; ' under your direction and control'; ' on terms that prohibit them from making any copies of your copyrighted material outside their relationship with you.'; ''; 'Conveying under any other circumstances is permitted solely under the conditions stated below. Sublicensing is not allowed'; ' section 10 makes it unnecessary.'; ''; '3. Protecting Users'' Legal Rights From Anti-Circumvention Law.'; 'No covered work shall be deemed part of an effective technological measure under any applicable law fulfilling obligations under article 11 of the WIPO copyright treaty adopted on 20 December 1996'; ' or similar laws prohibiting or restricting circumvention of such measures.'; ''; 'When you convey a covered work'; ' you waive any legal power to forbid circumvention of technological measures to the extent such circumvention is effected by exercising rights under this License with respect to the covered work'; ' and you disclaim any intention to limit operation or modification of the work as a means of enforcing'; ' against the work''s users'; ' your or third parties'' legal rights to forbid circumvention of technological measures.'; ''; '4. Conveying Verbatim Copies.'; 'You may convey verbatim copies of the Program''s source code as you receive it'; ' in any medium'; ' provided that you conspicuously and appropriately publish on each copy an appropriate copyright notice'; ' keep intact all notices stating that this License and any non-permissive terms added in accord with section 7 apply to the code'; ' keep intact all notices of the absence of any warranty'; ' and give all recipients a copy of this License along with the Program.'; ''; 'You may charge any price or no price for each copy that you convey'; ' and you may offer support or warranty protection for a fee.'; ''; '5. Conveying Modified Source Versions.'; 'You may convey a work based on the Program'; ' or the modifications to produce it from the Program'; ' in the form of source code under the terms of section 4'; ' provided that you also meet all of these conditions:'; ''; 'a) The work must carry prominent notices stating that you modified it'; ' and giving a relevant date.'; 'b) The work must carry prominent notices stating that it is released under this License and any conditions added under section 7. This requirement modifies the requirement in section 4 to “keep intact all notices”.'; 'c) You must license the entire work'; ' as a whole'; ' under this License to anyone who comes into possession of a copy. This License will therefore apply'; ' along with any applicable section 7 additional terms'; ' to the whole of the work'; ' and all its parts'; ' regardless of how they are packaged. This License gives no permission to license the work in any other way'; ' but it does not invalidate such permission if you have separately received it.'; 'd) If the work has interactive user interfaces'; ' each must display Appropriate Legal Notices'; ' however'; ' if the Program has interactive interfaces that do not display Appropriate Legal Notices'; ' your work need not make them do so.'; 'A compilation of a covered work with other separate and independent works'; ' which are not by their nature extensions of the covered work'; ' and which are not combined with it such as to form a larger program'; ' in or on a volume of a storage or distribution medium'; ' is called an “aggregate” if the compilation and its resulting copyright are not used to limit the access or legal rights of the compilation''s users beyond what the individual works permit. Inclusion of a covered work in an aggregate does not cause this License to apply to the other parts of the aggregate.'; ''; '6. Conveying Non-Source Forms.'; 'You may convey a covered work in object code form under the terms of sections 4 and 5'; ' provided that you also convey the machine-readable Corresponding Source under the terms of this License'; ' in one of these ways:'; ''; 'a) Convey the object code in'; ' or embodied in'; ' a physical product (including a physical distribution medium)'; ' accompanied by the Corresponding Source fixed on a durable physical medium customarily used for software interchange.'; 'b) Convey the object code in'; ' or embodied in'; ' a physical product (including a physical distribution medium)'; ' accompanied by a written offer'; ' valid for at least three years and valid for as long as you offer spare parts or customer support for that product model'; ' to give anyone who possesses the object code either (1) a copy of the Corresponding Source for all the software in the product that is covered by this License'; ' on a durable physical medium customarily used for software interchange'; ' for a price no more than your reasonable cost of physically performing this conveying of source'; ' or (2) access to copy the Corresponding Source from a network server at no charge.'; 'c) Convey individual copies of the object code with a copy of the written offer to provide the Corresponding Source. This alternative is allowed only occasionally and noncommercially'; ' and only if you received the object code with such an offer'; ' in accord with subsection 6b.'; 'd) Convey the object code by offering access from a designated place (gratis or for a charge)'; ' and offer equivalent access to the Corresponding Source in the same way through the same place at no further charge. You need not require recipients to copy the Corresponding Source along with the object code. If the place to copy the object code is a network server'; ' the Corresponding Source may be on a different server (operated by you or a third party) that supports equivalent copying facilities'; ' provided you maintain clear directions next to the object code saying where to find the Corresponding Source. Regardless of what server hosts the Corresponding Source'; ' you remain obligated to ensure that it is available for as long as needed to satisfy these requirements.'; 'e) Convey the object code using peer-to-peer transmission'; ' provided you inform other peers where the object code and Corresponding Source of the work are being offered to the general public at no charge under subsection 6d.'; 'A separable portion of the object code'; ' whose source code is excluded from the Corresponding Source as a System Library'; ' need not be included in conveying the object code work.'; ''; 'A “User Product” is either (1) a “consumer product”'; ' which means any tangible personal property which is normally used for personal'; ' family'; ' or household purposes'; ' or (2) anything designed or sold for incorporation into a dwelling. In determining whether a product is a consumer product'; ' doubtful cases shall be resolved in favor of coverage. For a particular product received by a particular user'; ' “normally used” refers to a typical or common use of that class of product'; ' regardless of the status of the particular user or of the way in which the particular user actually uses'; ' or expects or is expected to use'; ' the product. A product is a consumer product regardless of whether the product has substantial commercial'; ' industrial or non-consumer uses'; ' unless such uses represent the only significant mode of use of the product.'; ''; '“Installation Information” for a User Product means any methods'; ' procedures'; ' authorization keys'; ' or other information required to install and execute modified versions of a covered work in that User Product from a modified version of its Corresponding Source. The information must suffice to ensure that the continued functioning of the modified object code is in no case prevented or interfered with solely because modification has been made.'; ''; 'If you convey an object code work under this section in'; ' or with'; ' or specifically for use in'; ' a User Product'; ' and the conveying occurs as part of a transaction in which the right of possession and use of the User Product is transferred to the recipient in perpetuity or for a fixed term (regardless of how the transaction is characterized)'; ' the Corresponding Source conveyed under this section must be accompanied by the Installation Information. But this requirement does not apply if neither you nor any third party retains the ability to install modified object code on the User Product (for example'; ' the work has been installed in ROM).'; ''; 'The requirement to provide Installation Information does not include a requirement to continue to provide support service'; ' warranty'; ' or updates for a work that has been modified or installed by the recipient'; ' or for the User Product in which it has been modified or installed. Access to a network may be denied when the modification itself materially and adversely affects the operation of the network or violates the rules and protocols for communication across the network.'; ''; 'Corresponding Source conveyed'; ' and Installation Information provided'; ' in accord with this section must be in a format that is publicly documented (and with an implementation available to the public in source code form)'; ' and must require no special password or key for unpacking'; ' reading or copying.'; ''; '7. Additional Terms.'; '“Additional permissions” are terms that supplement the terms of this License by making exceptions from one or more of its conditions. Additional permissions that are applicable to the entire Program shall be treated as though they were included in this License'; ' to the extent that they are valid under applicable law. If additional permissions apply only to part of the Program'; ' that part may be used separately under those permissions'; ' but the entire Program remains governed by this License without regard to the additional permissions.'; ''; 'When you convey a copy of a covered work'; ' you may at your option remove any additional permissions from that copy'; ' or from any part of it. (Additional permissions may be written to require their own removal in certain cases when you modify the work.) You may place additional permissions on material'; ' added by you to a covered work'; ' for which you have or can give appropriate copyright permission.'; ''; 'Notwithstanding any other provision of this License'; ' for material you add to a covered work'; ' you may (if authorized by the copyright holders of that material) supplement the terms of this License with terms:'; ''; 'a) Disclaiming warranty or limiting liability differently from the terms of sections 15 and 16 of this License'; ' or'; 'b) Requiring preservation of specified reasonable legal notices or author attributions in that material or in the Appropriate Legal Notices displayed by works containing it'; ' or'; 'c) Prohibiting misrepresentation of the origin of that material'; ' or requiring that modified versions of such material be marked in reasonable ways as different from the original version'; ' or'; 'd) Limiting the use for publicity purposes of names of licensors or authors of the material'; ' or'; 'e) Declining to grant rights under trademark law for use of some trade names'; ' trademarks'; ' or service marks'; ' or'; 'f) Requiring indemnification of licensors and authors of that material by anyone who conveys the material (or modified versions of it) with contractual assumptions of liability to the recipient'; ' for any liability that these contractual assumptions directly impose on those licensors and authors.'; 'All other non-permissive additional terms are considered “further restrictions” within the meaning of section 10. If the Program as you received it'; ' or any part of it'; ' contains a notice stating that it is governed by this License along with a term that is a further restriction'; ' you may remove that term. If a license document contains a further restriction but permits relicensing or conveying under this License'; ' you may add to a covered work material governed by the terms of that license document'; ' provided that the further restriction does not survive such relicensing or conveying.'; ''; 'If you add terms to a covered work in accord with this section'; ' you must place'; ' in the relevant source files'; ' a statement of the additional terms that apply to those files'; ' or a notice indicating where to find the applicable terms.'; ''; 'Additional terms'; ' permissive or non-permissive'; ' may be stated in the form of a separately written license'; ' or stated as exceptions'; ' the above requirements apply either way.'; ''; '8. Termination.'; 'You may not propagate or modify a covered work except as expressly provided under this License. Any attempt otherwise to propagate or modify it is void'; ' and will automatically terminate your rights under this License (including any patent licenses granted under the third paragraph of section 11).'; ''; 'However'; ' if you cease all violation of this License'; ' then your license from a particular copyright holder is reinstated (a) provisionally'; ' unless and until the copyright holder explicitly and finally terminates your license'; ' and (b) permanently'; ' if the copyright holder fails to notify you of the violation by some reasonable means prior to 60 days after the cessation.'; ''; 'Moreover'; ' your license from a particular copyright holder is reinstated permanently if the copyright holder notifies you of the violation by some reasonable means'; ' this is the first time you have received notice of violation of this License (for any work) from that copyright holder'; ' and you cure the violation prior to 30 days after your receipt of the notice.'; ''; 'Termination of your rights under this section does not terminate the licenses of parties who have received copies or rights from you under this License. If your rights have been terminated and not permanently reinstated'; ' you do not qualify to receive new licenses for the same material under section 10.'; ''; '9. Acceptance Not Required for Having Copies.'; 'You are not required to accept this License in order to receive or run a copy of the Program. Ancillary propagation of a covered work occurring solely as a consequence of using peer-to-peer transmission to receive a copy likewise does not require acceptance. However'; ' nothing other than this License grants you permission to propagate or modify any covered work. These actions infringe copyright if you do not accept this License. Therefore'; ' by modifying or propagating a covered work'; ' you indicate your acceptance of this License to do so.'; ''; '10. Automatic Licensing of Downstream Recipients.'; 'Each time you convey a covered work'; ' the recipient automatically receives a license from the original licensors'; ' to run'; ' modify and propagate that work'; ' subject to this License. You are not responsible for enforcing compliance by third parties with this License.'; ''; 'An “entity transaction” is a transaction transferring control of an organization'; ' or substantially all assets of one'; ' or subdividing an organization'; ' or merging organizations. If propagation of a covered work results from an entity transaction'; ' each party to that transaction who receives a copy of the work also receives whatever licenses to the work the party''s predecessor in interest had or could give under the previous paragraph'; ' plus a right to possession of the Corresponding Source of the work from the predecessor in interest'; ' if the predecessor has it or can get it with reasonable efforts.'; ''; 'You may not impose any further restrictions on the exercise of the rights granted or affirmed under this License. For example'; ' you may not impose a license fee'; ' royalty'; ' or other charge for exercise of rights granted under this License'; ' and you may not initiate litigation (including a cross-claim or counterclaim in a lawsuit) alleging that any patent claim is infringed by making'; ' using'; ' selling'; ' offering for sale'; ' or importing the Program or any portion of it.'; ''; '11. Patents.'; 'A “contributor” is a copyright holder who authorizes use under this License of the Program or a work on which the Program is based. The work thus licensed is called the contributor''s “contributor version”.'; ''; 'A contributor''s “essential patent claims” are all patent claims owned or controlled by the contributor'; ' whether already acquired or hereafter acquired'; ' that would be infringed by some manner'; ' permitted by this License'; ' of making'; ' using'; ' or selling its contributor version'; ' but do not include claims that would be infringed only as a consequence of further modification of the contributor version. For purposes of this definition'; ' “control” includes the right to grant patent sublicenses in a manner consistent with the requirements of this License.'; ''; 'Each contributor grants you a non-exclusive'; ' worldwide'; ' royalty-free patent license under the contributor''s essential patent claims'; ' to make'; ' use'; ' sell'; ' offer for sale'; ' import and otherwise run'; ' modify and propagate the contents of its contributor version.'; ''; 'In the following three paragraphs'; ' a “patent license” is any express agreement or commitment'; ' however denominated'; ' not to enforce a patent (such as an express permission to practice a patent or covenant not to sue for patent infringement). To “grant” such a patent license to a party means to make such an agreement or commitment not to enforce a patent against the party.'; ''; 'If you convey a covered work'; ' knowingly relying on a patent license'; ' and the Corresponding Source of the work is not available for anyone to copy'; ' free of charge and under the terms of this License'; ' through a publicly available network server or other readily accessible means'; ' then you must either (1) cause the Corresponding Source to be so available'; ' or (2) arrange to deprive yourself of the benefit of the patent license for this particular work'; ' or (3) arrange'; ' in a manner consistent with the requirements of this License'; ' to extend the patent license to downstream recipients. “Knowingly relying” means you have actual knowledge that'; ' but for the patent license'; ' your conveying the covered work in a country'; ' or your recipient''s use of the covered work in a country'; ' would infringe one or more identifiable patents in that country that you have reason to believe are valid.'; ''; 'If'; ' pursuant to or in connection with a single transaction or arrangement'; ' you convey'; ' or propagate by procuring conveyance of'; ' a covered work'; ' and grant a patent license to some of the parties receiving the covered work authorizing them to use'; ' propagate'; ' modify or convey a specific copy of the covered work'; ' then the patent license you grant is automatically extended to all recipients of the covered work and works based on it.'; ''; 'A patent license is “discriminatory” if it does not include within the scope of its coverage'; ' prohibits the exercise of'; ' or is conditioned on the non-exercise of one or more of the rights that are specifically granted under this License. You may not convey a covered work if you are a party to an arrangement with a third party that is in the business of distributing software'; ' under which you make payment to the third party based on the extent of your activity of conveying the work'; ' and under which the third party grants'; ' to any of the parties who would receive the covered work from you'; ' a discriminatory patent license (a) in connection with copies of the covered work conveyed by you (or copies made from those copies)'; ' or (b) primarily for and in connection with specific products or compilations that contain the covered work'; ' unless you entered into that arrangement'; ' or that patent license was granted'; ' prior to 28 March 2007.'; ''; 'Nothing in this License shall be construed as excluding or limiting any implied license or other defenses to infringement that may otherwise be available to you under applicable patent law.'; ''; '12. No Surrender of Others'' Freedom.'; 'If conditions are imposed on you (whether by court order'; ' agreement or otherwise) that contradict the conditions of this License'; ' they do not excuse you from the conditions of this License. If you cannot convey a covered work so as to satisfy simultaneously your obligations under this License and any other pertinent obligations'; ' then as a consequence you may not convey it at all. For example'; ' if you agree to terms that obligate you to collect a royalty for further conveying from those to whom you convey the Program'; ' the only way you could satisfy both those terms and this License would be to refrain entirely from conveying the Program.'; ''; '13. Use with the GNU Affero General Public License.'; 'Notwithstanding any other provision of this License'; ' you have permission to link or combine any covered work with a work licensed under version 3 of the GNU Affero General Public License into a single combined work'; ' and to convey the resulting work. The terms of this License will continue to apply to the part which is the covered work'; ' but the special requirements of the GNU Affero General Public License'; ' section 13'; ' concerning interaction through a network will apply to the combination as such.'; ''; '14. Revised Versions of this License.'; 'The Free Software Foundation may publish revised and/or new versions of the GNU General Public License from time to time. Such new versions will be similar in spirit to the present version'; ' but may differ in detail to address new problems or concerns.'; ''; 'Each version is given a distinguishing version number. If the Program specifies that a certain numbered version of the GNU General Public License “or any later version” applies to it'; ' you have the option of following the terms and conditions either of that numbered version or of any later version published by the Free Software Foundation. If the Program does not specify a version number of the GNU General Public License'; ' you may choose any version ever published by the Free Software Foundation.'; ''; 'If the Program specifies that a proxy can decide which future versions of the GNU General Public License can be used'; ' that proxy''s public statement of acceptance of a version permanently authorizes you to choose that version for the Program.'; ''; 'Later license versions may give you additional or different permissions. However'; ' no additional obligations are imposed on any author or copyright holder as a result of your choosing to follow a later version.'; ''; '15. Disclaimer of Warranty.'; 'THERE IS NO WARRANTY FOR THE PROGRAM'; ' TO THE EXTENT PERMITTED BY APPLICABLE LAW. EXCEPT WHEN OTHERWISE STATED IN WRITING THE COPYRIGHT HOLDERS AND/OR OTHER PARTIES PROVIDE THE PROGRAM “AS IS” WITHOUT WARRANTY OF ANY KIND'; ' EITHER EXPRESSED OR IMPLIED'; ' INCLUDING'; ' BUT NOT LIMITED TO'; ' THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE. THE ENTIRE RISK AS TO THE QUALITY AND PERFORMANCE OF THE PROGRAM IS WITH YOU. SHOULD THE PROGRAM PROVE DEFECTIVE'; ' YOU ASSUME THE COST OF ALL NECESSARY SERVICING'; ' REPAIR OR CORRECTION.'; ''; '16. Limitation of Liability.'; 'IN NO EVENT UNLESS REQUIRED BY APPLICABLE LAW OR AGREED TO IN WRITING WILL ANY COPYRIGHT HOLDER'; ' OR ANY OTHER PARTY WHO MODIFIES AND/OR CONVEYS THE PROGRAM AS PERMITTED ABOVE'; ' BE LIABLE TO YOU FOR DAMAGES'; ' INCLUDING ANY GENERAL'; ' SPECIAL'; ' INCIDENTAL OR CONSEQUENTIAL DAMAGES ARISING OUT OF THE USE OR INABILITY TO USE THE PROGRAM (INCLUDING BUT NOT LIMITED TO LOSS OF DATA OR DATA BEING RENDERED INACCURATE OR LOSSES SUSTAINED BY YOU OR THIRD PARTIES OR A FAILURE OF THE PROGRAM TO OPERATE WITH ANY OTHER PROGRAMS)'; ' EVEN IF SUCH HOLDER OR OTHER PARTY HAS BEEN ADVISED OF THE POSSIBILITY OF SUCH DAMAGES.'; ''; '17. Interpretation of Sections 15 and 16.'; 'If the disclaimer of warranty and limitation of liability provided above cannot be given local legal effect according to their terms'; ' reviewing courts shall apply local law that most closely approximates an absolute waiver of all civil liability in connection with the Program'; ' unless a warranty or assumption of liability accompanies a copy of the Program in return for a fee.'; ''; 'END OF TERMS AND CONDITIONS'; ''; 'How to Apply These Terms to Your New Programs'; 'If you develop a new program'; ' and you want it to be of the greatest possible use to the public'; ' the best way to achieve this is to make it free software which everyone can redistribute and change under these terms.'; ''; 'To do so'; ' attach the following notices to the program. It is safest to attach them to the start of each source file to most effectively state the exclusion of warranty'; ' and each file should have at least the “copyright” line and a pointer to where the full notice is found.'; ''; '    <one line to give the program''s name and a brief idea of what it does.>'; '    Copyright (C) <year>  <name of author>'; ''; '    This program is free software: you can redistribute it and/or modify'; '    it under the terms of the GNU General Public License as published by'; '    the Free Software Foundation'; ' either version 3 of the License'; ' or'; '    (at your option) any later version.'; ''; '    This program is distributed in the hope that it will be useful'; ''; '    but WITHOUT ANY WARRANTY'; ' without even the implied warranty of'; '    MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the'; '    GNU General Public License for more details.'; ''; '    You should have received a copy of the GNU General Public License'; '    along with this program.  If not'; ' see <https://www.gnu.org/licenses/>.'; 'Also add information on how to contact you by electronic and paper mail.'; ''; 'If the program does terminal interaction'; ' make it output a short notice like this when it starts in an interactive mode:'; ''; '    <program>  Copyright (C) <year>  <name of author>'; '    This program comes with ABSOLUTELY NO WARRANTY'; ' for details type `show w''.'; '    This is free software'; ' and you are welcome to redistribute it'; '    under certain conditions'; ' type `show c'' for details.'; 'The hypothetical commands `show w'' and `show c'' should show the appropriate parts of the General Public License. Of course'; ' your program''s commands might be different'; ' for a GUI interface'; ' you would use an “about box”.'; ''; 'You should also get your employer (if you work as a programmer) or school'; ' if any'; ' to sign a “copyright disclaimer” for the program'; ' if necessary. For more information on this'; ' and how to apply and follow the GNU GPL'; ' see <https://www.gnu.org/licenses/>.'; ''; 'The GNU General Public License does not permit incorporating your program into proprietary programs. If your program is a subroutine library'; ' you may consider it more useful to permit linking proprietary applications with the library. If this is what you want to do'; ' use the GNU Lesser General Public License instead of this License. But first'; ' please read <https://www.gnu.org/licenses/why-not-lgpl.html>.'};
            app.btnReportBug = uibutton(app.AboutTab, 'push');
            app.btnReportBug.ButtonPushedFcn = createCallbackFcn(app, @btnReportBugButtonPushed, true);
            app.btnReportBug.Icon = 'bug.png';
            app.btnReportBug.Text = 'Report an issue';
            app.btnHomePage = uibutton(app.AboutTab, 'push');
            app.btnHomePage.ButtonPushedFcn = createCallbackFcn(app, @btnHomePageButtonPushed, true);
            app.btnHomePage.Icon = '48px-web-browser.png';
            app.btnHomePage.Text = 'Visit home page';
            app.btnUserManual = uibutton(app.AboutTab, 'push');
            app.btnUserManual.ButtonPushedFcn = createCallbackFcn(app, @btnUserManualButtonPushed, true);
            app.btnUserManual.Icon = '48px-Manual.png';
            app.btnUserManual.Text = 'User Manual';
            app.Log = uieditfield(app.UIFigure, 'text');
            app.Log.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ObjectFinder

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end